classdef example8_4_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageAppThisBookGroupV100UIFigure  matlab.ui.Figure
        SaveImageButton                 matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        VarEditField                    matlab.ui.control.NumericEditField
        VarEditFieldLabel               matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        NoiseTypeDropDown               matlab.ui.control.DropDown
        NoiseTypeDropDownLabel          matlab.ui.control.Label
        contrastEditField_2             matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        ContrastEditField               matlab.ui.control.NumericEditField
        ContrastEditFieldLabel          matlab.ui.control.Label
        AddNoiseButton                  matlab.ui.control.Button
        GreyScaleButton                 matlab.ui.control.Button
        ProcessedimageLabel             matlab.ui.control.Label
        OriginalimageLabel              matlab.ui.control.Label
        ImageFilePathEditField          matlab.ui.control.EditField
        ImageFilePathEditFieldLabel     matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        DigitalimageprocessingappLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path_image;
        image_data;
        processed_image_data;
        grey_image;
    end
    
    methods (Access = private)
        
        
        function results = greyscale_fcn(app)
            app.grey_image=rgb2gray(app.image_data);
            app.processed_image_data=app.grey_image;
            disp_processed_image_data_fcn(app);
        end
        
        
        function results = greyscale_contrast_fcn(app)
            compare_result= num_compare_fcn(app,app.ContrastEditField.Value,app.contrastEditField_2.Value);
            
            app.ContrastEditField.Value=compare_result(1);
            app.contrastEditField_2.Value=compare_result(2);
            
            process_result= imadjust(app.grey_image,[compare_result(1),compare_result(2)],[]);
            app.processed_image_data=process_result;
            disp_processed_image_data_fcn(app);
        end
        
        
        function results = noise_add_fcn(app)
            if app.NoiseTypeDropDown.Value=="Pepper"
                process_result = imnoise(app.image_data,'salt & pepper');
            elseif app.NoiseTypeDropDown.Value=="Gaussian"
                
                if app.VarEditField.Value<=0
                    app.VarEditField.Value=0.01;
                end
                
                process_result = imnoise(app.image_data,'gaussian',app.MeanEditField.Value,app.VarEditField.Value);
                
            end
            app.processed_image_data=process_result;
            disp_processed_image_data_fcn(app);
        end


        function results = disp_processed_image_data_fcn(app)
            I=imshow(app.processed_image_data,'Parent',app.UIAxes_2,...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);
            
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
        end
        
        function results = num_compare_fcn(app,a,b)
            if (a<0 || a>1)
                a=0;
            end
            if (b<0 || b>1)
                b=1;
            end
            if (a>=b)
                if a==b
                    a=0.1+b;
                end
                t=a;
                a=b;
                b=t;
            end
            results=[a b];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function Open(app, event)
            [file,app.path_image] = uigetfile({'*.bmp;*.jpg;*.png'});
            filepath=fullfile(app.path_image,file);
            
            app.ImageFilePathEditField.Value=filepath;
            
            app.image_data=imread(filepath);
            I=imshow(app.image_data,'Parent',app.UIAxes,...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
        end

        % Button pushed function: GreyScaleButton
        function greyscale(app, event)
            greyscale_fcn(app);
        end

        % Value changed function: ContrastEditField
        function grey_contra_1(app, event)
            greyscale_contrast_fcn(app);
        end

        % Value changed function: contrastEditField_2
        function grey_contra_2(app, event)
            greyscale_contrast_fcn(app);
        end

        % Button pushed function: AddNoiseButton
        function addnoise(app, event)
            noise_add_fcn(app);
        end

        % Value changed function: NoiseTypeDropDown
        function noise_drop(app, event)
            
            noise_add_fcn(app);
        end

        % Value changed function: MeanEditField
        function mean(app, event)
            noise_add_fcn(app);
        end

        % Value changed function: VarEditField
        function var(app, event)
            noise_add_fcn(app);  
        end

        % Button pushed function: SaveImageButton
        function save(app, event)
            filepath=fullfile(app.path_image,app.FileNameEditField.Value);
            imwrite(app.processed_image_data,filepath);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageAppThisBookGroupV100UIFigure = uifigure('Visible', 'off');
            app.ImageAppThisBookGroupV100UIFigure.Name = 'ImageApp-This Book Group V1.0.0';
            app.UIAxes = uiaxes(app.ImageAppThisBookGroupV100UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.ImageAppThisBookGroupV100UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.DigitalimageprocessingappLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.DigitalimageprocessingappLabel.Text = 'Digital image processing app';
            app.OpenButton = uibutton(app.ImageAppThisBookGroupV100UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @Open, true);
            app.OpenButton.Text = 'Open';
            app.ImageFilePathEditFieldLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.ImageFilePathEditFieldLabel.Text = 'Image File Path';
            app.ImageFilePathEditField = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'text');
            app.OriginalimageLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.OriginalimageLabel.Text = 'Original image';
            app.ProcessedimageLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.ProcessedimageLabel.Text = 'Processed image';
            app.GreyScaleButton = uibutton(app.ImageAppThisBookGroupV100UIFigure, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @greyscale, true);
            app.GreyScaleButton.Text = 'Grey Scale';
            app.AddNoiseButton = uibutton(app.ImageAppThisBookGroupV100UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @addnoise, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.ContrastEditFieldLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'numeric');
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @grey_contra_1, true);
            app.ContrastEditField.Value = 0.1;
            app.toLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.toLabel.Text = 'to';
            app.contrastEditField_2 = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'numeric');
            app.contrastEditField_2.ValueChangedFcn = createCallbackFcn(app, @grey_contra_2, true);
            app.contrastEditField_2.Value = 0.3;
            app.NoiseTypeDropDownLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.ImageAppThisBookGroupV100UIFigure);
            app.NoiseTypeDropDown.Items = {'Pepper', 'Gaussian'};
            app.NoiseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @noise_drop, true);
            app.NoiseTypeDropDown.Value = 'Pepper';
            app.MeanEditFieldLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean ';
            app.MeanEditField = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'numeric');
            app.MeanEditField.ValueChangedFcn = createCallbackFcn(app, @mean, true);
            app.VarEditFieldLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.VarEditFieldLabel.Text = 'Var';
            app.VarEditField = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'numeric');
            app.VarEditField.ValueChangedFcn = createCallbackFcn(app, @var, true);
            app.VarEditField.Value = 0.01;
            app.FileNameEditFieldLabel = uilabel(app.ImageAppThisBookGroupV100UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ImageAppThisBookGroupV100UIFigure, 'text');
            app.FileNameEditField.Value = 'processed.png';
            app.SaveImageButton = uibutton(app.ImageAppThisBookGroupV100UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImageButton.Text = 'Save Image';
            % Show the figure after all components are created
            app.ImageAppThisBookGroupV100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = example8_4_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageAppThisBookGroupV100UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageAppThisBookGroupV100UIFigure)
        end
    end
end