classdef zcimage_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_zcimage      matlab.ui.Figure
        m_file              matlab.ui.container.Menu
        m_file_open         matlab.ui.container.Menu
        m_file_save         matlab.ui.container.Menu
        m_file_exit         matlab.ui.container.Menu
        m_image             matlab.ui.container.Menu
        m_image_2bw         matlab.ui.container.Menu
        uitoolbar1          matlab.ui.container.Toolbar
        tb1_open            matlab.ui.container.toolbar.PushTool
        tb1_save            matlab.ui.container.toolbar.PushTool
        axes_dst            matlab.ui.control.UIAxes
        axes_src            matlab.ui.control.UIAxes
        axe_dst_menu        matlab.ui.container.ContextMenu
        axes_dst_menu_save  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function zcimage_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure_zcimage, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            setappdata(handles.figure_zcimage, 'imag_src', 0);         %初始化全局参量imag_src
            set(handles.m_image, 'Enable', 'off');           %图片未打开前，设置图像处理菜单不可用
            setappdata(handles.figure_zcimage, 'bSave', false);
            setappdata(handles.figure_zcimage, 'bChanged', false);     %设置两个标志：一是图片是否被处理过了，
                                                                       %二是图片是否被保存了
            setappdata(handles.figure_zcimage, 'fstSave', true);        %记录是否第一次保存
            setappdata(handles.figure_zcimage, 'fstPath', 0);           %记录第一次保存的路径
            
            
            % Choose default command line output for zcimage
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: axes_dst_menu_save
        function axes_dst_menu_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to axes_dst_menu_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile({'*.bmp';'BMP files';'*.jpg';'JPG files'},'pick an image');
            if isequal(filename,0)|| isequal(pathname,0)
                return;              %如果点了取消
            else
                fpath = fullfile(pathname, filename);  %获得全路径的另一种方法
            end
            imag_dst = getimage(handles.axes_dst);    %取得处理后的图片的数据
            imwrite(imag_dst, fpath);                 %保存图片
            setappdata(handles.figure_zcimage, 'bSave', true);
        end

        % Menu selected function: m_file_exit
        function m_file_exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_file_exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            bChanged = getappdata(handles.figure_zcimage, 'bChanged');   %获得是否更改
            bSave = getappdata(handles.figure_zcimage, 'bSave');   %获得是否保存
            if bChanged == true && bSave == false       %更改了，而没有保存时
                btnName = questdlg('您已更改了图片，但没有保存，需要保存吗？', '提示', '保存', '不保存','保存');
                                                          %用提问对话框
                switch btnName
                    case '保存'           %执行axes_dst_menu_save_Callback功能
                        feval(@axes_dst_menu_save_Callback, handles.axe_dst_menu_save, eventdata, handles);
                    case '不保存'
                end
            end
            h = findobj('Tag', 'figure_im2bw'); %查找是否打开设置图像二值化参数窗口
            if ~isempty(h)
                close(h);
            end
            close(findobj('Tag', 'figure_zcimage'));
        end

        % Menu selected function: m_file_open
        function m_file_open_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_file_open (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uigetfile(...
                {'*.bmp;*.jpg;*.png;*.jpeg', 'Imag Files(*.bmp;*.jpg;*.png;*.jpeg)'; ...
                '*.*',  'All Files(*.*)'}, ...
               'pick an image');
            if isequal(filename,0)|| isequal(pathname,0)
                return;              %如果点了取消
            end
            fpath = [pathname filename];  %将文件名和目录名合成一个完整的路径
            imag_src = imread(fpath);      %用imread读入图片
            axes(handles.axes_src);        %用axes命令设定当前操作的坐标轴是axes_src
            imshow(imag_src);              %并用imshow在axes_src上显示图片
            setappdata(handles.figure_zcimage, 'imag_src', imag_src);    %在open文件设置save文件需要调用的变量imag_src
            set(handles.m_image, 'Enable', 'on');
        end

        % Menu selected function: m_file_save
        function m_file_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_file_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uiputfile({'*.bmp';'BMP files';'*.jpg';'JPG files'},'pick an image');
            if isequal(filename,0)|| isequal(pathname,0)
                return;              %如果点了取消
            else
                fpath = fullfile(pathname, filename);  %获得全路径的另一种方法
            end
            imag_src = getappdata(handles.figure_zcimage, 'imag_src');  %取得打开图片的数据
            imwrite(imag_src, fpath);
        end

        % Menu selected function: m_image_2bw
        function m_image_2bw_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to m_image_2bw (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = im2bw_args;
            setappdata(handles.figure_zcimage, 'bChanged', true);
        end

        % Clicked callback: tb1_open
        function tb1_open_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tb1_open (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            feval(@m_file_open_Callback, handles.m_file_open, eventdata, handles);
        end

        % Clicked callback: tb1_save
        function tb1_save_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tb1_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fstSave = getappdata(handles.figure_zcimage, 'fstSave');
            if (fstSave == true)
                [filename, pathname] = uiputfile({'*.bmp';'BMP files';'*.jpg';'JPG files'},'pick an image');
                if isequal(filename, 0) || isequal(pathname, 0)
                    return;
                else
                    fpath = fullfile(pathname, filename);
                end
                imag_dst = getimage(handles.axes_dst);
                imwrite(imag_dst, fpath);
                setappdata(handles.figure_zcimage, 'fstPath',fpath );
                setappdata(handles.figure_zcimage, 'bSave',true );
                setappdata(handles.figure_zcimage, 'fstSave',false );
            else
                imag_dst = getimage(handles.axes_dst);
                fpath = getappdata(handles.figure_zcimage, 'fstPath');
                imwrite(imag_dst, fpath);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_zcimage = uifigure('Visible', 'off');
            app.figure_zcimage.Name = '图像处理实例';
            app.figure_zcimage.Tag = 'figure_zcimage';
            app.m_file = uimenu(app.figure_zcimage);
            app.m_file.Accelerator = 'F';
            app.m_file.Text = '文件';
            app.m_file.Tag = 'm_file';
            app.m_file_open = uimenu(app.m_file);
            app.m_file_open.MenuSelectedFcn = createCallbackFcn(app, @m_file_open_Callback, true);
            app.m_file_open.Accelerator = 'O';
            app.m_file_open.Text = '打开';
            app.m_file_open.Tag = 'm_file_open';
            app.m_file_save = uimenu(app.m_file);
            app.m_file_save.MenuSelectedFcn = createCallbackFcn(app, @m_file_save_Callback, true);
            app.m_file_save.Accelerator = 'S';
            app.m_file_save.Text = '保存';
            app.m_file_save.Tag = 'm_file_save';
            app.m_file_exit = uimenu(app.m_file);
            app.m_file_exit.MenuSelectedFcn = createCallbackFcn(app, @m_file_exit_Callback, true);
            app.m_file_exit.Accelerator = 'X';
            app.m_file_exit.Text = '退出';
            app.m_file_exit.Tag = 'm_file_exit';
            app.m_image = uimenu(app.figure_zcimage);
            app.m_image.Text = '图像处理';
            app.m_image.Tag = 'm_image';
            app.m_image_2bw = uimenu(app.m_image);
            app.m_image_2bw.MenuSelectedFcn = createCallbackFcn(app, @m_image_2bw_Callback, true);
            app.m_image_2bw.Text = '图像二值化';
            app.m_image_2bw.Tag = 'm_image_2bw';
            app.uitoolbar1 = uitoolbar(app.figure_zcimage);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.tb1_open = uipushtool(app.uitoolbar1);
            app.tb1_open.Tag = 'tb1_open';
            app.tb1_open.Tooltip = '打开文件';
            app.tb1_open.ClickedCallback = createCallbackFcn(app, @tb1_open_ClickedCallback, true);
            app.tb1_open.Icon = 'tb1_open_image.png';
            app.tb1_save = uipushtool(app.uitoolbar1);
            app.tb1_save.Tag = 'tb1_save';
            app.tb1_save.Tooltip = '保存图窗';
            app.tb1_save.ClickedCallback = createCallbackFcn(app, @tb1_save_ClickedCallback, true);
            app.tb1_save.Icon = 'tb1_save_image.png';
            app.axes_src = uiaxes(app.figure_zcimage);
            app.axes_src.Tag = 'axes_src';
            app.axes_dst = uiaxes(app.figure_zcimage);
            app.axes_dst.Tag = 'axes_dst';
            app.axe_dst_menu = uicontextmenu(app.figure_zcimage);
            app.axe_dst_menu.Tag = 'axe_dst_menu';
            app.axes_dst_menu_save = uimenu(app.axe_dst_menu);
            app.axes_dst_menu_save.MenuSelectedFcn = createCallbackFcn(app, @axes_dst_menu_save_Callback, true);
            app.axes_dst_menu_save.Text = '保存';
            app.axes_dst_menu_save.Tag = 'axes_dst_menu_save';
            % Show the figure after all components are created
            app.figure_zcimage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zcimage_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure_zcimage)

                % Execute the startup function
                runStartupFcn(app, @(app)zcimage_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure_zcimage)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_zcimage)
        end
    end
end