classdef ocr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image         matlab.ui.control.Image
        TextTextArea  matlab.ui.control.TextArea
        Label         matlab.ui.control.Label
        Button        matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, TextTextArea
        function ButtonPushed(app, event)

        [filename,pathname]=uigetfile('.jpg,.png','choose the picture you want to deal with')
        ii=imread(filename);
        imshow(ii,'Parent',app.UIAxes);
        ocrobj = ocr(ii);
        text=ocrobj.Text(1,:);
        app.TextTextArea.Value=text(1,:);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '文字提取';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '载入文字图片';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '光学字符识别';
            app.TextTextArea = uitextarea(app.UIFigure);
            app.TextTextArea.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'samplepicture1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ocr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end