classdef PCB_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uitoolbar1            matlab.ui.container.Toolbar
        uitoggletool1         matlab.ui.container.toolbar.ToggleTool
        uitoggletool2         matlab.ui.container.toolbar.ToggleTool
        uitoggletool3         matlab.ui.container.toolbar.ToggleTool
        uipushtool1           matlab.ui.container.toolbar.PushTool
        PCBLabel              matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        ChooseTestButton      matlab.ui.control.Button
        ChooseTemplateButton  matlab.ui.control.Button
        DefectTypes           matlab.ui.control.Button
        CheckDefects          matlab.ui.control.Button
        axes7                 matlab.ui.control.UIAxes
        axes6                 matlab.ui.control.UIAxes
        axes2                 matlab.ui.control.UIAxes
        axes1                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function miniproject_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to miniproject (see VARARGIN)
            
            % Choose default command line output for miniproject
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: CheckDefects
        function CheckDefects_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CheckDefects (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Creating global variables to be used across Callback Function
            global template test defect
            % Display image in top right axes
            axes(handles.axes6)
            % by using image substraction between template and test, defects can be
            % identified as diff_1 variable
            defect= imabsdiff(template,test);
            % display test image
            imshow (test)
            % draw a boundingbox on each defect found on PCB
            all_defect_bboxes = regionprops(defect,'BoundingBox','centroid');
            hold on
            
            for i = 1:length(all_defect_bboxes)
                % Accessing each bounding box to obtain its position and insert
                % annotation
                each_boundingbox = all_defect_bboxes(i).BoundingBox;
                rectangle('Position',each_boundingbox,'EdgeColor','r','LineWidth',1.5)
            end
        end

        % Button pushed function: ChooseTemplateButton
        function ChooseTemplateButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ChooseTemplateButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global template
                  fullname_temp = uigetfile({'*.jpg*'}, 'Select File to Open');
                    template = imread(fullname_temp);
                    axes(handles.axes1);
                    template=im2bw(template,0.5);
                    imshow (template);
        end

        % Button pushed function: ChooseTestButton
        function ChooseTestButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ChooseTestButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global test
                  fullname_test = uigetfile({'*.jpg*'}, 'Select File to Open');
                       test = imread(fullname_test);
                    axes(handles.axes2);
                    test=im2bw(test,0.5);
                    imshow (test);
        end

        % Button pushed function: DefectTypes
        function DefectTypes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DefectTypes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Creating global variables to be used across Callback Function
            global template test
            % Determining defect types
            axes(handles.axes7)
            imshow (test)
            
            % Negative image represents defects of Pin Holes, Open Circuit and
            % Mousebite
            
            Negative_Image =  test-template;
            % Dealing with logical arrays
            Negative_Image = Negative_Image>0;
            % Assign bounding box to each Negative Image defect
            neg_img_defect = regionprops(Negative_Image,'BoundingBox','centroid');
            % Looping for each defect found from negative image
            for i=1:length(neg_img_defect)
                neg_defectbox=neg_img_defect(i).BoundingBox;
                % modifying bounding box starting point (left corner), width and height
                neg_defectbox(1)=neg_defectbox(1)-2;
                neg_defectbox(2)=neg_defectbox(2)-2;
                neg_defectbox(3)=neg_defectbox(3)+6;
                neg_defectbox(4)=neg_defectbox(4)+6;
            
                % dealing with a smaller logical array with defects included
                neg_crop_test=imcrop(test,neg_defectbox);
                neg_crop_temp=imcrop(template,neg_defectbox);
            
                % invert the arrays since object detection is only applicable for white
                % parts
                 test_comp1=imcomplement(neg_crop_test);
                 [neg_labelled_img, neg_num_object] = bwlabel(test_comp1);
                 boundingbox_centroid = neg_img_defect(i).Centroid;
                        negdefect_centr_x=boundingbox_centroid(1);
                        negdefect_centr_y=boundingbox_centroid(2);
                % filling holes only applicable for white surrounding ares
                test_comp2_filled=imfill(test_comp1,'holes');
                temp = neg_img_defect(i).Centroid;
                        holes_centr_x=temp(1);
                        holes_centr_y=temp(2);
                        % if filling up holes causing test image to be equal to
                        % template image, the array being filled are classified as Pin
                        % Hole defects
                if test_comp2_filled==imcomplement(neg_crop_temp)
                    % insert annotation at position near hole centre
                    text(holes_centr_x-20,holes_centr_y+30, 'PinHoles','Color','r','FontSize',14,'FontWeight','bold')
                    rectangle('Position',neg_defectbox,'EdgeColor','c','LineWidth',1.5)
                    % if more than one object detected, open circuit, morea than 1
                    % white part (after inverting) detected, missing part in the mid
                elseif neg_num_object >1
                            text(negdefect_centr_x-20,negdefect_centr_y+30, 'Open','Color','r','FontSize',14,'FontWeight','bold')
                            rectangle('Position',neg_defectbox,'EdgeColor','c','LineWidth',1.5)
                else
                    % if negative image defects, not holes, not open circuit, objects
                    % detected <1, means the PCB circuit is still connected partly,
                    % only chunks of the part is missing, then MouseBite
                            text(negdefect_centr_x-20,negdefect_centr_y+30, 'MouseBite','Color','r','FontSize',14,'FontWeight','bold')
                            rectangle('Position',neg_defectbox,'EdgeColor','c','LineWidth',1.5)
                end
            end
            
            % Positive image represents defects of Spurs, spurrious copper and short
            % circuit
            % opposite of negative image
                Positive_Image = template - test;
                % Dealing with logical arrays
                Positive_Image = Positive_Image > 0;
                pos_img_defect = regionprops(Positive_Image,'BoundingBox','centroid');
            
            for i=1:length(pos_img_defect)
                pos_defectbox=pos_img_defect(i).BoundingBox;
                % modifying bounding box starting point (left corner), width and height
                pos_defectbox(1)=pos_defectbox(1)-2;
                pos_defectbox(2)=pos_defectbox(2)-2;
                pos_defectbox(3)=pos_defectbox(3)+6;
                pos_defectbox(4)=pos_defectbox(4)+6;
                % dealing with a smaller logical array with defects included
                pos_crop_test=imcrop(test,pos_defectbox);
            
            
                [pos_labelled_img, pos_num_object] = bwlabel(pos_crop_test);
            
                boundingbox_centroid = pos_img_defect(i).Centroid;
                        posdefect_centr_x=boundingbox_centroid(1);
                        posdefect_centr_y=boundingbox_centroid(2);
                        %Same concept, if object more than 1, means surrounded by more
                        %than 1 white parts, thus a black wire in between, indicating
                        %short circuit
                if pos_num_object >1
                    text(posdefect_centr_x-20,posdefect_centr_y+30, 'Short','Color','r','FontSize',14,'FontWeight','bold')
                    rectangle('Position',pos_defectbox,'EdgeColor','y','LineWidth',1.5)
                else
                    %using ratio concept of surrounding edge of the array to determine
                    %if is spur or copper, if ratio larger than specified value,means
                    %the defect is surrounded by white(1), hence, ratio is larger, vice
                    %versa.
                    %Calculating edges, ratio
                refedge= double(2*(pos_defectbox(3)+pos_defectbox(4))+4);
                topedge= sum(pos_crop_test(1,:));
                bottomedge= sum(pos_crop_test(end,:));
                leftedge= sum(pos_crop_test(:,1));
                rightedge= sum(pos_crop_test(:,end));
                sum_edges=topedge + bottomedge + leftedge + rightedge;
                ratio = sum_edges/refedge;
            
                    if ratio < 0.7
                        text(posdefect_centr_x-20,posdefect_centr_y+30, 'Spur','Color','r','FontSize',14,'FontWeight','bold')
                        rectangle('Position',pos_defectbox,'EdgeColor','y','LineWidth',1.5)
                    else
                        text(posdefect_centr_x-20,posdefect_centr_y+30, 'Copper','Color','r','FontSize',14,'FontWeight','bold')
                        rectangle('Position',pos_defectbox,'EdgeColor','y','LineWidth',1.5)
                    end
                end
            end
        end

        % Clicked callback: uipushtool1
        function uipushtool1_ClickedCallback(app, event)
            % This tool was migrated from GUIDE to App Designer.
            % In GUIDE, clicking on the print tool opened a system
            % print dialog. In App Designer, clicking
            % on the print tool saves a screenshot of the app
            % to a new file.
            
            % Determine the inputs to uiputfile, including default file
            % name, file filter, and dialog title.
            defname = [pwd, filesep, mfilename, '_Screenshot.jpg'];
            filter = {'*.jpg','JPEG Joint Photographic Experts Group Format';...
            '*.png','PNG Portable Network Graphics Format';...
            '*.tiff','TIFF Tag Image File Format';...
            '*.pdf','PDF Portable Document Format'};
            title = 'Save App Screenshot to File';
            
            % Display a modal dialog box that allows users to select or
            % specify a file path for the saved screenshot.
            [file, name, path] = uiputfile(filter, title, defname);
            
            % Check that the file path is valid and then save a screenshot of the app.
            if path ~= 0
            
                exportapp(app.figure1, [name, file]);
            end
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'miniproject';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Print Figure';
            app.uipushtool1.ClickedCallback = createCallbackFcn(app, @uipushtool1_ClickedCallback, true);
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes6 = uiaxes(app.figure1);
            app.axes6.Tag = 'axes6';
            app.axes7 = uiaxes(app.figure1);
            app.axes7.Tag = 'axes7';
            app.CheckDefects = uibutton(app.figure1, 'push');
            app.CheckDefects.ButtonPushedFcn = createCallbackFcn(app, @CheckDefects_Callback, true);
            app.CheckDefects.Tag = 'CheckDefects';
            app.CheckDefects.Text = '缺陷检测';
            app.DefectTypes = uibutton(app.figure1, 'push');
            app.DefectTypes.ButtonPushedFcn = createCallbackFcn(app, @DefectTypes_Callback, true);
            app.DefectTypes.Tag = 'DefectTypes';
            app.DefectTypes.Text = '标注缺陷类型';
            app.ChooseTemplateButton = uibutton(app.figure1, 'push');
            app.ChooseTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseTemplateButton_Callback, true);
            app.ChooseTemplateButton.Tag = 'ChooseTemplateButton';
            app.ChooseTemplateButton.Text = '选择正确样本图';
            app.ChooseTestButton = uibutton(app.figure1, 'push');
            app.ChooseTestButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseTestButton_Callback, true);
            app.ChooseTestButton.Tag = 'ChooseTestButton';
            app.ChooseTestButton.Text = '选择待检测图';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'samplepicture1.png';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '无缺陷对照图';
            app.Label_2 = uilabel(app.figure1);
            app.Label_2.Text = '待检图';
            app.Label_3 = uilabel(app.figure1);
            app.Label_3.Text = '检测结果';
            app.Label_4 = uilabel(app.figure1);
            app.Label_4.Text = '缺陷标注';
            app.PCBLabel = uilabel(app.figure1);
            app.PCBLabel.Text = 'PCB板缺陷检测系统';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCB_Detection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)miniproject_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end