classdef DefectDetection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel4     matlab.ui.container.Panel
        text31       matlab.ui.control.Label
        uipanel2     matlab.ui.container.Panel
        pushbutton3  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        text18       matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        text19       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        edit1        matlab.ui.control.EditField
        text8        matlab.ui.control.Label
        edit2        matlab.ui.control.EditField
        text9        matlab.ui.control.Label
        edit4        matlab.ui.control.EditField
        text22       matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        axes7        matlab.ui.control.UIAxes
        text28       matlab.ui.control.Label
        text27       matlab.ui.control.Label
        text26       matlab.ui.control.Label
        text25       matlab.ui.control.Label
        text24       matlab.ui.control.Label
        text23       matlab.ui.control.Label
        text21       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
        axes6        matlab.ui.control.UIAxes
        axes5        matlab.ui.control.UIAxes
        axes4        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DefectDetection_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DefectDetection (see VARARGIN)
            handles.input = 0;
            handles.img = [];
            handles.manual_flag = 1;
            handles.detectResults = [];
            handles.isolate_flag = 1;
            handles.ignore_edge_flag = 1;
            handles.gauss_segma = 3;
            handles.disk_size = 2;
            handles.seg_threshold = 4;
            
            set(handles.radiobutton1,'value',1);
            set(handles.radiobutton2,'value',0);
            
            set(handles.radiobutton5,'value',1);
            set(handles.radiobutton4,'value',0);
            
            set(handles.radiobutton6,'value',1);
            set(handles.radiobutton7,'value',0);
            
            axes(handles.axes1);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes2);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes3);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes4);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes5);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes6);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes7)    %在坐标轴7下画图
            ii = imread('background.png');
            set(gca,'color','none');
            imshow(ii)


            % Choose default command line output for DefectDetection
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName] = uigetfile({'*.jpg'; '*.png'; '*.bmp'}, 'Select the image for defect detection');
            if isequal(FileName,0) || isequal(PathName,0)
                input = 0;
                return; %若未选中jpg文件则重新打开[文件选择]窗口
            else
                fpath = fullfile(PathName,FileName);
                input = 1;
                set(handles.text15,'String',fpath);
            end
            handles.input = input;
            I = imread(fpath);
            cla(handles.axes1);
            axes(handles.axes1);
            imshow(I, []);
            guidata(hObject,handles);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            guidata(hObject,handles);
            t = size(I);
            if length(t) == 2
                handles.img = I;
                guidata(hObject,handles);
            elseif length(t) == 3
                handles.img = rgb2gray(I);
                guidata(hObject,handles);
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles = guidata(hObject)
            I = handles.img;
            
            t1 = clock;
            % DFT transform
            DFT = fftn(I);  %n维快速傅里叶变换
            MAG = abs(DFT); %二维矢量幅值
            PHASE = DFT./MAG; %相位
            
            % 逆傅里叶变换重建图像
            R1 = ifftn(MAG);
            R2 = ifftn(PHASE);
            
            t2 = clock;
            
            % Display Images
            cla(handles.axes3);
            axes(handles.axes3);
            imshow(uint8(R1));
            guidata(hObject,handles);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            
            cla(handles.axes4);
            axes(handles.axes4);
            imshow(R2, []);
            guidata(hObject,handles);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            % figure,imshow(I); %original image
            % figure,imshow(uint8(R1)); %reconstructed image from magnitude only
            % figure,imshow(R2,[]); %reconstructed image from phase only
            
            gauss_segma = get(handles.edit4, 'String');
            if gauss_segma == '3'
                gauss_segma = 3;
            else
                gauss_segma = str2num(gauss_segma);
            end
            
            
            t3 = clock;
            % 马氏距离阈值分割
            Y = imgaussfilt(R2, gauss_segma); %Gaussian filter with sigma =
            % figure,imshow(Y,[]);
            t4 = clock;
            
            cla(handles.axes5);
            axes(handles.axes5);
            imshow(Y, []);
            guidata(hObject,handles);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            
            t5 = clock;
            % 计算欧氏距离
            MEAN = mean2(Y);
            [x,y,z]=size(I);
            for i = 1:x
                for j=1:y
                    D(i,j)=(Y(i,j)-MEAN).^2;
                end
            end
            % figure; imshow(D,[]);
            t6 = clock;
            
            cla(handles.axes6);
            axes(handles.axes6);
            imshow(D, []);
            guidata(hObject,handles);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            
            if get(handles.radiobutton1,'value')
                % manual_flag = 1;
                handles.manual_flag = 1;
            end
            guidata(hObject,handles);
            
            if get(handles.radiobutton2,'value')
                % manual_flag = 0;
                handles.manual_flag = 0;
            end
            guidata(hObject,handles);
            
            handles = guidata(hObject);
            manual_flag = handles.manual_flag;
            
            disk_size = get(handles.edit2, 'String');
            if disk_size == '2'
                disk_size = 2;
            else
                disk_size = str2num(disk_size);
            end
            
            seg_threshold = get(handles.edit1, 'String');
            if seg_threshold == '4'
                seg_threshold = 4;
            else
                seg_threshold = str2num(seg_threshold);
            end
            
            if manual_flag
                t7 = clock;
                % Detect the blob
                O = (uint8(255*mat2gray(D)));
                % figure; imshow(O);
                BW = O;
                if seg_threshold > max(max(BW))
                    seg_threshold = max(max(BW));
                    errordlg('阈值过大，结果显示可能出现错误！');
                end
                BW(find(BW > seg_threshold)) = 100;
                se = strel('disk', disk_size);
                BW_1 = BW;
                BW_1 = imerode(BW_1, se);
                BW_1 = imdilate(BW_1, se);
                t8 = clock;
            else
                t7 = clock;
                % Detect the blob
                O = (uint8(255*mat2gray(D)));
                level = graythresh(O);
                BW_1 = imbinarize(O, level);
                t8 = clock;
            end
            t_consumed = (t2 - t1) + (t4 - t3) + (t6 - t5) + (t8 - t7);
            t_consumed = 365*24*60*60*t_consumed(1) + 30*24*60*60*t_consumed(2) + 24*60*60*t_consumed(3) + 60*60*t_consumed(4) + 60*t_consumed(5) + t_consumed(6);
            
            set(handles.text21,'String',[num2str(t_consumed), 's']);
            
            %%
            if get(handles.radiobutton6,'value')
                % manual_flag = 1;
                handles.ignore_edge_flag = 1;
            end
            guidata(hObject,handles);
            
            if get(handles.radiobutton7,'value')
                % manual_flag = 0;
                handles.ignore_edge_flag = 0;
            end
            guidata(hObject,handles);
            
            handles = guidata(hObject);
            ignore_edge_flag = handles.ignore_edge_flag;
            
            if ignore_edge_flag
                ign_wid = get(handles.edit3, 'String');
                if ign_wid == '5'
                    ign_wid = 5;
                else
                    ign_wid = str2num(ign_wid);
                end
                mask = zeros(size(BW_1));
                mask((1 + ign_wid):(size(BW_1, 1) - ign_wid), (1 + ign_wid):(size(BW_1, 2) - ign_wid)) = 1;
                BW_1 = mask .* double(BW_1);
            end
            
            %%
            if get(handles.radiobutton5,'value')
                % manual_flag = 1;
                handles.isolate_flag = 1;
            end
            guidata(hObject,handles);
            
            if get(handles.radiobutton4,'value')
                % manual_flag = 0;
                handles.isolate_flag = 0;
            end
            guidata(hObject,handles);
            
            handles = guidata(hObject);
            isolate_flag = handles.isolate_flag;
            
            if isolate_flag
                cla(handles.axes2);
                axes(handles.axes2);
                imshow(BW_1, []);
                guidata(hObject,handles);
                set(gca,'xtick',[]);
                set(gca,'ytick',[]);
                guidata(hObject,handles);
                handles.detectResults = BW_1;
                guidata(hObject,handles);
            else
                original_img = I;
                orig_img_g = original_img;
                orig_img_b = original_img;
                mask_img = BW_1;
                mask_img_g = zeros(size(mask_img));
                mask_ing_b = zeros(size(mask_img));
                g1 = cat(3, mask_img, mask_img_g, mask_ing_b);
                g2 = cat(3, original_img, orig_img_g, orig_img_b);
                g3 = imadd(30 * uint8(g1), g2);
                cla(handles.axes2);
                axes(handles.axes2);
                imshow(g3);
                guidata(hObject,handles);
                set(gca,'xtick',[]);
                set(gca,'ytick',[]);
                guidata(hObject,handles);
                handles.detectResults = g3;
                guidata(hObject,handles);
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [f, p]=uiputfile({'*.jpg'},'Save Weight Image');
            if isequal(f, 0) || isequal(p, 0)
                return;
            else
                fpath=fullfile(p, f);
            end
            % pix = getframe(handles.axes2);  % 如此提取图片分辨率较低
            % imwrite(pix.cdata, fpath, 'jpg'); % 如此提取图片分辨率较低
            handles = guidata(hObject);
            detectResults = handles.detectResults;
            imwrite(detectResults, fpath, 'jpg');
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.edit4,'String','3');
            set(handles.edit2,'String','2');
            set(handles.edit1,'String','4');
            set(handles.edit3,'String','5');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DefectDetection';
            app.figure1.Tag = 'figure1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.axes5 = uiaxes(app.figure1);
            app.axes5.Tag = 'axes5';
            app.axes6 = uiaxes(app.figure1);
            app.axes6.Tag = 'axes6';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = '图像路径：';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = '';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Running Time:';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Time (s)';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = '矢量幅值重建';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = '比例重建';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '高斯滤波相位';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = '马氏距离';
            app.text27 = uilabel(app.figure1);
            app.text27.Tag = 'text27';
            app.text27.Text = '';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = '';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.axes7 = uiaxes(app.uipanel2);
            app.axes7.Tag = 'axes7';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = '设置';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = '缺陷特征提取';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Gauss Segma';
            app.edit4 = uieditfield(app.uipanel2, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '3';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'DiskSize';
            app.edit2 = uieditfield(app.uipanel2, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '2';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Seg Threshold';
            app.edit1 = uieditfield(app.uipanel2, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '4';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = '显示';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = '掩膜';
            app.edit3 = uieditfield(app.uipanel2, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '5';
            app.pushbutton1 = uibutton(app.uipanel2, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = '载入图像';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = '忽略边缘';
            app.pushbutton5 = uibutton(app.uipanel2, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = '重置参数';
            app.pushbutton2 = uibutton(app.uipanel2, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = '开始检测';
            app.pushbutton3 = uibutton(app.uipanel2, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = '保存';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.text31 = uilabel(app.uipanel4);
            app.text31.Tag = 'text31';
            app.text31.Text = '表面缺陷检测系统';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefectDetection_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DefectDetection_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end