classdef OCR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        Menu     matlab.ui.container.Menu
        Menu_2   matlab.ui.container.Menu
        Menu_3   matlab.ui.container.Menu
        axes1    matlab.ui.control.UIAxes
        axes4    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function BotonSalir_Callback(app, hObject, eventdata, handles)
            exit;
        end
        
        function pushbutton7_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(ESCANEAR);
            PORTADA
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ESCANEAR_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            cla(handles.axes1,'reset');
            set(handles.axes1,'Visible','off');
            axes(handles.axes4);
            path = 'samplepicture2.png';
            img = imread(path);
            imshow(img);
            axis off;
            handles.output = hObject;
            guidata(hObject, handles);
        end

        % Menu selected function: Menu
        function BotonGrabar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [nombre dire]=uigetfile('*.jpg','Abrir');
            if nombre==0
                return
            end
            imagen=imread(fullfile(dire,nombre));
            axes(handles.axes1)
            image(imagen)
            axis off
            guidata(hObject, handles);
        end

        % Menu selected function: Menu_2
        function BotonReproducir_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagen=getimage(handles.axes1);
            %title('INPUT IMAGE WITH NOISE')
            % Convert to gray scale
            if size(imagen,3)==3 %RGB image
                imagen=rgb2gray(imagen);
            end
            % Convert to BW
            threshold = graythresh(imagen);
            imagen =~im2bw(imagen,threshold);
            % Remove all object containing fewer than 30 pixels
            imagen = bwareaopen(imagen,30);
            %Storage matrix word from image
            word=[ ];
            re=imagen;
            %Opens text.txt as file for write
            fid = fopen('doc.doc', 'wt');
            % Load templates
            load templates
            global templates
            % Compute the number of letters in template file
            num_letras=size(templates,2);
            while 1
                %Fcn 'lines' separate lines in text
                [fl re]=lines(re);
                imgn=fl;
                %Uncomment line below to see lines one by one
                %imshow(fl);pause(0.5)
                %-----------------------------------------------------------------
                % Label and count connected components
                [L Ne] = bwlabel(imgn);
                for n=1:Ne
                    [r,c] = find(L==n);
                    % Extract letter
                    n1=imgn(min(r):max(r),min(c):max(c));
                    % Resize letter (same size of template)
                    img_r=imresize(n1,[42 24]);
                    %Uncomment line below to see letters one by one
                     %imshow(img_r);pause(0.5)
                    %-------------------------------------------------------------------
                    % Call fcn to convert image to text
                    letter=read_letter(img_r,num_letras);
                    % Letter concatenation
                    word=[word letter];
                end
                %fprintf(fid,'%s\n',lower(word));%Write 'word' in text file (lower)
                fprintf(fid,'%s\n',word);%Write 'word' in text file (upper)
                % Clear 'word' variable
                word=[ ];
                %*When the sentences finish, breaks the loop
                if isempty(re)  %See variable 're' in Fcn 'lines'
                    break
                end
            end
            fclose(fid);
            %Open 'text.txt' file
            open('doc.doc')
        end

        % Menu selected function: Menu_3
        function pushbutton8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla(handles.axes1,'reset');
            set(handles.axes1,'Visible','off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ESCANEAR';
            app.figure1.Tag = 'figure1';
            app.Menu = uimenu(app.figure1);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @BotonGrabar_Callback, true);
            app.Menu.Text = '打开图像';
            app.Menu_2 = uimenu(app.figure1);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @BotonReproducir_Callback, true);
            app.Menu_2.Text = '文字识别';
            app.Menu_3 = uimenu(app.figure1);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.Menu_3.Text = '重置图片';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = zeros(1,0);
            app.axes1.XTickLabel = '';
            app.axes1.YTick = zeros(1,0);
            app.axes1.YTickLabel = '';
            app.axes1.Tag = 'axes1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OCR(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ESCANEAR_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end