classdef License_plate_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Licenseplaterecognition20UIFigure  matlab.ui.Figure
        biaoti        matlab.ui.control.Label
        result        matlab.ui.control.Label
        PHYLabel      matlab.ui.control.Label
        ByLabel       matlab.ui.control.Label
        DesignLabel   matlab.ui.control.Label
        yijianshibie  matlab.ui.control.Button
        shibie_cnn    matlab.ui.control.Button
        shibie_moban  matlab.ui.control.Button
        fenge         matlab.ui.control.Button
        biaoding      matlab.ui.control.Button
        wenjian       matlab.ui.control.Button
    end

    
    properties (Access = private)
        tu % Description       
        tu2 % Description
        tu3 % Description

        word1 % Description
        word2 % Description
        word3 % Description
        word4 % Description
        word5 % Description
        word6 % Description
        word7 % Description
    end
    
    methods (Access = private)
        
        function [result1,result2] = caijian_app(app,tu)
            
            [y,x,z]=size(tu);


            bw1 = zeros(y, x);
            bw2 = zeros(y, x);
            
            
            myi=double(tu);
            blue_y=zeros(y,1);
            
            for i=1:y
                for j=1:x
                    rij = myi(i, j, 1)/(myi(i, j, 3)+eps);
                    gij = myi(i, j, 2)/(myi(i, j, 3)+eps);
                    bij = myi(i, j, 3);
                    if ( (rij < 0.3 && gij < 0.6 && bij > 120) ...
                            || (gij < 0.8 && rij < 0.9 && bij > 120) )
                        blue_y(i,1)=blue_y(i,1)+1;
                        %bw1(i,j)=1;
                    end
                end
            end
            
            
            [~,maxy]=max (blue_y);
            while blue_y(maxy-5)<blue_y(maxy)/2 && blue_y(maxy+5)<blue_y(maxy)/2
                blue_y(maxy)=0;
                [~,maxy]=max(blue_y);
            end
            
            
            py1=maxy;
            while((blue_y(py1,1)>=50)&&(py1>1))
                py1=py1-1;
            end
            
            py2=maxy;
            while (blue_y(py2,1)>=5)&&(py2<y)
                py2=py2+1;
            end
            
            x1=tu(py1:py2, :, :);
            %subplot(2,2,2);imshow(x1);


            blue_x=zeros(1,x);
            
            for j=1:x
                for i=py1:py2
                    rij = myi(i, j, 1)/(myi(i, j, 3)+eps);
                    gij = myi(i, j, 2)/(myi(i, j, 3)+eps);
                    bij = myi(i, j, 3);
                    if ( (rij < 0.3 && gij < 0.6 && bij > 120) ...
                            || (gij < 0.8 && rij < 0.9 && bij > 120) )
                        blue_x(1,j)=blue_x(1,j)+1;
                        %bw2(i,j)=1;
                    end
                end
            end
            
            px1=1;
            while(blue_x(1,px1)<10 && (px1<x) )
                px1=px1+1;
            end
            
            px2=x;
            while(blue_x(1,px2)<25 && (px2>px1))
                px2=px2-1;
            end
            
            dw=tu(py1+10:py2-10, px1+5:px2-5, :);
            
            
            [y,x,z]=size(dw);
            bw=zeros(y,x);
            
            
            %转为黑白
            for i=1:y
                for j=1:x
                    if (dw(i,j,1)>140 && dw(i,j,2)>150 && dw(i,j,3)>120 )
                        bw(i,j)=1;            
                    end
                end
            end
            
            result1=dw;  %dw
            result2=bw;  %bw
            
        end
        
        function re_pic = qiege_app(app,image)
            
            [m,n]=size(image);
            top = 1; 
            bottom = m;
            left = 1;
            right = n;
            
            while sum(image(top,:))==0 && top<=m
                top = top + 1;
            end
            while sum(image(bottom,:))==0 && bottom>=1
                bottom = bottom - 1;
            end
            while sum(image(:,left))<2 && left<=n
                left = left + 1;
            end
            while sum(image(:,right))<2 && right>=1
                 right = right - 1;
            end
            dd = right - left; 
            hh = bottom - top; 
            
                
            re_pic = imcrop(image, [left top dd hh]);
            
            
        end
        
        function [word1,image] = fenli_ch_app(app,pic)
            
            [y,x]=size(pic);
            size_x=zeros(1,x);
            
            i=1;
            while i<=x
                while ( sum(pic(:,i)) > 1 && i<=x )%统计每个y方向上的白色像素，从左边到右边没有白色的地方
                    size_x(1,i)=sum(pic(:,i));     %将y方向的白色像素数目放入一维数组size_x中
                    i=i+1;
                    
                    if i>x                          %如果超出图片的边界就回到边界跳出循环
                        i=x;
                    end       
                    if (i>x/10 && sum(pic(:,i))==0) || i==x %发现的白色像素的长度超过十分之一车牌长度的话 
                        break;                              %就跳出循环
                    end
                end
                
                if i>x/10                                   %如果白色像素的长度超过十分之一车牌长度
                    break;                                  %说明是一个字符，跳出循环
                end
                
                while i<=x/10 && sum(size_x(1,i)) ==0       %如果白色像素的长度小于十分之一车牌长度
                    size_x(1,i)=5;                          %有可能是汉字的偏旁与主体之间是断开的
                    i=i+1;                                  %那么将中间的值值为5，并向右移动
                end                                         %一直到移动到汉字的主体位置
                    
            end
            if i<x/8
                x_1=i-1;
            else
                [~,x_1]=min(size_x);
            end
            
            x_1=x_1+3;
            
            word1=pic(:,1:x_1);
            %figure(3);subplot(2,1,1);imshow(word1);subplot(2,1,2);imshow(pic(:,x_1:end));
            size_y_top=zeros(y,1);
            size_y_bottom=zeros(y,1);
            i=1;
            while sum(word1(i,:))<=3 && i<y
                size_y_top=sum(word1(i,:));
                i=i+1;
            end
            i_t=i;
            i=y;
            while sum(word1(i,:))<=3 && i>1
                size_y_bottom=sum(word1(i,:));
                i=i-1;
            end
            i_b=i;
            
            word1=logical(word1(i_t:i_b,:));
            image=logical(pic(:,x_1:end));


        end
        
        function [w1,w2,w3,w4,w5,w6] = fenli_app(app,pic)
            
            %作用是分离字符，以便识别

            %pic1=edge(pic,'Canny');
            pic1=bwlabel(pic,8);
            stats=regionprops(pic1,'BoundingBox');
            if length(stats)~=6
                msgbox('fail');
                return
            end

            w1=pic(round(stats(1).BoundingBox(2)):floor(stats(1).BoundingBox(2)+stats(1).BoundingBox(4)),...
                     round(stats(1).BoundingBox(1)):floor(stats(1).BoundingBox(1)+stats(1).BoundingBox(3)));
            
            w2=pic(round(stats(2).BoundingBox(2)):floor(stats(2).BoundingBox(2)+stats(2).BoundingBox(4)),...
                     round(stats(2).BoundingBox(1)):floor(stats(2).BoundingBox(1)+stats(2).BoundingBox(3)));
            
            w3=pic(round(stats(3).BoundingBox(2)):floor(stats(3).BoundingBox(2)+stats(3).BoundingBox(4)),...
                     round(stats(3).BoundingBox(1)):floor(stats(3).BoundingBox(1)+stats(3).BoundingBox(3)));
            
            w4=pic(round(stats(4).BoundingBox(2)):floor(stats(4).BoundingBox(2)+stats(4).BoundingBox(4)),...
                     round(stats(4).BoundingBox(1)):floor(stats(4).BoundingBox(1)+stats(4).BoundingBox(3)));
            
            w5=pic(round(stats(5).BoundingBox(2)):floor(stats(5).BoundingBox(2)+stats(5).BoundingBox(4)),...
                     round(stats(5).BoundingBox(1)):floor(stats(5).BoundingBox(1)+stats(5).BoundingBox(3)));
            
            w6=pic(round(stats(6).BoundingBox(2)):floor(stats(6).BoundingBox(2)+stats(6).BoundingBox(4)),...
                     round(stats(6).BoundingBox(1)):floor(stats(6).BoundingBox(1)+stats(6).BoundingBox(3)));


        end
        
        function str = shibie_moban_app(app,word1,word2,word3,word4,word5,word6,word7)
            
            word1 = imresize(word1, [40 20]);
            word2 = imresize(word2, [40 20]);
            word3 = imresize(word3, [40 20]);
            word4 = imresize(word4, [40 20]);
            word5 = imresize(word5, [40 20]);
            word6 = imresize(word6, [40 20]);
            word7 = imresize(word7, [40 20]);
             
            pattern = [];
            dirpath = fullfile(pwd, '标准库/*.jpg');
            files = ls(dirpath);
            for t = 1 : length(files)
                filenamet = fullfile(pwd, '标准库', files(t,:));
                [~, name, ~] = fileparts(filenamet);
                imagedata = imread(filenamet);
                imagedata = im2bw(imagedata, 0.5);
                %imagedata=im2double(imagedata);
                %imagedata=rgb2gray(imagedata);
                pattern(t).feature = imagedata;
                pattern(t).name = name;
            end
            
            result=[];
            
            distance = [];
            for m = 1 : 7;
                for n = 1 : length(files);
                    switch m
                        case 1
                            distance(n)=corr2(word1,pattern(n).feature);
                        case 2
                            distance(n)=corr2(word2,pattern(n).feature);
                        case 3
                            distance(n)=corr2(word3,pattern(n).feature);
                        case 4
                            distance(n)=corr2(word4,pattern(n).feature);
                        case 5
                            distance(n)=corr2(word5,pattern(n).feature);
                        case 6
                            distance(n)=corr2(word6,pattern(n).feature);
                        case 7
                            distance(n)=corr2(word7,pattern(n).feature);
                    end
                end
                [yvalue,xnumber]=max(distance);
                filename = files(xnumber, :);
                [pathstr, name, ext] = fileparts(filename);
                result(m) = name;
            end
            str = ['识别结果为：' result];         
            
        end
        
        function str=shibie_cnn_app(app,word1,word2,word3,word4,word5,word6,word7)
                     
            warning off parallel:gpu:device:DeviceLibsNeedsRecompiling
            try
                gpuArray.eye(2)^2;
            catch ME
            end
            try
                nnet.internal.cnngpu.reluForward(1);
            catch ME
            end
            
            load('cnn_net.mat');
            
            if exist('net')~=1
                msgbox('error');
                return;
            end
            

            word1 = imresize(word1, [20 20]);
            word2 = imresize(word2, [20 20]);
            word3 = imresize(word3, [20 20]);
            word4 = imresize(word4, [20 20]);
            word5 = imresize(word5, [20 20]);
            word6 = imresize(word6, [20 20]);
            word7 = imresize(word7, [20 20]);
            
            mkdir cnn_runing;
            path1=pwd;
            cd cnn_runing;
            path2=fullfile(path1,'cnn_runing');
            
            
            imwrite(word1,'word1.jpg');
            imwrite(word2,'word2.jpg');
            imwrite(word3,'word3.jpg');
            imwrite(word4,'word4.jpg');
            imwrite(word5,'word5.jpg');
            imwrite(word6,'word6.jpg');
            imwrite(word7,'word7.jpg');
            
            imds1=imageDatastore('word1.jpg');
            imds2=imageDatastore('word2.jpg');
            imds3=imageDatastore('word3.jpg');
            imds4=imageDatastore('word4.jpg');
            imds5=imageDatastore('word5.jpg');
            imds6=imageDatastore('word6.jpg');
            imds7=imageDatastore('word7.jpg');
            
            cd (path1);
            an1=classify(net,imds1);
            an2=classify(net,imds2);
            an3=classify(net,imds3);
            an4=classify(net,imds4);
            an5=classify(net,imds5);
            an6=classify(net,imds6);
            an7=classify(net,imds7);
            
            a1=char(cellstr(an1));
            a2=char(cellstr(an2));
            a3=char(cellstr(an3));
            a4=char(cellstr(an4));
            a5=char(cellstr(an5));
            a6=char(cellstr(an6));
            a7=char(cellstr(an7));
            
            str=[a1 a2 a3 a4 a5 a6 a7];
            
            rmdir cnn_runing s;
            
            str=['识别结果为：',str];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Licenseplaterecognition20UIFigure.AutoResizeChildren = 'off';
            app.result.Text='';   
            try
                nnet.internal.cnngpu.reluForward(1);
            catch ME
            end
        end

        % Button pushed function: wenjian
        function wenjianButtonPushed(app, event)
            [file path] = uigetfile('*.*');
                                   
            filename=fullfile(path,file);
            pic=imread(filename);
            ax1 = subplot(8,8,[2:5,10:13,18:21,26:29,34:37,42:45],'Parent',app.Licenseplaterecognition20UIFigure);
            imshow(pic,'Parent',ax1);
            app.tu=pic;
        end

        % Button pushed function: biaoding
        function biaodingButtonPushed(app, event)
            if isempty(app.tu)
                msgbox('请先导入待识别文件！');
                return;
            end
            pic=app.tu;
            [pic1,pic2]=caijian_app(app,pic);
            ax2 = subplot(8,8,[6:8,14:16,22:24],'Parent',app.Licenseplaterecognition20UIFigure);
            ax3 = subplot(8,8,[30:32,38:40,46:48],'Parent',app.Licenseplaterecognition20UIFigure);
            imshow(pic1,'Parent',ax2);
            imshow(pic2,'Parent',ax3);
            app.tu2=pic1;
            app.tu3=pic2;
            
        end

        % Button pushed function: fenge
        function fengeButtonPushed(app, event)
            if isempty(app.tu3)
                msgbox('请先标定车牌区域！');
                return;
            end
            
            pic=app.tu3;
            pic1=qiege_app(app,pic);
            [word1,pic2]=fenli_ch_app(app,pic1);
            
            [y,x]=size(pic);
            num=round((y*x)/150);
            
            pic3=medfilt2(pic2,[3,3]);
            pic4=bwareaopen(pic3,num,8);
            
            [word2,word3,word4,word5,word6,word7]=fenli_app(app,pic4);
            
            ax4 = subplot(8,8,[50,58],'Parent',app.Licenseplaterecognition20UIFigure);
            ax5 = subplot(8,8,[51,59],'Parent',app.Licenseplaterecognition20UIFigure);
            ax6 = subplot(8,8,[52,60],'Parent',app.Licenseplaterecognition20UIFigure);
            ax7 = subplot(8,8,[53,61],'Parent',app.Licenseplaterecognition20UIFigure);
            ax8 = subplot(8,8,[54,62],'Parent',app.Licenseplaterecognition20UIFigure);
            ax9 = subplot(8,8,[55,63],'Parent',app.Licenseplaterecognition20UIFigure);
            ax10 = subplot(8,8,[56,64],'Parent',app.Licenseplaterecognition20UIFigure);
            
            imshow(word1,'Parent',ax4);
            imshow(word2,'Parent',ax5);
            imshow(word3,'Parent',ax6);
            imshow(word4,'Parent',ax7);
            imshow(word5,'Parent',ax8);
            imshow(word6,'Parent',ax9);
            imshow(word7,'Parent',ax10);
            
            app.word1=word1;
            app.word2=word2;
            app.word3=word3;
            app.word4=word4;
            app.word5=word5;
            app.word6=word6;
            app.word7=word7;
        end

        % Button pushed function: shibie_moban
        function shibie_mobanButtonPushed(app, event)
            if isempty(app.word7)
                msgbox('请先进行分离字符操作！');
                return;
            end
            word1=app.word1;
            word2=app.word2;
            word3=app.word3;
            word4=app.word4;
            word5=app.word5;
            word6=app.word6;
            word7=app.word7;
            
            str=shibie_moban_app(app,word1,word2,word3,word4,word5,word6,word7);
            app.result.Text=str;
        end

        % Button pushed function: shibie_cnn
        function shibie_cnnButtonPushed(app, event)
            if isempty(app.word7)
                msgbox('请先进行分离字符操作！');
                return;
            end
            word1=app.word1;
            word2=app.word2;
            word3=app.word3;
            word4=app.word4;
            word5=app.word5;
            word6=app.word6;
            word7=app.word7;
            
            str=shibie_cnn_app(app,word1,word2,word3,word4,word5,word6,word7);
            app.result.Text=str;
            
        end

        % Button pushed function: yijianshibie
        function yijianshibieButtonPushed(app, event)
            
            wenjianButtonPushed(app, event);
            biaodingButtonPushed(app, event);
            fengeButtonPushed(app, event);
            shibie_cnnButtonPushed(app, event);
            
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Licenseplaterecognition20UIFigure = uifigure('Visible', 'off');
            app.Licenseplaterecognition20UIFigure.Name = 'License plate recognition 2.0';
            app.wenjian = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.wenjian.ButtonPushedFcn = createCallbackFcn(app, @wenjianButtonPushed, true);
            app.wenjian.Text = '打开文件';
            app.biaoding = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.biaoding.ButtonPushedFcn = createCallbackFcn(app, @biaodingButtonPushed, true);
            app.biaoding.Text = '标定车牌区域';
            app.fenge = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.fenge.ButtonPushedFcn = createCallbackFcn(app, @fengeButtonPushed, true);
            app.fenge.Text = '分割字符';
            app.shibie_moban = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.shibie_moban.ButtonPushedFcn = createCallbackFcn(app, @shibie_mobanButtonPushed, true);
            app.shibie_moban.Text = '识别车牌（模板识别）';
            app.shibie_cnn = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.shibie_cnn.ButtonPushedFcn = createCallbackFcn(app, @shibie_cnnButtonPushed, true);
            app.shibie_cnn.Text = '识别车牌（CNN）';
            app.yijianshibie = uibutton(app.Licenseplaterecognition20UIFigure, 'push');
            app.yijianshibie.ButtonPushedFcn = createCallbackFcn(app, @yijianshibieButtonPushed, true);
            app.yijianshibie.Text = '一键识别（CNN）';
            app.DesignLabel = uilabel(app.Licenseplaterecognition20UIFigure);
            app.DesignLabel.Text = 'Design';
            app.ByLabel = uilabel(app.Licenseplaterecognition20UIFigure);
            app.ByLabel.Text = 'By';
            app.PHYLabel = uilabel(app.Licenseplaterecognition20UIFigure);
            app.PHYLabel.Text = 'PHY';
            app.result = uilabel(app.Licenseplaterecognition20UIFigure);
            app.result.Text = '';
            app.biaoti = uilabel(app.Licenseplaterecognition20UIFigure);
            app.biaoti.Text = '车牌识别程序2.0';
            % Show the figure after all components are created
            app.Licenseplaterecognition20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = License_plate_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Licenseplaterecognition20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Licenseplaterecognition20UIFigure)
        end
    end
end