classdef Aapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        BappDT         % Bapp
        SD = 35;       % 初始化Surface sample size
        C = 'Parula'; % 初始化Colormap    
    end 
    
    methods (Access = public)
        
        function updateplot(app,SD,C)
            % 储存输入参数作为属性值
            app.SD = SD;
            app.C = C;
            
            % 更新绘图 
            Z = peaks(SD);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,C);
            
            % 启用Button按钮
            app.Button.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Aapp, SD, C)
            % 调用updateplot函数初始化绘图
            updateplot(app, app.SD, app.C)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 当Button按钮被按下后，Bapp弹出
            % 此时Aapp按钮变灰不能操作，避免多次调用Bapp
            app.Button.Enable = 'off';
            
            % 打开Bapp，接收要传递的参数
            app.BappDT = Bapp(app, app.SD, app.C);        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 当主程序主界面关闭后删除两个
            delete(app.BappDT)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end