classdef App_3_10_Spinner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button        matlab.ui.control.Button
        Lamp          matlab.ui.control.Lamp
        LampLabel     matlab.ui.control.Label
        Spinner       matlab.ui.control.Spinner
        SpinnerLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            % 避免颜色值过大提示错误
            if value >= 1/0.15
                value = 1/0.15
                app.Spinner.Enable = 0;
            else
                app.Spinner.Enable = 1;
            end
           app.Lamp.Color = value*[0.15 0.15 0.15];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Spinner.Value = 0;
            app.Lamp.Color = [0.15 0.15 0.15];
            app.Spinner.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Step = 0.5;
            app.Spinner.Limits = [0 10];
            app.Spinner.ValueDisplayFormat = '%.1f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '恢复';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_10_Spinner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end