classdef App_3_12_DatePicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DatePicker       matlab.ui.control.DatePicker
        DatePickerLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DatePicker
        function DatePickerValueChanged(app, event)
            %value = app.DatePicker.Value;
            lastdate = char(event.PreviousValue);
            newdate = char(event.Value);
            msg = ['把日期从' lastdate '改到' newdate '?'];
            % Confirm new date
            selection = uiconfirm(app.UIFigure,msg,'确认日期更改');            
            if (strcmp(selection,'Cancel'))
                % Revert to previous selection if cancelled
                app.DatePicker.Value = event.PreviousValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.DatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePickerValueChanged, true);
            app.DatePicker.Value = datetime([2020 11 23]);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_12_DatePicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end