classdef App_3_14_Treeapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        Tree            matlab.ui.container.Tree
        NewYork         matlab.ui.container.TreeNode
        TexasNode       matlab.ui.container.TreeNode
        LosAngelesNode  matlab.ui.container.TreeNode
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 读取电子表格中病人信息
            t = readtable('病人信息.xlsx');
            % 创建纽约的病人节点
            patients = t(strcmp(t.City,app.NewYork.Text) ...
                & t.Infect, 'Name');
            for j = 1:size(patients,1)
                name = char(patients{j,'Name'});
                uitreenode(app.NewYork,'Text',name);
            end
            % 创建田纳西的病人节点
            patients = t(strcmp(t.City,app.TexasNode.Text) ...
                & t.Infect & t.Age ~= 0,'Name');
            for j = 1:size(patients,1)
                name = char(patients{j,'Name'});
                uitreenode(app.TexasNode,'Text',name);
            end
            % 创建洛杉矶的病人节点
            patients = t(strcmp(t.City,app.LosAngelesNode.Text) ...
                & t.Infect & t.Age > 44,'Name');
            for j = 1:size(patients,1)
                name = char(patients{j,'Name'});
                uitreenode(app.LosAngelesNode,'Text',name);
            end
            % Set table row names to last names
            t.Properties.RowNames = t.Name;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure);
            app.NewYork = uitreenode(app.Tree);
            app.NewYork.Text = 'NewYork';
            app.TexasNode = uitreenode(app.Tree);
            app.TexasNode.Text = 'Texas';
            app.LosAngelesNode = uitreenode(app.Tree);
            app.LosAngelesNode.Text = 'LosAngeles';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择城市查看新冠病人';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_14_Treeapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end