classdef App_3_20_FrictionF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Hyperlink                      matlab.ui.control.Hyperlink
        Button                         matlab.ui.control.Button
        fEditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        RoEditField                    matlab.ui.control.NumericEditField
        kgm3EditFieldLabel             matlab.ui.control.Label
        uEditField                     matlab.ui.control.NumericEditField
        PasEditFieldLabel              matlab.ui.control.Label
        VEditField                     matlab.ui.control.NumericEditField
        msEditFieldLabel               matlab.ui.control.Label
        kEditField                     matlab.ui.control.NumericEditField
        mEditField_2Label              matlab.ui.control.Label
        DEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        fTextArea                      matlab.ui.control.TextArea
        fLabel                         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        GERG_Friction_Factor_Equation  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 将文本区域的标签的Interpreter设置为latex
            app.fLabel.Interpreter = 'latex';
            % 标签显示fn
            app.fLabel.Text = {'$${f_{n}}$$'};            
        end

        % Menu selected function: GERG_Friction_Factor_Equation
        function GERG_Friction_Factor_EquationSelected(app, event)
            % 新建一个UI窗口
            fig = uifigure;
            % 在UI窗口中建立一个标签
            lbl = uilabel(fig);
            % 设定标签的在UI窗口中的位置和大小
            lbl.Position = [10 0 500 500]% [left bottom width height]
            % 将文字换行设置on
            lbl.WordWrap = 'on'
            % 标签的Interpreter设置为latex
            lbl.Interpreter = 'latex';
            % 显示两组字符串，一组为GERG摩阻系数公式，一组为公式的说明文字
            str1 = ['$${1 \over {\sqrt \lambda  }} = ' ...
                '  - {2 \over n}{\log _{10}}\left[ {{{\left( ' ...
                '{{{k/D} \over {3.71}}} \right)}^n} + {{\left( {{{1.499} ' ...
                '\over {f \cdot {\mathop{\rm Re}\nolimits}  \cdot \sqrt \lambda  }}}' ...
                '  \right)}^{0.942 \cdot n \cdot f}}} \right]$$']
            str2 = ['λ为Darcy-Weisban摩擦系数，无量纲量；k为管道内壁粗糙度，m；' ...
                'D为管道内径，m；f为阻力因子（等同于输送效率），无量纲量；' ...
                'n为幂指数，描述从光滑管到粗糙管转变的变化剧烈程度；Re为雷诺数，无量纲量。']
            lbl.Text = {str1;str2}
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            D = app.DEditField.Value;% 管径
            k = app.kEditField.Value;% 粗糙度
            V = app.VEditField.Value;% 流速
            u = app.uEditField.Value;% 动力粘度
            Ro = app.RoEditField.Value;%流体密度
            f = app.fEditField.Value;% 输送效率
            Re = Ro*V*D/u;% 雷诺数
            x1=[1:10].*0;% 预分配大小
            % 一次性求出对应n=1~10的10个摩阻系数值
            syms x
            for n=1:10
                y = 1./x.^0.5+2./n.*log10((k/D/3.71).^n+...
                    (1.499/f/Re/x^0.5).^(0.942.*n.*f));
                x1(n) = vpasolve(y,x,[-inf inf]);
            end
            % 将十组摩阻系数数据转制为10×1的列向量
            % 将是个摩阻系数值转为字符串形式，
            % 并设定为保留十位小数的科学计数法格式
            x=[num2str(x1(1:10)','%.10e')];
            % 10组摩阻系数值在文本区域内显示
            app.fTextArea.Value = string(x);  
            % 在坐标区画出n=[1:10]的摩阻系数值
            plot(app.UIAxes,[1:10],x1,'b*');
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.Hyperlink.URL = 'https://xueshu.baidu.com/usercenter/paper/show?paperid=e3ef124364c751cdca30da64f0f4b9db&site=xueshu_se'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = 'f';
            app.fTextArea = uitextarea(app.UIFigure);
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '管径，m';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.Value = 0.8;
            app.mEditField_2Label = uilabel(app.UIFigure);
            app.mEditField_2Label.Text = '粗糙度，m';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditField.Limits = [0 Inf];
            app.kEditField.ValueDisplayFormat = '%11.6e';
            app.kEditField.Value = 0.0015;
            app.msEditFieldLabel = uilabel(app.UIFigure);
            app.msEditFieldLabel.Text = '流速，m/s';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VEditField.ValueDisplayFormat = '%11.6e';
            app.VEditField.Value = 92.10355503;
            app.PasEditFieldLabel = uilabel(app.UIFigure);
            app.PasEditFieldLabel.Text = '动力粘度，Pa·s';
            app.uEditField = uieditfield(app.UIFigure, 'numeric');
            app.uEditField.Limits = [0 Inf];
            app.uEditField.ValueDisplayFormat = '%11.6e';
            app.uEditField.Value = 1.10862e-05;
            app.kgm3EditFieldLabel = uilabel(app.UIFigure);
            app.kgm3EditFieldLabel.Text = '流体密度，kg/m^3';
            app.RoEditField = uieditfield(app.UIFigure, 'numeric');
            app.RoEditField.ValueDisplayFormat = '%11.6e';
            app.RoEditField.Value = 6.12129;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '输送效率，0-1';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditField.Value = 0.9;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'GERG方程网址';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.GERG_Friction_Factor_Equation = uimenu(app.ContextMenu);
            app.GERG_Friction_Factor_Equation.MenuSelectedFcn = createCallbackFcn(app, @GERG_Friction_Factor_EquationSelected, true);
            app.GERG_Friction_Factor_Equation.Text = 'GERG_Friction_Factor_Equation';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_20_FrictionF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end