classdef Button_2020_11_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=num2str(2^6);
            %创建一个结构体，MATLAB 按 TeX 格式解释消息。
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            %创建消息对话框，并将幂符号^解释为上标
            %使用字符连接符号+进行字符连接
            h= msgbox("2^6="+a,'Value',CreateStruct);
            h1 = findobj(h, 'Type', 'text');%获取句柄属性
            %设置字体大小
            set(h1, 'FontSize', 19, 'Unit', 'normal');
            set(h, 'resize', 'on')%手动改变对话框大小
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计   算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Button_2020_11_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end