classdef App_3_4_ButtonGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        EditField_3  matlab.ui.control.NumericEditField
        EditField_2  matlab.ui.control.NumericEditField
        EditField    matlab.ui.control.NumericEditField
        Button3      matlab.ui.control.RadioButton
        Button2      matlab.ui.control.RadioButton
        Button       matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Button2.Value = true;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;            
            if strcmp(selectedButton.Text,'Button3')==1                
                app.ButtonGroup.Title = "选择了数字"+...
                    app.EditField_3.Value;
            elseif strcmp(selectedButton.Text,'Button2')==1
                app.ButtonGroup.Title = "选择了数字"+...
                    app.EditField_2.Value;
            else
                app.ButtonGroup.Title = "选择了数字"+...
                    app.EditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择数字';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_2.Value = 2;
            app.EditField_3 = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_3.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_4_ButtonGroup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end