classdef ToggleButtonGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button6      matlab.ui.control.ToggleButton
        Button5      matlab.ui.control.ToggleButton
        Button4      matlab.ui.control.ToggleButton
        Button3      matlab.ui.control.ToggleButton
        Button2      matlab.ui.control.ToggleButton
        Button1      matlab.ui.control.ToggleButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '1层'
                    app.Button1.BackgroundColor=[0.00,1.00,0.00];
                    app.Button2.BackgroundColor=[0.96,0.96,0.96];
                    app.Button3.BackgroundColor=[0.96,0.96,0.96];
                    app.Button4.BackgroundColor=[0.96,0.96,0.96];
                    app.Button5.BackgroundColor=[0.96,0.96,0.96];
                    app.Button6.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F1.wav');
                    sound(y,Fs)
                case '2层'
                    app.Button2.BackgroundColor=[0.00,1.00,0.00];
                    app.Button1.BackgroundColor=[0.96,0.96,0.96];
                    app.Button3.BackgroundColor=[0.96,0.96,0.96];
                    app.Button4.BackgroundColor=[0.96,0.96,0.96];
                    app.Button5.BackgroundColor=[0.96,0.96,0.96];
                    app.Button6.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F2.wav');
                    sound(y,Fs)
                case '3层'
                    app.Button3.BackgroundColor=[0.00,1.00,0.00];
                    app.Button1.BackgroundColor=[0.96,0.96,0.96];
                    app.Button2.BackgroundColor=[0.96,0.96,0.96];
                    app.Button4.BackgroundColor=[0.96,0.96,0.96];
                    app.Button5.BackgroundColor=[0.96,0.96,0.96];
                    app.Button6.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F3.wav');
                    sound(y,Fs)
                case '4层'
                    app.Button4.BackgroundColor=[0.00,1.00,0.00];
                    app.Button1.BackgroundColor=[0.96,0.96,0.96];
                    app.Button2.BackgroundColor=[0.96,0.96,0.96];
                    app.Button3.BackgroundColor=[0.96,0.96,0.96];
                    app.Button5.BackgroundColor=[0.96,0.96,0.96];
                    app.Button6.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F4.wav');
                    sound(y,Fs)
                case '5层'
                    app.Button5.BackgroundColor=[0.00,1.00,0.00];
                    app.Button1.BackgroundColor=[0.96,0.96,0.96];
                    app.Button2.BackgroundColor=[0.96,0.96,0.96];
                    app.Button3.BackgroundColor=[0.96,0.96,0.96];
                    app.Button4.BackgroundColor=[0.96,0.96,0.96];
                    app.Button6.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F5.wav');
                    sound(y,Fs)
                otherwise
                    app.Button6.BackgroundColor=[0.00,1.00,0.00];
                    app.Button1.BackgroundColor=[0.96,0.96,0.96];
                    app.Button2.BackgroundColor=[0.96,0.96,0.96];
                    app.Button3.BackgroundColor=[0.96,0.96,0.96];
                    app.Button4.BackgroundColor=[0.96,0.96,0.96];
                    app.Button5.BackgroundColor=[0.96,0.96,0.96];
                    [y,Fs] = audioread('F6.wav');
                    sound(y,Fs)
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button1 = uitogglebutton(app.ButtonGroup);
            app.Button1.Text = '1层';
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = '2层';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = '3层';
            app.Button3.Value = true;
            app.Button4 = uitogglebutton(app.ButtonGroup);
            app.Button4.Text = '4层';
            app.Button5 = uitogglebutton(app.ButtonGroup);
            app.Button5.Text = '5层';
            app.Button6 = uitogglebutton(app.ButtonGroup);
            app.Button6.Text = '6层';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToggleButtonGroup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end