classdef App_3_9_CheckBox188 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TextArea_2  matlab.ui.control.TextArea
        TextArea    matlab.ui.control.TextArea
        CheckBox2   matlab.ui.control.CheckBox
        CheckBox    matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value==1 & app.CheckBox2.Value ~=1
                msgbox("你选中了"+app.TextArea.Value)
            else
                msgbox("只能选一个哟")
            end            
        end

        % Value changed function: CheckBox2
        function CheckBox2ValueChanged(app, event)
            value = app.CheckBox2.Value
            if value==1 & app.CheckBox.Value ~=1
                msgbox("你选中了"+app.TextArea_2.Value)
            else
                msgbox("只能选一个哟")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBox2ValueChanged, true);
            app.CheckBox2.Text = 'Check Box2';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'小张'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'小李'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_9_CheckBox188

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end