classdef App_4_1_GridLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        Button         matlab.ui.control.Button
        x2SliderLabel  matlab.ui.control.Label
        x2Slider       matlab.ui.control.Slider
        x1SliderLabel  matlab.ui.control.Label
        x1Slider       matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=min(app.x1Slider.Value,app.x2Slider.Value);
            b=max(app.x1Slider.Value,app.x2Slider.Value);
            if app.DropDown.Value=='sin'
                x=a:0.1:b;
                plot(app.UIAxes,sin(x));
                title(app.UIAxes, ['三角函数图形绘制：','sin(x)'])
            elseif app.DropDown.Value=='cos'
                x=a:0.1:b;
                plot(app.UIAxes,cos(x));
                title(app.UIAxes, ['三角函数图形绘制：','cos(x)'])
            elseif app.DropDown.Value=='tan'
                x=a:0.1:b;
                plot(app.UIAxes,tan(x));
                title(app.UIAxes, ['三角函数图形绘制：','tan(x)'])
            elseif app.DropDown.Value=='cot'
                x=a:0.1:b;
                plot(app.UIAxes,cot(x));
                title(app.UIAxes, ['三角函数图形绘制：','cot(x)'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '三角函数图形绘制')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'sin', 'cos', 'tan', 'cot'};
            app.DropDown.Value = 'sin';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'选择'; '函数'};
            app.x1Slider = uislider(app.GridLayout);
            app.x1Slider.Limits = [-10 10];
            app.x1Slider.Value = -5;
            app.x1SliderLabel = uilabel(app.GridLayout);
            app.x1SliderLabel.Text = 'x1';
            app.x2Slider = uislider(app.GridLayout);
            app.x2Slider.Limits = [-10 10];
            app.x2Slider.Value = 5;
            app.x2SliderLabel = uilabel(app.GridLayout);
            app.x2SliderLabel.Text = 'x2';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_4_1_GridLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end