classdef App_4_2_TabGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        Button         matlab.ui.control.Button
        x2Slider       matlab.ui.control.Slider
        x2SliderLabel  matlab.ui.control.Label
        x1Slider       matlab.ui.control.Slider
        x1SliderLabel  matlab.ui.control.Label
        DropDown_3     matlab.ui.control.DropDown
        DropDown_2     matlab.ui.control.DropDown
        DropDown       matlab.ui.control.DropDown
        Tab2           matlab.ui.container.Tab
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a = min(app.x1Slider.Value,app.x2Slider.Value);
            b = max(app.x1Slider.Value,app.x2Slider.Value);
            if app.DropDown_2.Value =='sin'
                if app.DropDown_3.Value =='sin'
                    if app.DropDown.Value =='+'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x)+sin(x));
                    elseif  app.DropDown.Value =='-'
                        msgbox("不画图，没意义")
                    elseif  app.DropDown.Value =='*'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x).*sin(x));
                    elseif  app.DropDown.Value =='/'
                        msgbox("不画图，没意义")
                    end
                elseif app.DropDown_3.Value =='cos'
                    if app.DropDown.Value =='+'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x)+sin(x));
                    elseif  app.DropDown.Value =='-'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x)-cos(x));
                    elseif  app.DropDown.Value =='*'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x).*cos(x));
                    elseif  app.DropDown.Value =='/'
                        x=a:0.1:b;
                        plot(app.UIAxes,sin(x)./cos(x));
                    end
                end
            end
            app.TabGroup.SelectedTab = app.Tab2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'+', '-', '*', '/'};
            app.DropDown.Value = '+';
            app.DropDown_2 = uidropdown(app.Tab);
            app.DropDown_2.Items = {'sin', 'cos', 'tan', 'cot', ''};
            app.DropDown_2.Value = 'sin';
            app.DropDown_3 = uidropdown(app.Tab);
            app.DropDown_3.Items = {'sin', 'cos', 'tan', 'cot', ''};
            app.DropDown_3.Value = 'sin';
            app.x1SliderLabel = uilabel(app.Tab);
            app.x1SliderLabel.Text = 'x1';
            app.x1Slider = uislider(app.Tab);
            app.x1Slider.Limits = [-100 100];
            app.x1Slider.Value = 5;
            app.x2SliderLabel = uilabel(app.Tab);
            app.x2SliderLabel.Text = 'x2';
            app.x2Slider = uislider(app.Tab);
            app.x2Slider.Limits = [-100 100];
            app.x2Slider.Value = -5;
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_4_2_TabGroup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end