classdef App_4_3_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        DropDown_3        matlab.ui.control.DropDown
        DropDown_2        matlab.ui.control.DropDown
        Label_2           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        DropDown          matlab.ui.control.DropDown
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel.Scrollable = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=['姓   名：' app.EditField.Value];
            b=['性   别：' app.DropDown.Value];
            c=['家庭住址：' app.DropDown_2.Value app.DropDown_3.Value];
            d=['手机号码：' num2str(app.EditField_3.Value)];
            str=[a,10,b,10,c,10,d];
            app.TextArea.Value=str
            app.Panel.Scrollable = 'on';
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value=='北京市'
                app.DropDown_3.Items = {'东城区', '西城区',...
                    '朝阳区', '丰台区', '石景山区', '海淀区', ...
                    '门头沟区', '房山区', '通州区', '顺义区',...
                    '昌平区', '大兴区', '怀柔区', '平谷区', ...
                    '密云区', '延庆区', ''};
            elseif value=='天津市'
                app.DropDown_3.Items ={'和平区', '河东区',...
                    '河西区', '南开区', '河北区', '红桥区', ...
                    '东丽区', '西青区', '津南区', '北辰区', ...
                    '武清区', '宝坻区', '滨海新区', '宁河区',...
                    '静海区', '蓟州区', ''};
            elseif value=='河北省'
                app.DropDown_3.Items ={'唐山市', '秦皇岛市', ...
                    '邯郸市', '邢台市', '保定市', '张家口市',...
                    '承德市', '沧州市', '廊坊市', '衡水市', ''};  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '回乡登记';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '姓名';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.Value = '张三';
            app.EditField_3Label = uilabel(app.Panel);
            app.EditField_3Label.Text = '手机号码';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%11.11g';
            app.EditField_3.Value = 15611100638;
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'男', '女'};
            app.DropDown.Value = '男';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '住址';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'北京市', '天津市', '河北省', '山西省', '内蒙古自治区', '辽宁省', '吉林省', '黑龙江省', '上海市', '江苏省', '浙江省', '安徽省', '福建省', '江西省', '山东省', '河南省', '湖北省', '湖南省', '广东省', '广西壮族自治区', '海南省', '重庆市', '四川省', '贵州省', '云南省', '西藏自治区', '陕西省', '甘肃省', '青海省', '宁夏回族自治区', '新疆维吾尔自治区'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '北京市';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'东城区', '西城区', '朝阳区', '丰台区', '石景山区', '海淀区', '门头沟区', '房山区', '通州区', '顺义区', '昌平区', '大兴区', '怀柔区', '平谷区', '密云区', '延庆区', ''};
            app.DropDown_3.Value = '东城区';
            app.TextArea = uitextarea(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_4_3_Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end