classdef App_4_4_LoanCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Button                    matlab.ui.control.Button
        MonthlyPaymentEditField   matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        LoanPeriodYearsEditField  matlab.ui.control.NumericEditField
        Label_3                   matlab.ui.control.Label
        InterestRateEditField     matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        LoanAmountEditField       matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             % 计算每月支付的金额
            amount = app.LoanAmountEditField.Value;
            rate = app.InterestRateEditField.Value/12/100;
            nper = 12*app.LoanPeriodYearsEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % 预分配和初始化变量
            interest = zeros(1,nper);%利息
            principal = zeros(1,nper);%本金
            balance = zeros (1,nper);%结余
            
            balance(1) = amount;
            
            % 计算一段时间内的本金和利息
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % 绘制本金和利息
            plot(app.UIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.UIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.UIAxes,[0 nper]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {280, 280};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {119, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '贷款总额';
            app.LoanAmountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LoanAmountEditField.ValueDisplayFormat = '%.0f';
            app.LoanAmountEditField.Value = 500000;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '利率,%';
            app.InterestRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InterestRateEditField.Value = 5;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '贷款年限';
            app.LoanPeriodYearsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LoanPeriodYearsEditField.Value = 30;
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '每月还款额';
            app.MonthlyPaymentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%.0f';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_4_4_LoanCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end