classdef App_5_2_Toolbar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Toolbar     matlab.ui.container.Toolbar
        PushTool    matlab.ui.container.toolbar.PushTool
        ToggleTool  matlab.ui.container.toolbar.ToggleTool
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            x=-5:0.1:5;
            plot(app.UIAxes,sin(x));
        end

        % On callback: ToggleTool
        function ToggleToolOn(app, event)
            tt=app.ToggleTool;%获取ToggleTool句柄
            ttImage = zeros(16,16,3);%生成黑色的图片
            tt.Icon = ttImage;%将图片赋给ToggleTool图标
            x=-5:0.1:5;
            plot(app.UIAxes,cos(x));
        end

        % Off callback: ToggleTool
        function ToggleToolOff(app, event)
            tt=app.ToggleTool;
            ttImage(:,:,3) = ones(16);%生成蓝色的图片
            tt.Icon = ttImage;
            cla(app.UIAxes)%清空坐标区图像
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.OffCallback = createCallbackFcn(app, @ToggleToolOff, true);
            app.ToggleTool.OnCallback = createCallbackFcn(app, @ToggleToolOn, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_5_2_Toolbar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end