classdef App_5_3_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        FileMenu      matlab.ui.container.Menu
        OpenMenu      matlab.ui.container.Menu
        SaveAllMenu   matlab.ui.container.Menu
        SavePicMenu   matlab.ui.container.Menu
        SaveTextMenu  matlab.ui.container.Menu
        PlotMenu      matlab.ui.container.Menu
        sinMenu       matlab.ui.container.Menu
        cosMenu       matlab.ui.container.Menu
        TextArea      matlab.ui.control.TextArea
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 启动程序自动执行以下内容 
            % 获取当前路径并显示在文本区域组件中
            app.TextArea.Value = pwd;
            % 绘制余切曲线图形显示在坐标区中
            x = -5:0.1:5; plot(app.UIAxes,cot(x));
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % 获取文件路径
            [filename,path] = ...
                uigetfile({'*.*'},'选择','选择文件')
            str = [path '\' filename];% 打开文件或获得文件的ID            
            fileID = fopen(filename);
            %文件读取器对象
            fr = matlab.io.datastore.DsFileReader(str);
            % 从文件中读取字节
            C =  read(fr,1000,'OutputType','char')
            fclose(fileID);% 关闭文件
            % 将字节赋值给文本区域组件的Value值
            app.TextArea.Value = C;
        end

        % Menu selected function: SaveAllMenu
        function SaveAllMenuSelected(app, event)
            % 通过用分号分隔 filter 输入参数中的每个文件扩展名，
            % 在保存类型列表框中显示多个文件类型。
            filter = {'*.jpg';'*.png';'*.tif'};
            % 打开用于保存文件的对话框
            %filename-用户指定的文件名，
            % filepath-用户指定的文件名的路径
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                % 以图像或 PDF 格式捕获 App
                % 导出 fig 指定的图窗的内容，
                % 并将其存储在 filename 指定的文件中。
                % 必须使用 uifigure 函数
                % 或 App 设计工具来创建图窗。
                % 捕获所有图形内容，包括 UI 组件。
                % 支持的文件类型有 JPEG、PNG、TIFF 和 PDF。
                exportapp(app.UIFigure,[filepath filename]);
            end
        end

        % Menu selected function: SavePicMenu
        function SavePicMenuSelected(app, event)
            filter = {'*.jpg';'*.png';'*.tif'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                % 将 obj 指定的图形对象的内容保存到文件中。
                % 图形对象可以是任何类型的坐标区、图窗、独立可视化、
                % 分块图布局或图窗内的容器。生成的图形经过紧密裁剪，
                % 内容周围只留很窄的边距。
                % 指定保存文件的其他选项。例如，
                %exportgraphics(gca,'my.jpg','Resolution',300) 
                % 将当前坐标区的内容保存为 300-DPI 图像文件。
                exportgraphics(app.UIAxes,...
                    [filepath filename],'Resolution',300)
                app.TextArea.Value =[filepath filename];
            end            
        end

        % Menu selected function: SaveTextMenu
        function SaveTextMenuSelected(app, event)
            %将文本内容转为字符串
            str5 = string(app.TextArea.Value);
            filter = {'*.txt';'*.m'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                str6='\';
                %获得文件绝对路径
                str7 = [filepath str6 filename];
                %打开文件，如果没有则创建。
                fileID = fopen(str7,"w");
                %写入文件
                nbytes = fprintf(fileID,'%s\n',str5);
                status = fclose(fileID);%关闭文件
                app.TextArea.Value = [str7];
            end
        end

        % Menu selected function: sinMenu
        function sinMenuSelected(app, event)
            x = -5:0.1:5; plot(app.UIAxes,sin(x));
        end

        % Menu selected function: cosMenu
        function cosMenuSelected(app, event)
            x = -5:0.1:5; plot(app.UIAxes,cos(x));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAllMenu = uimenu(app.FileMenu);
            app.SaveAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllMenuSelected, true);
            app.SaveAllMenu.Text = 'SaveAll';
            app.SavePicMenu = uimenu(app.FileMenu);
            app.SavePicMenu.MenuSelectedFcn = createCallbackFcn(app, @SavePicMenuSelected, true);
            app.SavePicMenu.Text = 'SavePic';
            app.SaveTextMenu = uimenu(app.FileMenu);
            app.SaveTextMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTextMenuSelected, true);
            app.SaveTextMenu.Text = 'SaveText';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.sinMenu = uimenu(app.PlotMenu);
            app.sinMenu.MenuSelectedFcn = createCallbackFcn(app, @sinMenuSelected, true);
            app.sinMenu.Text = 'sin';
            app.cosMenu = uimenu(app.PlotMenu);
            app.cosMenu.MenuSelectedFcn = createCallbackFcn(app, @cosMenuSelected, true);
            app.cosMenu.Text = 'cos';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '路径';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_5_3_Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end