classdef App_5_4_FigureTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        SolveMenu       matlab.ui.container.Menu
        PlotMenu        matlab.ui.container.Menu
        Toolbar         matlab.ui.container.Toolbar
        PushToolSolve   matlab.ui.container.toolbar.PushTool
        PushToolPlot    matlab.ui.container.toolbar.PushTool
        TextArea        matlab.ui.control.TextArea
        xTextAreaLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        Label           matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
        ContextMenu     matlab.ui.container.ContextMenu
        SaveMenu        matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SolveMenu
        function SolveMenuSelected(app, event)
            % 将编辑字段（文本）中公式转化为符号方程
            y = str2sym(app.EditField.Value);  
            % 求解方程
            x = vpasolve(y);
            % 将求解出的x值存入文本区域
            app.TextArea.Value = string(x);
        end

        % Menu selected function: PlotMenu
        function PlotMenuSelected(app, event)
            y =str2sym(app.EditField.Value)
            % 使用fplot函数绘图
            fplot(app.UIAxes,y,'b*')
            % 自动调整坐标区图形大小
            app.UIAxes.reset
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
           % 调用绘图函数，避免保存空图像
            PushToolPlotClicked(app, event)
            filter = {'*.jpg';'*.png';'*.tif'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                % 保存图像
                exportgraphics(app.UIAxes,[filepath filename]);
            end
        end

        % Clicked callback: PushToolSolve
        function PushToolSolveClicked(app, event)
            y = str2sym(app.EditField.Value);            
            x = vpasolve(y);
            app.TextArea.Value = string(x);
        end

        % Clicked callback: PushToolPlot
        function PushToolPlotClicked(app, event)
            y =str2sym(app.EditField.Value)
            fplot(app.UIAxes,y,'b*')
            app.UIAxes.reset
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SolveMenu = uimenu(app.FileMenu);
            app.SolveMenu.MenuSelectedFcn = createCallbackFcn(app, @SolveMenuSelected, true);
            app.SolveMenu.Text = '&Solve';
            app.PlotMenu = uimenu(app.FileMenu);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMenuSelected, true);
            app.PlotMenu.Text = '&Plot';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolSolve = uipushtool(app.Toolbar);
            app.PushToolSolve.ClickedCallback = createCallbackFcn(app, @PushToolSolveClicked, true);
            app.PushToolSolve.Icon = 'CAlc.jpg';
            app.PushToolPlot = uipushtool(app.Toolbar);
            app.PushToolPlot.ClickedCallback = createCallbackFcn(app, @PushToolPlotClicked, true);
            app.PushToolPlot.Icon = '快照1.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '方程';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'x^2-4*x+4';
            app.xTextAreaLabel = uilabel(app.UIFigure);
            app.xTextAreaLabel.Text = 'x=';
            app.TextArea = uitextarea(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SaveMenu = uimenu(app.ContextMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = '&Save';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_5_4_FigureTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end