classdef App_6_1_Gauge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Slider        matlab.ui.control.Slider
        Label_2       matlab.ui.control.Label
        Gauge_Speed   matlab.ui.control.Gauge
        Gauge_2Label  matlab.ui.control.Label
        Gauge_Cool    matlab.ui.control.NinetyDegreeGauge
        Label         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge_Speed.Value = changingValue;
            if changingValue<60
                app.Gauge_Cool.Value = changingValue^0.9;
            elseif changingValue < 100
                app.Gauge_Cool.Value = changingValue^0.9;
            elseif changingValue > 150
                msgbox("开太猛了，慢点")                
            else
                app.Gauge_Cool.Value = changingValue^0.8;
            end              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '冷却液温度';
            app.Gauge_Cool = uigauge(app.UIFigure, 'ninetydegree');
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '车速';
            app.Gauge_Speed = uigauge(app.UIFigure, 'circular');
            app.Gauge_Speed.Limits = [0 300];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '车速';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 300];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_1_Gauge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end