classdef App_6_2_Lamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label_4   matlab.ui.control.Label
        Panel     matlab.ui.container.Panel
        Label_3   matlab.ui.control.Label
        Lamp3     matlab.ui.control.Lamp
        Lamp2     matlab.ui.control.Lamp
        Label_2   matlab.ui.control.Label
        Lamp1     matlab.ui.control.Lamp
        Label     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_4.Text = "右转灯亮，右转车辆通行";
            pause(10)
            app.Lamp1.Color = [0 1 0];
            app.Lamp3.Color = [1 1 0.07];
            app.Lamp2.Color = [1 0 0];
            app.Label_4.Text = "左转灯亮，左转车辆通行";
            pause(10)
            app.Lamp2.Color = [0 1 0];
            app.Lamp1.Color = [1 1 0.07];
            app.Lamp3.Color = [1 0 0];
            app.Label_4.Text = "直行灯亮，直行车辆通行";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '交通灯';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '左转';
            app.Lamp1 = uilamp(app.Panel);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '直行';
            app.Lamp2 = uilamp(app.Panel);
            app.Lamp3 = uilamp(app.Panel);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '右转';
            app.Label_4 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_2_Lamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end