classdef App_6_3_DiscreteKnob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Lamp        matlab.ui.control.Lamp
        LampLabel   matlab.ui.control.Label
        Knob2       matlab.ui.control.DiscreteKnob
        Knob2Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LampLabel.Text = '空调开启'
        end

        % Value changed function: Knob2
        function Knob2ValueChanged(app, event)
            value = app.Knob2.Value;
            if app.Knob2.Value == "Freezing"
                app.LampLabel.Text = '冰冻模式'
                app.Lamp.Color = [1 1 1];
            elseif app.Knob2.Value == "Cold"
                app.LampLabel.Text = '凉爽模式'
                app.Lamp.Color = [0.3922 0.8314 0.0745];
            elseif app.Knob2.Value == "Warm"
                app.LampLabel.Text = '温暖模式'
                app.Lamp.Color = [0.9294 0.6941 0.1255];
            elseif app.Knob2.Value == "Hot"
                app.LampLabel.Text = '加热模式'
                app.Lamp.Color = [1 0 0];
            else
                app.LampLabel.Text = '空调关闭'
                app.Lamp.Color = [0.8 0.8 0.8];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Knob2Label = uilabel(app.UIFigure);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.UIFigure, 'discrete');
            app.Knob2.Items = {'on', 'Freezing', 'Cold', 'Warm', 'Hot', 'off'};
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @Knob2ValueChanged, true);
            app.Knob2.Value = 'on';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_3_DiscreteKnob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end