classdef App_6_4_Knob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Knob       matlab.ui.control.Knob
        KnobLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = round(app.Knob.Value)
            if value == 96
                app.KnobLabel.Text ="欢迎收听山东人民广播电台经济台";
            elseif value == 97
                app.KnobLabel.Text ="欢迎收听山东文艺广播电台";
            elseif value == 101
                app.KnobLabel.Text ="欢迎收听山东人民广播电台交通音乐之声";
            elseif value == 103
                app.KnobLabel.Text ="欢迎收听济南人民广播电台交通音乐台";
            elseif value == 104
                app.KnobLabel.Text ="欢迎收听山东人民广播电台";
            elseif value == 105
                app.KnobLabel.Text ="欢迎收听山东人民广播电台生活频道";
            elseif value == 106
                app.KnobLabel.Text ="欢迎收听济南人民广播电台";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [96 107];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 96;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_4_Knob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end