classdef App_6_5_Switch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SwitchSpeed   matlab.ui.control.Switch
        Label_4       matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        Label_5       matlab.ui.control.Label
        SwitchStart   matlab.ui.control.ToggleSwitch
        Label_3       matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        Label_2       matlab.ui.control.Label
        Gauge_Speed   matlab.ui.control.Gauge
        Gauge_2Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slider.Enable=0;            
        end

        % Value changed function: SwitchStart
        function SwitchStartValueChanged(app, event)
            value = app.SwitchStart.Value
            if value == '1'
                app.Slider.Enable = 1;
                app.Lamp.Color = [0 1 0];
            elseif value== '0'
                app.Slider.Enable = 0;
                app.Lamp.Color = [0 0 0];
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge_Speed.Value = changingValue;
            if changingValue > 150
                app.Label_5.Text = "开太猛了，慢点开" 
            end              
        end

        % Value changed function: SwitchSpeed
        function SwitchSpeedValueChanged(app, event)
            value = str2num(app.SwitchSpeed.Value);
            if value == 1 && str2num(app.SwitchStart.Value) == 1;
                app.Gauge_Speed.BackgroundColor = [0.00,1.00,1.00];
            elseif value == 0
                app.Gauge_Speed.BackgroundColor = [1.00,1.00,1.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '车速';
            app.Gauge_Speed = uigauge(app.UIFigure, 'circular');
            app.Gauge_Speed.Limits = [0 300];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '车速';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 300];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '启动/熄火';
            app.SwitchStart = uiswitch(app.UIFigure, 'toggle');
            app.SwitchStart.Items = {'0', '1'};
            app.SwitchStart.ValueChangedFcn = createCallbackFcn(app, @SwitchStartValueChanged, true);
            app.SwitchStart.Value = '0';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '启动指示灯';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '仪表盘灯';
            app.SwitchSpeed = uiswitch(app.UIFigure, 'slider');
            app.SwitchSpeed.Items = {'0', '1'};
            app.SwitchSpeed.ValueChangedFcn = createCallbackFcn(app, @SwitchSpeedValueChanged, true);
            app.SwitchSpeed.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_5_Switch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end