classdef App_6_6_Instrumentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Lamp          matlab.ui.control.Lamp
        LampLabel     matlab.ui.control.Label
        Switch        matlab.ui.control.ToggleSwitch
        Label_3       matlab.ui.control.Label
        Slider        matlab.ui.control.Slider
        Label_2       matlab.ui.control.Label
        Gauge_Speed   matlab.ui.control.Gauge
        Gauge_2Label  matlab.ui.control.Label
        Gauge_Cool    matlab.ui.control.NinetyDegreeGauge
        Label         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slider.Enable=0;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value
            if value == '1'
                app.Slider.Enable = 1;
                app.Lamp.Color = [0 1 0];
            elseif value== '0'
                app.Slider.Enable = 0;
                app.Lamp.Color = [0 0 0];
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge_Speed.Value = changingValue;
            if changingValue<60
                app.Gauge_Cool.Value = changingValue^0.9;
            elseif changingValue < 100
                app.Gauge_Cool.Value = changingValue^0.9;
            elseif changingValue > 150
                app.LampLabel.Text = "开太猛了，慢点开"               
            else
                app.Gauge_Cool.Value = changingValue^0.8;
            end              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '冷却液温度';
            app.Gauge_Cool = uigauge(app.UIFigure, 'ninetydegree');
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '车速';
            app.Gauge_Speed = uigauge(app.UIFigure, 'circular');
            app.Gauge_Speed.Limits = [0 300];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '车速';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 300];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '启动/熄火';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_6_6_Instrumentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end