classdef GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        edit3        matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        pushbutton5  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        axes6        matlab.ui.control.UIAxes
        axes5        matlab.ui.control.UIAxes
        axes4        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUI (see VARARGIN)
%             
%             % Choose default command line output for GUI
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            
            
            % 显示figure标记
            flag = 1;
            % 获取图片库文件列表
                Img =getimage(app.axes1);
                % 图像预处理
                [Im1, rectValide] = GetValideImage(Img, 0);%自定义的函数
                % 人脸区域定位
                [hsv,erzhi,bw1,BW2] = GetFaceAreaImg(Im1, 0);
                    axes(app.axes2); imshow(hsv, []); title('hsv空间图像');
                      axes(app.axes3); imshow(erzhi, []); title('二值化图像');
                    axes(app.axes4); imshow(bw1); title('形态学处理图像');
                     axes(app.axes5); imshow(BW2, []); title('人脸筛选区域图像');
            
                     %人脸标记
                     bwimage = BW2;
                     [L,nm] = bwlabel(bwimage,8);%找出图像中的八连通区域，视为字符所在区域
            axes(app.axes6);%%使用图像，操作在坐标6
            imshow( Img);%在坐标axes6显示
            title('定位');
            for i =1:nm
            %     stats = regionprops(L);
            %     Ar = cat(1, stats.Area);
            %     ind = find(Ar ==max(Ar));%找到最大连通区域的标号
                [r,c] = find(L ==i);
                left= min(c);%左边界
                right= max(c);%右边界
                top= min(r);%上边界
                buttom= max(r);%下边界
                width=right - left + 1;%宽度
                height = buttom - top + 1;%高度
                rectangle('Position',[left,top,width,height],'EdgeColor','r');%对连通域进行标记
                cropimage = imcrop(Img,[left,top,width,height]);%图像裁剪
                cropgray =rgb2gray(cropimage);
                cropgray = imresize(cropgray,[110,90])
                imwrite(cropgray,'人脸分割.bmp');
                pause(0.01);
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %%%打开图像程序
            [filename,pathname]=uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpg'},'选择图像');
            if isequal(filename,0)||isequal(pathname,0)
              errordlg('请选择图片','提示');%如果没有输入，则创建错误对话框
              return;
            else
                global im;
                im=[pathname,filename];%合成路径+文件名
                image=imread(im);%读取图像
                global m n k;
                [m n k]=size(image);
                set(app.axes1,'HandleVisibility','ON');%打开坐标，方便操作
                app.axes1;%%使用图像，操作在坐标1
                imshow(image);%在坐标axes1显示原图像
                title('原始图像');
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %%%提取PCA特征部分
            global reference;%全局变量
            global base;
            global imgmean;
            imgdata=[];%训练图像矩阵
            for i=1:5
              for j=1:5
                a=imread(strcat('.\train\s',num2str(i),'\',num2str(j),'.bmp'));
            %     name = strcat('.train\0',num2str(i),'-',num2str(j),'m.jpg');
            %     b=a(1:112*92); % b是行矢量 1×N，其中N＝10304
                b=a(1:110*90); % b是行矢量 1×N，其中N＝10304
                b=double(b);
                imgdata=[imgdata; b]; % imgdata 是一个M * N 矩阵，imgdata中每一行数据一张图片，M＝200
              end;
            end;
            imgdata=imgdata'; %每一列为一张图片
            imgmean=mean(imgdata,2); % 平均图片，N维列向量
            for i=1:25
              minus(:,i) = imgdata(:,i)-imgmean; % minus是一个N*M矩阵，是训练图和平均图之间的差值
            end;
            
            covx=minus'* minus; % M * M 阶协方差矩阵
            [COEFF, latent,explained] = pcacov(covx'); %PCA，用协方差矩阵的转置来计算以减小计算量
            
            %选择构成95%的能量的特征值
            i=1;
            proportion=0;
            while(proportion < 95)
              proportion=proportion+explained(i);
              i=i+1;
            end;
            p=i-1;
            
            % 训练得到特征脸坐标系
            i=1;
            while (i<=p && latent(i)>0)
              base(:,i) = latent(i)^(-1/2)*minus * COEFF(:,i); % base是N×p阶矩阵，用来进行投影，除以latent(i)^(1/2)是对人脸图像的标准化
              i = i + 1;
            end
            
            % 将训练样本对坐标系上进行投影,得到一个 p*M 阶矩阵为参考
            reference = base'*minus;
            msgbox('特征提取完成');
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %%%人脸识别程序，最小距离法
            global reference;
            global base;
            global imgmean;
            a=imread('人脸分割.bmp');
            b=a(1:9900);
            b=double(b);
            b=b';
            
            object = base'*(b-imgmean);
            distance=100000;
            
            %最小距离法，寻找和待识别图片最为接近的训练图片
            for k=1:25
                temp= norm(object - reference(:,k));
                if (distance > temp)
                    which = k;
                    distance = temp;
                end;
            end;
            temp1 = which/5;
            temp1 = ceil(temp1);
            switch temp1
                case 1
                    set(app.edit3,'string','黄晓明');
                      case 2
                    set(app.edit3,'string','杨幂');
                     case 3
                    set(app.edit3,'string','沈腾');
                          case 4
                    set(app.edit3,'string','迪丽热巴');
                          case 5
                    set(app.edit3,'string','刘德华');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = '人脸识别系统';
            app.axes1 = uiaxes(app.figure1);
            app.axes2 = uiaxes(app.figure1);
            app.axes3 = uiaxes(app.figure1);
            app.axes4 = uiaxes(app.figure1);
            app.axes5 = uiaxes(app.figure1);
            app.axes6 = uiaxes(app.figure1);
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = '人脸定位';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.Text = '关    闭';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = '打开图像';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = '特征提取';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Text = '人脸识别';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = '识别结果';
            app.edit3 = uieditfield(app.figure1, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end