classdef fc_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        face_recognition  matlab.ui.Figure
        Image             matlab.ui.control.Image
        Panel             matlab.ui.container.Panel
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global img;
            [filename, pathname] = uigetfile('*.jpg', 'Select an image?');
            if ~ischar(filename); return; end      %user cancelled
            filepath = fullfile(pathname, filename);
            img = imread(filepath);
            faceDetector = vision.CascadeObjectDetector;
            bbox = step(faceDetector, img);
               
    
            for i = 1 : size(bbox, 1) 
                  img = imcrop(img, bbox(i, :)); 
            end
            imshow(img,'Parent',app.UIAxes2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global img;
            global pred;
            imdst = imageDatastore('Pictures\picture - train',"IncludeSubfolders",true,"FileExtensions",'.jpg',"LabelSource",'foldernames');
            imdsv = imageDatastore('Pictures\picture - validate',"IncludeSubfolders",true,"FileExtensions",'.jpg',"LabelSource",'foldernames');
            %crop train
            while hasdata(imdst)
               [im,info] = read(imdst); 
               
               faceDetector = vision.CascadeObjectDetector;
               bbox = step(faceDetector, im);
               
           
                for i = 1 : size(bbox, 1) 
                  im = imcrop(im, bbox(i, :)); 
                end
               
               imwrite(im,info.Filename);
            end
            
            %crop validate
            while hasdata(imdsv)
               [im,info] = read(imdsv); 
               
               faceDetector = vision.CascadeObjectDetector;
               bbox = step(faceDetector, im);
               
             
                for i = 1 : size(bbox, 1) 
                  im = imcrop(im, bbox(i, :)); 
                end
               
               imwrite(im,info.Filename);
            end
            
            imdst1 = augmentedImageDatastore([256 256],imdst);
            imdsv1 = augmentedImageDatastore([256 256],imdsv);
            imdsTrain = imdst1;
            imdsValidation = imdsv1;
            
            inputSize = [256 256 3];
            numClasses = 5;
            
            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(5,25)
                batchNormalizationLayer
                reluLayer
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            
            options = trainingOptions('sgdm', ...
                'MaxEpochs',15, ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress');
            
            net = trainNetwork(imdsTrain,layers,options);
            
            img1 = imresize(img, [256 256]);
            pred = classify(net, img1);
            app.Label_3.Text = pred;
            drawnow;


        end

        % Callback function
        function ButtonPushed(app, event)
            imshow()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.face_recognition = uifigure('Visible', 'off');
            app.face_recognition.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.face_recognition);
            title(app.UIAxes2, '待识别图像')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Button_2 = uibutton(app.face_recognition, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '载入图像';
            app.Button_3 = uibutton(app.face_recognition, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '识别';
            app.Label = uilabel(app.face_recognition);
            app.Label.Text = '人脸识别系统';
            app.Panel = uipanel(app.face_recognition);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '经识别,图中人员是：';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '请添加图像！';
            app.Image = uiimage(app.face_recognition);
            app.Image.ImageSource = 'samplepicture1.png';
            % Show the figure after all components are created
            app.face_recognition.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fc_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.face_recognition)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.face_recognition)
        end
    end
end