classdef GUI1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        menu1             matlab.ui.container.Menu
        simpleinspection  matlab.ui.container.Menu
        autoinspection    matlab.ui.container.Menu
        exit              matlab.ui.container.Menu
        Help              matlab.ui.container.Menu
        Image             matlab.ui.control.Image
        uipanel1          matlab.ui.container.Panel
        uipanel2          matlab.ui.container.Panel
        edit1             matlab.ui.control.EditField
        text3             matlab.ui.control.Label
        pushbutton1       matlab.ui.control.Button
        edit2             matlab.ui.control.EditField
        pushbutton2       matlab.ui.control.Button
        text4             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        axes1             matlab.ui.control.UIAxes
        uipanel3          matlab.ui.container.Panel
        studentsNum       matlab.ui.control.EditField
        text12            matlab.ui.control.Label
        passshow          matlab.ui.control.Label
        text10            matlab.ui.control.Label
        text9             matlab.ui.control.Label
        gradeshow         matlab.ui.control.Label
        pushbutton3       matlab.ui.control.Button
        showpath          matlab.ui.control.Label
        text6             matlab.ui.control.Label
        axes3             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function text5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to text5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            set(text('Rotation',90 ));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI1_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI1 (see VARARGIN)
            
            % Choose default command line output for GUI1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: Help
        function Help_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            msgbox(fileread('Readme.txt'), 'System Information','关于');
        end

        % Menu selected function: autoinspection
        function autoinspection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to autoinspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel2,'Visible','on');
            set(handles.uipanel3,'Visible','off');
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exit();
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            CustomNo = get(handles.edit1,'String');%需查询的学号
            CustomNo = sprintf('%s.png',CustomNo);
            %---------------------------读取result文件夹-----------------------------
            dir_struct_search = dir('results');
            [sort_names_search,sort_index_search] = sortrows({dir_struct_search.name});
            handles.file_name_search = sort_names_search;
            handles.is_dir_search = [dir_struct_search.isdir];%判断是否为目录
            for i = 1:length(handles.is_dir_search)
                if ~handles.is_dir_search(i)
                    handles.filenames_search{i} = handles.file_name_search{i};
                end
            end
            %去除handles.filenames（含有文件夹中所有文件名）中的空元素
            handles.filenames_search(cellfun(@isempty,handles.filenames_search))=[];
            %-----------------------------------------------------------------------
            for i2 = 1 : length(handles.filenames_search)
                if strcmp(CustomNo , handles.filenames_search{i2})
                   axes(handles.axes1);
                   imshow(sprintf('results\\%s',CustomNo));
                   break;
                end
                if i2 == length(handles.filenames_search)
                    msgbox('无此答题卡', '错误提示','error');
                end
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Custom_pass_scores = str2double(get(handles.edit2,'String'));
            %flod_name = uigetdir;
            dir_struct = dir('images');
            [sort_names,sort_index] = sortrows({dir_struct.name});
            handles.file_name = sort_names;
            handles.is_dir = [dir_struct.isdir];%判断是否为目录
            for i = 1:length(handles.is_dir)
                if ~handles.is_dir(i)
                    handles.filenames{i} = handles.file_name{i};
                end
            end
            %去除handles.filenames中的空元素
            handles.filenames(cellfun(@isempty,handles.filenames))=[];
            Paper_Inspection( handles.filenames,Custom_pass_scores );
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName,~] = uigetfile('*.*','请选择答题卡图像文件');
            set(handles.showpath,'String',strcat(PathName,FileName));
            [pass,sum_scores,I4,tips] = SimplePaper_Inspection( ...
                get(handles.showpath,'String'));
            axes(handles.axes3);
            imshow(I4);
            set(handles.studentsNum,'String',tips);
            set(handles.gradeshow,'String',pass);
            set(handles.passshow,'String',num2str(sum_scores));
        end

        % Menu selected function: simpleinspection
        function simpleinspection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to simpleinspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel2,'Visible','off');
            set(handles.uipanel3,'Visible','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI1';
            app.figure1.Tag = 'figure1';
            app.menu1 = uimenu(app.figure1);
            app.menu1.Text = '菜单';
            app.menu1.Tag = 'menu1';
            app.simpleinspection = uimenu(app.menu1);
            app.simpleinspection.MenuSelectedFcn = createCallbackFcn(app, @simpleinspection_Callback, true);
            app.simpleinspection.Text = '单张阅卷';
            app.simpleinspection.Tag = 'simpleinspection';
            app.autoinspection = uimenu(app.menu1);
            app.autoinspection.MenuSelectedFcn = createCallbackFcn(app, @autoinspection_Callback, true);
            app.autoinspection.Text = '批量一键阅卷';
            app.autoinspection.Tag = 'autoinspection';
            app.exit = uimenu(app.menu1);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Text = '退出';
            app.exit.Tag = 'exit';
            app.Help = uimenu(app.figure1);
            app.Help.MenuSelectedFcn = createCallbackFcn(app, @Help_Callback, true);
            app.Help.Separator = 'on';
            app.Help.Text = '关于';
            app.Help.Tag = 'Help';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '答题卡识别与成绩统计系统';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = '单张手动阅卷操作';
            app.uipanel3.Tag = 'uipanel3';
            app.axes3 = uiaxes(app.uipanel3);
            app.axes3.Tag = 'axes3';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = '答题卡显示';
            app.showpath = uilabel(app.uipanel3);
            app.showpath.Tag = 'showpath';
            app.showpath.Text = '';
            app.pushbutton3 = uibutton(app.uipanel3, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = '打开图像...';
            app.gradeshow = uilabel(app.uipanel3);
            app.gradeshow.Tag = 'gradeshow';
            app.gradeshow.Text = '';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = '成绩';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = '是否通过';
            app.passshow = uilabel(app.uipanel3);
            app.passshow.Tag = 'passshow';
            app.passshow.Text = '';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = '学号';
            app.studentsNum = uieditfield(app.uipanel3, 'text');
            app.studentsNum.Tag = 'studentsNum';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = '一键阅卷与查卷操作';
            app.uipanel2.Visible = 'off';
            app.uipanel2.Tag = 'uipanel2';
            app.axes1 = uiaxes(app.uipanel2);
            app.axes1.Tag = 'axes1';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = '请输入学号：';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = '请输入及格分数';
            app.pushbutton2 = uibutton(app.uipanel2, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = '一键阅卷';
            app.edit2 = uieditfield(app.uipanel2, 'text');
            app.edit2.Tag = 'edit2';
            app.pushbutton1 = uibutton(app.uipanel2, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = '查阅成绩';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = '答题卡信息显示';
            app.edit1 = uieditfield(app.uipanel2, 'text');
            app.edit1.Tag = 'edit1';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'samplepicture2.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end