classdef remake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        picopenbutton_2  matlab.ui.control.Button
        handle           matlab.ui.container.Panel
        Panel            matlab.ui.container.Panel
        passtext_2       matlab.ui.control.Label
        logo             matlab.ui.control.Image
        studentsNum      matlab.ui.control.EditField
        xuehaotext       matlab.ui.control.Label
        grade            matlab.ui.control.Label
        passtext         matlab.ui.control.Label
        gradetext        matlab.ui.control.Label
        gradeshow        matlab.ui.control.Label
        picopenbutton    matlab.ui.control.Button
        picpath          matlab.ui.control.Label
        title            matlab.ui.control.Label
        pic              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function text5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to text5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            set(text('Rotation',90 ));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI1_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI1 (see VARARGIN)
            
            % Choose default command line output for GUI1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Callback function
        function Help_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            msgbox(fileread('Readme.txt'), 'System Information','关于');
        end

        % Callback function
        function autoinspection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to autoinspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel2,'Visible','on');
            set(handles.uipanel3,'Visible','off');
        end

        % Callback function
        function exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exit();
        end

        % Callback function
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            CustomNo = get(handles.edit1,'String');%需查询的学号
            CustomNo = sprintf('%s.png',CustomNo);
            %---------------------------读取result文件夹-----------------------------
            dir_struct_search = dir('results');
            [sort_names_search,sort_index_search] = sortrows({dir_struct_search.name});
            handles.file_name_search = sort_names_search;
            handles.is_dir_search = [dir_struct_search.isdir];%判断是否为目录
            for i = 1:length(handles.is_dir_search)
                if ~handles.is_dir_search(i)
                    handles.filenames_search{i} = handles.file_name_search{i};
                end
            end
            %去除handles.filenames（含有文件夹中所有文件名）中的空元素
            handles.filenames_search(cellfun(@isempty,handles.filenames_search))=[];
            %-----------------------------------------------------------------------
            for i2 = 1 : length(handles.filenames_search)
                if strcmp(CustomNo , handles.filenames_search{i2})
                   axes(handles.axes1);
                   imshow(sprintf('results\\%s',CustomNo));
                   break;
                end
                if i2 == length(handles.filenames_search)
                    msgbox('无此答题卡', '错误提示','error');
                end
            end
        end

        % Callback function
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Custom_pass_scores = str2double(get(handles.edit2,'String'));
            %flod_name = uigetdir;
            dir_struct = dir('images');
            [sort_names,sort_index] = sortrows({dir_struct.name});
            handles.file_name = sort_names;
            handles.is_dir = [dir_struct.isdir];%判断是否为目录
            for i = 1:length(handles.is_dir)
                if ~handles.is_dir(i)
                    handles.filenames{i} = handles.file_name{i};
                end
            end
            %去除handles.filenames中的空元素
            handles.filenames(cellfun(@isempty,handles.filenames))=[];
            Paper_Inspection( handles.filenames,Custom_pass_scores );
        end

        % Button pushed function: picopenbutton
        function picopenbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [FileName,PathName,~] = uigetfile('*.*','请选择答题卡图像文件');
            set(handles.showpath,'String',strcat(PathName,FileName));
            [pass,sum_scores,I4,tips] = SimplePaper_Inspection( ...
                get(handles.showpath,'String'));
            axes(handles.axes3);
            imshow(I4);
            set(handles.studentsNum,'String',tips);
            set(handles.gradeshow,'String',pass);
            set(handles.passshow,'String',num2str(sum_scores));
        end

        % Callback function
        function simpleinspection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to simpleinspection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel2,'Visible','off');
            set(handles.uipanel3,'Visible','on');
        end

        % Callback function
        function escMenuSelected(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exit();
        end

        % Button pushed function: picopenbutton_2
        function picopenbutton_2ButtonPushed(app, event)
                        % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            msgbox(fileread('Readme.txt'), 'System Information','关于');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI1';
            app.figure1.Tag = 'figure1';
            app.handle = uipanel(app.figure1);
            app.handle.Title = '答题卡阅卷识别实验';
            app.handle.Tag = 'uipanel3';
            app.pic = uiaxes(app.handle);
            app.pic.XTick = [];
            app.pic.YTick = [];
            app.pic.Tag = 'axes3';
            app.title = uilabel(app.handle);
            app.title.Tag = 'text6';
            app.title.Text = '答题卡显示';
            app.picpath = uilabel(app.handle);
            app.picpath.Tag = 'showpath';
            app.picpath.Text = '';
            app.picopenbutton = uibutton(app.handle, 'push');
            app.picopenbutton.ButtonPushedFcn = createCallbackFcn(app, @picopenbutton_Callback, true);
            app.picopenbutton.Tag = 'pushbutton3';
            app.picopenbutton.Text = '打开图像并阅卷';
            app.gradeshow = uilabel(app.handle);
            app.gradeshow.Tag = 'gradeshow';
            app.gradeshow.Text = '';
            app.gradetext = uilabel(app.handle);
            app.gradetext.Tag = 'text9';
            app.gradetext.Text = '成绩';
            app.passtext = uilabel(app.handle);
            app.passtext.Tag = 'text10';
            app.passtext.Text = '是否通过';
            app.grade = uilabel(app.handle);
            app.grade.Tag = 'passshow';
            app.grade.Text = '';
            app.xuehaotext = uilabel(app.handle);
            app.xuehaotext.Tag = 'text12';
            app.xuehaotext.Text = '学号';
            app.studentsNum = uieditfield(app.handle, 'text');
            app.studentsNum.Tag = 'studentsNum';
            app.logo = uiimage(app.handle);
            app.logo.ImageSource = 'samplepicture2.png';
            app.Panel = uipanel(app.handle);
            app.Panel.Title = '简介';
            app.passtext_2 = uilabel(app.Panel);
            app.passtext_2.Tag = 'text10';
            app.passtext_2.Text = {'系统功能：一键自动阅卷选项'; '功能1：识别image文件夹中的答题卡'; '功能2：将“学号”、“试卷类型”、“科目”、“总成绩”、“通过情况写入到results文件夹下的grades.xls中，标准答案在StanderdAnswers.xls文件中。用户可指定原始答题卡图像进行识别和成绩的统计，答题卡的成绩和图像将会显示'; '在UI界面上。'};
            app.picopenbutton_2 = uibutton(app.figure1, 'push');
            app.picopenbutton_2.ButtonPushedFcn = createCallbackFcn(app, @picopenbutton_2ButtonPushed, true);
            app.picopenbutton_2.Tag = 'pushbutton3';
            app.picopenbutton_2.Text = '使用介绍';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = remake(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end