classdef Color_Detect_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel4     matlab.ui.container.Panel
        pushbutton1  matlab.ui.control.Button
        set          matlab.ui.control.Button
        startv       matlab.ui.control.Button
        uipanel3     matlab.ui.container.Panel
        bluev        matlab.ui.control.Button
        greenv       matlab.ui.control.Button
        redv         matlab.ui.control.Button
        text         matlab.ui.control.Label
        pop          matlab.ui.control.DropDown
        video        matlab.ui.control.Button
        exit         matlab.ui.control.Button
        reset        matlab.ui.control.Button
        oi           matlab.ui.control.Label
        dc           matlab.ui.control.Label
        text5        matlab.ui.control.Label
        start        matlab.ui.control.Button
        snap         matlab.ui.control.Button
        uipanel2     matlab.ui.container.Panel
        red          matlab.ui.control.Button
        green        matlab.ui.control.Button
        blue         matlab.ui.control.Button
        uipanel1     matlab.ui.container.Panel
        camera       matlab.ui.control.Button
        browse       matlab.ui.control.Button
        demo         matlab.ui.control.Button
        image        matlab.ui.control.Button
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Color_Detect_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Color_Detect (see VARARGIN)
            
            % Choose default command line output for Color_Detect
            handles.output = hObject;
            
            
            position = get(handles.pushbutton1,'Position');
            I = imread('background.png');   %读取图片
            I = imresize(I, [position(4)*2.85*5, position(3)*5]);
            set(handles.pushbutton1,'CData',I);  %将按钮的背景图片设置成I，美化按钮


            % Update handles structure
            guidata(hObject, handles);
            
            
            set(handles.axes1,'Visible','off');
            set(handles.axes2,'Visible','off');
            set(handles.uipanel1,'visible','off');
            set(handles.uipanel2,'visible','off');
            set(handles.start,'Enable','off');
            set(handles.snap,'Enable','off');
            set(handles.oi,'visible','off');
            set(handles.dc,'visible','off');
            set(handles.uipanel3,'visible','off');
            set(handles.startv,'enable','off');
            set(handles.text,'enable','off');
            set(handles.set,'enable','off');
            set(handles.pop,'enable','off');
            set(handles.text5,'visible','off');
        end

        % Button pushed function: blue
        function blue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to blue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.x=3;
            set(handles.start,'enable','on');
            guidata(hObject,handles);
        end

        % Button pushed function: bluev
        function bluev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bluev (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.cv=3;
            guidata(hObject,handles);
            set(handles.startv,'enable','on');
        end

        % Button pushed function: browse
        function browse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Browse the file from user
            [file path]=uigetfile({'*.jpg';'*.bmp';'*.jpeg';'*.png'}, 'Load Image File within Avilable Extensions');
            image=[path file];
            handles.file=image;
            if (file==0)
                warndlg('You did not selected any file ') ; % fille is not selected
            end
            [fpath, fname, fext]=fileparts(file);
             validex=({'.bmp','.jpg','.jpeg','.png'});
             found=0;
             for (x=1:length(validex))
             if (strcmpi(fext,validex{x}))
                 found=1;
            handles.im=imread(image);
            axes(handles.axes1); cla;
            h = waitbar(0,'Please wait...');
            steps = 100;
            for step = 1:steps
                % computations take place here
                waitbar(step / steps)
            end
            close(h)
              end
             end
             imshow(handles.im);
            if (found==0)
                 errordlg('Selected file does not match available extensions. Please select file from available extensions [ .jpg, .jpeg, .bmp, .png] ','Image Format Error');
            end
            guidata(hObject, handles);
            set(handles.uipanel2,'visible','on');
            set(handles.oi,'visible','on');
        end

        % Button pushed function: camera
        function camera_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to camera (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imaqreset
            info= imaqhwinfo;
            name = char(info.InstalledAdaptors(end));
            c_info = imaqhwinfo(name);
            id = c_info.DeviceInfo.DeviceID(end);
            format = char(c_info.DeviceInfo.SupportedFormats(end));
            handles.vid= videoinput(name, id, format);
            axes(handles.axes1);cla;
            h = waitbar(0,'Please wait...');
            steps = 200;
            for step = 1:steps
                % computations take place here
                waitbar(step / steps)
            end
            close(h)
            
            preview(handles.vid);
            set(handles.snap,'Enable','on');
            guidata(hObject,handles);
            set(handles.uipanel2,'visible','on');
            set(handles.oi,'visible','on');
        end

        % Button pushed function: demo
        function demo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to demo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1); cla;
            str = {'RGB Wheel','RGB Balls','RGB Cube','Color Boxes'};
            [Selection,ok] = listdlg('PromptString','Select an Image','SelectionMode','single','ListSize',[200 100],...
                'ListString',str);
            if (ok==0)
                errordlg('You didnt select any image ','Selection Error');
            end
            switch Selection
                case 1
            handles.im=imread('rgbwheel.jpg');
            imshow(handles.im);
                case 2
                    handles.im=imread('rgbballs.jpg');
            imshow(handles.im);
            case 3
                    handles.im=imread('rgbcube.jpg');
            imshow(handles.im);
            case 4
                    handles.im=imread('mcolor.jpg');
            imshow(handles.im);
            end
            set(handles.uipanel2,'visible','on');
            set(handles.oi,'visible','on');
            guidata(hObject, handles);
        end

        % Button pushed function: exit
        function exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close
        end

        % Button pushed function: green
        function green_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to green (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.x=2;
            guidata(hObject,handles);
            set(handles.start,'enable','on');
        end

        % Button pushed function: greenv
        function greenv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to greenv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.cv=2;
            guidata(hObject,handles);
            set(handles.startv,'enable','on');
        end

        % Button pushed function: image
        function image_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uipanel1,'visible','on');
            set(handles.video,'Enable','off');
        end

        % Value changed function: pop
        function pop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            val=get(hObject,'Value');
            switch val
                case 1
                    warndlg('Please select a frame value');
                    set(handles.set,'enable','off');
                case 2
                    handles.frame=25;
                    set(handles.set,'enable','on');
                      case 3
                    handles.frame=50;
                    set(handles.set,'enable','on');
                      case 4
                    handles.frame=100;
                    set(handles.set,'enable','on');
                      case 5
                    handles.frame=150;
                    set(handles.set,'enable','on');
                      case 6
                    handles.frame=200;
                    set(handles.set,'enable','on');
                      case 7
                    handles.frame=300;
                    set(handles.set,'enable','on');
                      case 8
                    handles.frame=400;
                    set(handles.set,'enable','on');
            end
            guidata(hObject,handles);
        end

        % Button pushed function: red
        function red_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to red (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.x=1;
            guidata(hObject,handles);
            set(handles.start,'enable','on');
        end

        % Button pushed function: redv
        function redv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to redv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.cv=1;
            guidata(hObject,handles);
            set(handles.startv,'enable','on');
        end

        % Button pushed function: reset
        function reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1); cla;
            axes(handles.axes2); cla;
            set(handles.axes1,'Visible','off');
            set(handles.axes2,'Visible','off');
            set(handles.uipanel1,'visible','off');
            set(handles.uipanel2,'visible','off');
            set(handles.start,'Enable','off');
            set(handles.snap,'Enable','off');
            set(handles.oi,'visible','off');
            set(handles.dc,'visible','off');
            set(handles.video,'Enable','on');
            set(handles.image,'Enable','on');
            set(handles.uipanel3,'visible','off');
            set(handles.startv,'enable','off');
            set(handles.text,'enable','off');
            set(handles.set,'enable','off');
            set(handles.pop,'enable','off');
            set(handles.text5,'visible','off');
        end

        % Button pushed function: set
        function set_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to set (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.val=handles.frame;
            guidata(hObject,handles);
            set(handles.uipanel3,'visible','on');
        end

        % Button pushed function: snap
        function snap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to snap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.im=getsnapshot(handles.vid);
            axes(handles.axes1); cla; imshow(handles.im);
            closepreview(handles.vid);
            guidata(hObject,handles);
        end

        % Button pushed function: start
        function start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            im=handles.im; x=handles.x;
            switch x
                case 1
                    axes(handles.axes2); cla;
                    im=handles.im;
            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
            diff=imsubtract(r,rgb2gray(im));
             bw=im2bw(diff,0.18);
             area=bwareaopen(bw,300);
             rm=immultiply(area,r);  gm=g.*0;  bm=b.*0;
             image=cat(3,rm,gm,bm);
             imshow(image);
             % Transparcy part
              %[m n p]=size(im);
                % mask=ones(m,n);
                 %i=find(image(:,:,1)==0);
                % mask(i)=.9; % Change Transparacy
                 %imshow(im);
                 %hold on
                 %h=imshow(image);
                 %set(h,'AlphaData',mask);
             case 2
                    axes(handles.axes2); cla;
                    im=handles.im;
            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
            diff=imsubtract(g,rgb2gray(im));
             bw=im2bw(diff,0.18);
             area=bwareaopen(bw,300);
             gm=immultiply(area,g);  rm=r.*0;  bm=b.*0;
             image=cat(3,rm,gm,bm);
             imshow(image);
             case 3
                    axes(handles.axes2); cla;
                    im=handles.im;
            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
            diff=imsubtract(b,rgb2gray(im));
             bw=im2bw(diff,0.18);
             area=bwareaopen(bw,300);
             bm=immultiply(area,b);  gm=g.*0;  rm=r.*0;
             image=cat(3,rm,gm,bm);
             imshow(image);
             end
             set(handles.dc,'visible','on');
        end

        % Button pushed function: startv
        function startv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes1); cla;
            axes(handles.axes2); cla;
            set(handles.axes1,'Visible','off');
            set(handles.axes2,'Visible','off');
            set(handles.uipanel1,'visible','off');
            set(handles.uipanel2,'visible','off');
            set(handles.start,'Enable','off');
            set(handles.snap,'Enable','off');
            set(handles.oi,'visible','off');
            set(handles.dc,'visible','off');
            set(handles.video,'Enable','off');
            set(handles.image,'Enable','off');
            set(handles.uipanel3,'visible','off');
            set(handles.startv,'enable','off');
            set(handles.text,'enable','off');
            set(handles.set,'enable','off');
            set(handles.pop,'enable','off');
            set(handles.text5,'visible','off');
            set(handles.dc,'visible','on');
            set(handles.text5,'visible','on');
            h = waitbar(0,'Please wait...');
            steps = 200;
            for step = 1:steps
                % computations take place here
                waitbar(step / steps)
            end
            close(h)
            switch handles.cv
                case 1
                    imaqreset
                    info= imaqhwinfo;
                    name = char(info.InstalledAdaptors(end));
                    c_info = imaqhwinfo(name);
                    id = c_info.DeviceInfo.DeviceID(end);
                    format = char(c_info.DeviceInfo.SupportedFormats(end));
                    vid= videoinput(name, id, format);
                     set(vid, 'FramesPerTrigger', Inf);
                    set(vid, 'ReturnedColorspace', 'rgb')
                    vid.FrameGrabInterval = 5;
                    start(vid)
                    % Set loop from the value of slider
                            while(vid.FramesAcquired<=handles.val)
                            % current snapshot
                            im = getsnapshot(vid);
                            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
                            diff=imsubtract(r,rgb2gray(im));
                            bw=im2bw(diff,0.18);
                            area=bwareaopen(bw,300);
                            rm=immultiply(area,r);  gm=g.*0;  bm=b.*0;
                            image=cat(3,rm,gm,bm);
                            axes(handles.axes2);
                            imshow(image);
                            axes(handles.axes1);
                            imshow(im);
                            end
                            stop(vid);
                            flushdata(vid);
                            clear all
                case 2
                     imaqreset
                    info= imaqhwinfo;
                    name = char(info.InstalledAdaptors(end));
                    c_info = imaqhwinfo(name);
                    id = c_info.DeviceInfo.DeviceID(end);
                    format = char(c_info.DeviceInfo.SupportedFormats(end));
                    vid= videoinput(name, id, format);
                    set(vid, 'FramesPerTrigger', Inf);
                    set(vid, 'ReturnedColorspace', 'rgb')
                    vid.FrameGrabInterval = 5;
                    start(vid)
                    % Set loop from the value of slider
                            while(vid.FramesAcquired<=handles.val)
                            % current snapshot
                            im = getsnapshot(vid);
                            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
                            diff=imsubtract(g,rgb2gray(im));
                            bw=im2bw(diff,0.18);
                            area=bwareaopen(bw,300);
                            gm=immultiply(area,g);  rm=r.*0;  bm=b.*0;
                            image=cat(3,rm,gm,bm);
                            axes(handles.axes2);
                            imshow(image);
                            axes(handles.axes1);
                            imshow(im);
                            end
                            stop(vid);
                            flushdata(vid);
                            clear all
            
                case 3
                    imaqreset
                    info= imaqhwinfo;
                    name = char(info.InstalledAdaptors(end));
                    c_info = imaqhwinfo(name);
                    id = c_info.DeviceInfo.DeviceID(end);
                    format = char(c_info.DeviceInfo.SupportedFormats(end));
                    vid= videoinput(name, id, format);
                    set(vid, 'FramesPerTrigger', Inf);
                    set(vid, 'ReturnedColorspace', 'rgb')
                    vid.FrameGrabInterval = 5;
                    start(vid)
                    % Set loop from the value of slider
                            while(vid.FramesAcquired<=handles.val)
                            % current snapshot
                            im = getsnapshot(vid);
                            r=im(:,:,1); g=im(:,:,2); b=im(:,:,3);
                            diff=imsubtract(b,rgb2gray(im));
                            bw=im2bw(diff,0.18);
                            area=bwareaopen(bw,300);
                            bm=immultiply(area,b);  gm=g.*0;  rm=r.*0;
                            image=cat(3,rm,gm,bm);
                            axes(handles.axes2);
                            imshow(image);
                            axes(handles.axes1);
                            imshow(im);
                            end
                            stop(vid);
                            flushdata(vid);
                            clear all
            end
        end

        % Button pushed function: video
        function video_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to video (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.image,'Enable','off');
            set(handles.text,'enable','on');
            set(handles.pop,'enable','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Color_Detect';
            app.figure1.Tag = 'figure1';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = '色彩检测';
            app.uipanel4.Tag = 'uipanel4';
            app.axes1 = uiaxes(app.uipanel4);
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel4);
            app.axes2.XTick = [];
            app.axes2.YTick = [];
            app.axes2.Tag = 'axes2';
            app.image = uibutton(app.uipanel4, 'push');
            app.image.ButtonPushedFcn = createCallbackFcn(app, @image_Callback, true);
            app.image.Tag = 'image';
            app.image.Text = '载入图像';
            app.uipanel1 = uipanel(app.uipanel4);
            app.uipanel1.Title = '图像源';
            app.uipanel1.Tag = 'uipanel1';
            app.demo = uibutton(app.uipanel1, 'push');
            app.demo.ButtonPushedFcn = createCallbackFcn(app, @demo_Callback, true);
            app.demo.Tag = 'demo';
            app.demo.Text = '示例图像';
            app.browse = uibutton(app.uipanel1, 'push');
            app.browse.ButtonPushedFcn = createCallbackFcn(app, @browse_Callback, true);
            app.browse.Tag = 'browse';
            app.browse.Text = '打开...';
            app.camera = uibutton(app.uipanel1, 'push');
            app.camera.ButtonPushedFcn = createCallbackFcn(app, @camera_Callback, true);
            app.camera.Tag = 'camera';
            app.camera.Text = '相机采集';
            app.uipanel2 = uipanel(app.uipanel4);
            app.uipanel2.Title = '选择颜色';
            app.uipanel2.Tag = 'uipanel2';
            app.blue = uibutton(app.uipanel2, 'push');
            app.blue.ButtonPushedFcn = createCallbackFcn(app, @blue_Callback, true);
            app.blue.Tag = 'blue';
            app.blue.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.blue.Text = {'Blue'; ''};
            app.green = uibutton(app.uipanel2, 'push');
            app.green.ButtonPushedFcn = createCallbackFcn(app, @green_Callback, true);
            app.green.Tag = 'green';
            app.green.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.green.Text = {'Green'; ''};
            app.red = uibutton(app.uipanel2, 'push');
            app.red.ButtonPushedFcn = createCallbackFcn(app, @red_Callback, true);
            app.red.Tag = 'red';
            app.red.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.red.Text = {'RED'; ''};
            app.snap = uibutton(app.uipanel4, 'push');
            app.snap.ButtonPushedFcn = createCallbackFcn(app, @snap_Callback, true);
            app.snap.Tag = 'snap';
            app.snap.Text = '点击拍照';
            app.start = uibutton(app.uipanel4, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_Callback, true);
            app.start.Tag = 'start';
            app.start.Text = '开始检测';
            app.text5 = uilabel(app.uipanel4);
            app.text5.Tag = 'text5';
            app.text5.Text = '实况';
            app.dc = uilabel(app.uipanel4);
            app.dc.Tag = 'dc';
            app.dc.Text = '检测结果';
            app.oi = uilabel(app.uipanel4);
            app.oi.Tag = 'oi';
            app.oi.Text = '原图';
            app.reset = uibutton(app.uipanel4, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_Callback, true);
            app.reset.Tag = 'reset';
            app.reset.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.reset.Text = {'重置'; ''};
            app.exit = uibutton(app.uipanel4, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Tag = 'exit';
            app.exit.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.exit.Text = {'退出'; ''};
            app.video = uibutton(app.uipanel4, 'push');
            app.video.ButtonPushedFcn = createCallbackFcn(app, @video_Callback, true);
            app.video.Tag = 'video';
            app.video.Text = '实时检测';
            app.pop = uidropdown(app.uipanel4);
            app.pop.Items = {'选择...', '25', '50', '100', '150', '200', '300', '400'};
            app.pop.ValueChangedFcn = createCallbackFcn(app, @pop_Callback, true);
            app.pop.Tag = 'pop';
            app.pop.Value = '选择...';
            app.text = uilabel(app.uipanel4);
            app.text.Tag = 'text';
            app.text.Tooltip = 'It will set duration that how long video should continue to detect objects.';
            app.text.Text = '帧数';
            app.uipanel3 = uipanel(app.uipanel4);
            app.uipanel3.Title = '选择颜色';
            app.uipanel3.Tag = 'uipanel3';
            app.redv = uibutton(app.uipanel3, 'push');
            app.redv.ButtonPushedFcn = createCallbackFcn(app, @redv_Callback, true);
            app.redv.Tag = 'redv';
            app.redv.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.redv.Text = {'RED'; ''};
            app.greenv = uibutton(app.uipanel3, 'push');
            app.greenv.ButtonPushedFcn = createCallbackFcn(app, @greenv_Callback, true);
            app.greenv.Tag = 'greenv';
            app.greenv.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.greenv.Text = {'Green'; ''};
            app.bluev = uibutton(app.uipanel3, 'push');
            app.bluev.ButtonPushedFcn = createCallbackFcn(app, @bluev_Callback, true);
            app.bluev.Tag = 'bluev';
            app.bluev.Tooltip = 'Click and hold mouse pointer and then double click on rectangle';
            app.bluev.Text = {'Blue'; ''};
            app.startv = uibutton(app.uipanel4, 'push');
            app.startv.ButtonPushedFcn = createCallbackFcn(app, @startv_Callback, true);
            app.startv.Tag = 'startv';
            app.startv.Text = '开始检测';
            app.set = uibutton(app.uipanel4, 'push');
            app.set.ButtonPushedFcn = createCallbackFcn(app, @set_Callback, true);
            app.set.Tag = 'set';
            app.set.Tooltip = 'It will set duration that how long video should continue to detect objects.';
            app.set.Text = '设置';
            app.pushbutton1 = uibutton(app.uipanel4, 'push');
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Color_Detect_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Color_Detect_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end