classdef Image_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        image_acquisition  matlab.ui.Figure
        Panel_2            matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        Button_7           matlab.ui.control.Button
        Button_6           matlab.ui.control.Button
        Label_2            matlab.ui.control.Label
        StatusTextArea     matlab.ui.control.TextArea
        Button_4           matlab.ui.control.Button
        USBButton          matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    
    properties (Access = private)
        cam % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: USBButton
        function USBButtonPushed(app, event)
            app.StatusTextArea.Value="Starting webcam";
            app.cam = webcam();
            global frame
            frame=snapshot(app.cam);
            global img
            img =image(app.UIAxes,zeros(size(frame),'uint8'));
            axis(app.UIAxes,'image');
            preview(app.cam,img);
            

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.StatusTextArea.Value="Starting  Industrial cam";
            imaqtool();
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.StatusTextArea.Value="图像已保存";
            img = snapshot(app.cam)
            imwrite(img, '抓拍.jpg');
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            delete(app.image_acquisition)      
            run main.mlapp
        end

        % Callback function
        function Button_8Pushed(app, event)
              writerObj = VideoWriter( [filename '.avi'] );
              writerObj.FrameRate = N;
              open(writerObj);
              
              figure;
              for ii = 1: nframe
                  frame = getsnapshot(vid);
                  imshow(frame);
                  f.cdata = frame;
                  f.colormap = [];
                 writeVideo(writerObj,f);
             end
             
             close(writerObj);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.image_acquisition = uifigure('Visible', 'off');
            app.image_acquisition.Name = 'UI Figure';
            app.Panel = uipanel(app.image_acquisition);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'samplepicture4.png';
            app.USBButton = uibutton(app.Panel, 'push');
            app.USBButton.ButtonPushedFcn = createCallbackFcn(app, @USBButtonPushed, true);
            app.USBButton.Text = 'USB相机采集';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '拍照';
            app.StatusTextArea = uitextarea(app.Panel);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ' 状态信息';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '返回';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '工业相机采集';
            app.Panel_2 = uipanel(app.image_acquisition);
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '图像预览';
            % Show the figure after all components are created
            app.image_acquisition.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_acquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.image_acquisition)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.image_acquisition)
        end
    end
end