classdef Advanced_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Label_8   matlab.ui.control.Label
        Label_7   matlab.ui.control.Label
        Label_6   matlab.ui.control.Label
        Label_5   matlab.ui.control.Label
        Label_4   matlab.ui.control.Label
        Label_3   matlab.ui.control.Label
        Label_2   matlab.ui.control.Label
        Label     matlab.ui.control.Label
        Image_8   matlab.ui.control.Image
        Image_7   matlab.ui.control.Image
        Image_6   matlab.ui.control.Image
        Image_5   matlab.ui.control.Image
        Image_4   matlab.ui.control.Image
        Image_3   matlab.ui.control.Image
        Image_2   matlab.ui.control.Image
        Image     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            %高级应用回调
            run 'Good Project'\calcArea\calcArea.m
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            %高级应用回调
            run 'Good Project'\FaceFinder\facefinder.m
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            %高级应用回调
            run 'Good Project'\PCB\PCB_Detection\PCB_Detection.mlapp
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            %高级应用回调
            run 'Good Project'\OCR\ESCANEAR.m
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            %高级应用回调
            run 'Good Project'\trepang\trepang.mlapp
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            %高级应用回调
            run 'Good Project'\car_card\app\License_plate_recognition.mlapp
        end

        % Image clicked function: Image_7
        function Image_7Clicked(app, event)
            %高级应用回调
            run 'Good Project'\'Answer sheet'\remake.mlapp
        end

        % Image clicked function: Image_8
        function Image_8Clicked(app, event)
            %高级应用回调
            run 'Good Project'\Defect_detection\code\DefectDetection.m
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '高级应用实例';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'zc.jpg';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'RL.jpg';
            app.Image_3 = uiimage(app.Panel);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'PCB.jpg';
            app.Image_4 = uiimage(app.Panel);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = 'ocr.jpg';
            app.Image_5 = uiimage(app.Panel);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = 'HAI.jpg';
            app.Image_6 = uiimage(app.Panel);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = 'CP.jpg';
            app.Image_7 = uiimage(app.Panel);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @Image_7Clicked, true);
            app.Image_7.ImageSource = 'DTK.jpg';
            app.Image_8 = uiimage(app.Panel);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @Image_8Clicked, true);
            app.Image_8.ImageSource = 'js.png';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '视觉测量';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '人脸识别';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '电路板缺陷检测';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '英文字符识别';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '海参分级';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '车牌识别';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '答题卡识别';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '表面缺陷检测';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Advanced_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end