classdef Basic_Image_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button_24        matlab.ui.control.Button
        Panel_3          matlab.ui.container.Panel
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        Label_2          matlab.ui.control.Label
        Panel_7          matlab.ui.container.Panel
        Label_xt         matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        Label_yz         matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        DropDown_2       matlab.ui.control.DropDown
        DropDown         matlab.ui.control.DropDown
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        Panel_2          matlab.ui.container.Panel
        Panel_4          matlab.ui.container.Panel
        Label            matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
        Fun_Panel        matlab.ui.container.Panel
        Panel_6          matlab.ui.container.Panel
        Image3           matlab.ui.control.Image
        Image            matlab.ui.control.Image
        Button           matlab.ui.control.Button
        ButtonGroup_2_1  matlab.ui.container.ButtonGroup
        Label_4          matlab.ui.control.Label
        Label_7          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Button_23        matlab.ui.control.RadioButton
        Button_22        matlab.ui.control.RadioButton
        Button_21        matlab.ui.control.RadioButton
        Button_20        matlab.ui.control.RadioButton
        Button_19        matlab.ui.control.RadioButton
        Button_18        matlab.ui.control.RadioButton
        Button_17        matlab.ui.control.RadioButton
        Button_16        matlab.ui.control.RadioButton
        Button_15        matlab.ui.control.RadioButton
        HoughButton      matlab.ui.control.RadioButton
        Button_13        matlab.ui.control.RadioButton
        RadonButton      matlab.ui.control.RadioButton
        Button_2_1_6     matlab.ui.control.RadioButton
        Button_2_1_5     matlab.ui.control.RadioButton
        Button_2_1_4     matlab.ui.control.RadioButton
        Button_2_1_3     matlab.ui.control.RadioButton
        Button_2_1_2     matlab.ui.control.RadioButton
        Button_2_1_1     matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Iin % Description
        im % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % 启动
                %图像处理分控件
                %uiaxes2---预览图像
                %uiaxes---预览图像灰度直方图
                %uiaxes2_2-----图像输出1
                %uiaxes_2-----图像输出2
                app.DropDown.Visible = 'on'
                app.DropDown_2.Visible = 'off'
                app.Label_yz.Visible = 'off'
                app.Slider.Visible = 'off'
                app.Label_xt.Visible = 'off'
                app.DropDown_3.Visible = 'off'
                colorbar(app.UIAxes2_2,'off')
                iptsetpref('ImshowAxesVisible','off')
                axis(app.UIAxes2_2,'off')
                hold(app.UIAxes2_2,'off')
                hold(app.UIAxes2_2,'off')
                app.im = imread('pout.png');
                %预览图像和预览灰度直方图显示
                imshow(app.im,'Parent',app.UIAxes2);
                [counts,x] = imhist(app.im);
                plot(app.UIAxes,x,counts);
                %初始
                im = im2double(app.im);
                Fa = 2;Fb = -55;
                o1 = Fa .*im + Fb/255;
                [M,N] = size(o1);
                [counts,x] = imhist(o1);
                %输出灰度图像显示
                imshow(o1,'Parent',app.UIAxes2_2,'InitialMagnification','fit');
                plot(app.UIAxes_2,255*x,counts);
        end

        % Callback function
        function ButtonGroup1SelectionChanged(app, event)
              
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
                value = app.DropDown.Value;
                switch value
                    case '增加对比度'
                        im = im2double(app.im);
                        Fa = 2;Fb = -55;
                        o1 = Fa .*im + Fb/255;
                        [M,N] = size(o1)
                        [counts,x] = imhist(o1,64)
                        imshow(o1,'Parent',app.UIAxes2_2)
                        plot(app.UIAxes_2,255*x,counts)
                        %hold(app.UIAxes_2,'on')
                    case '减小对比度'
                        im = im2double(app.im);
                        Fa = 0.5;Fb = -55;
                        o1 = Fa .*im + Fb/255;
                        [M,N] = size(o1)
                        [counts,x] = imhist(o1,64)
                        imshow(o1,'Parent',app.UIAxes2_2)
                        plot(app.UIAxes_2,255*x,counts)
                        %hold(app.UIAxes_2,'on')
                    case '线性增加亮度'
                        im = im2double(app.im)
                        Fa = 1;Fb = 55;
                        o1 = Fa .*im + Fb/255;
                        [M,N] = size(o1)
                        [counts,x] = imhist(o1,64)
                        imshow(o1,'Parent',app.UIAxes2_2)
                        plot(app.UIAxes_2,255*x,counts)
                        %hold(app.UIAxes_2,'on')
                    case '反相显示'
                        im = im2double(app.im)
                        Fa = -1;Fb = 255;
                        o1 = Fa .*im + Fb/255;
                        [M,N] = size(o1)
                        [counts,x] = imhist(o1,64)
                        imshow(o1,'Parent',app.UIAxes2_2)
                        plot(app.UIAxes_2,255*x,counts)
                        %hold(app.UIAxes_2,'on')
                end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
                value = app.DropDown_2.Value;
                switch value
                    case 'Gamma 0.75'
                        im = im2double(app.im);
                        j = imadjust(im,[],[],0.75);
                        [counts,x] = imhist(j,64);
                        imshow(j,'parent',app.UIAxes2_2);
                        plot(app.UIAxes_2,255*x,counts);
                    case 'Gamma 1'
                        im = im2double(app.im);
                        j = imadjust(im,[],[],1);
                        [counts,x] = imhist(j,64);
                        imshow(j,'parent',app.UIAxes2_2);
                        plot(app.UIAxes_2,255*x,counts);
                    case 'Gamma 1.5'
                        im = im2double(app.im);
                        j = imadjust(im,[],[],1.5);
                        [counts,x] = imhist(j,64);
                        imshow(j,'parent',app.UIAxes2_2);
                        plot(app.UIAxes_2,255*x,counts);
                end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
                im = app.im;
                changingValue = event.Value;
                app.DropDown_2.Visible = 'off'
                app.DropDown_2.Visible = 'off'
                app.Slider.Visible = 'on'
                bw2 = im2bw(im,changingValue/255)
                [counts,x] = imhist(bw2,2);
                imshow(bw2,'parent',app.UIAxes2_2);
                scatter(app.UIAxes_2,x,counts,'filled');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %浏览图像bmp     
             [filename,pathname]=uigetfile({'*.png';'*.bmp';'*.jpg';'*.tif';'*.jpg'},'选择图像');
            if isequal(filename,0)||isequal(pathname,0)
               errordlg('请选择图片','提示');%如果没有输入，则创建错误对话框
               return;
            else
                im=[pathname,filename];%合成路径+文件名
                image=imread(im);%读取图像
                app.im = image;
                app.ButtonGroup_2_1.SelectedObject = app.Button_2_1_1;
                app.DropDown.Visible = 'on';
                app.DropDown.Value = '增加对比度'
                app.DropDown_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.Label_yz.Visible = 'off';
                app.Label_xt.Visible = 'off'
                app.DropDown_3.Visible = 'off'
                imshow(app.im,'parent',app.UIAxes2);%在预览显示原图像
                [counts,x] = imhist(app.im);
                plot(app.UIAxes,x,counts);
                im = im2double(app.im);
                Fa = 2;Fb = -55;
                o1 = Fa .*im + Fb/255;
                [M,N] = size(o1);
                [counts,x] = imhist(o1);
                imshow(o1,'Parent',app.UIAxes2_2);
                app.UIAxes2_2.Title.String = '';
                app.UIAxes2_2.XLabel.String = '';
                app.UIAxes2_2.YLabel.String = '';
                app.UIAxes_2.Title.String = '输出灰度直方图';
                app.UIAxes_2.XLabel.String = '灰度';
                app.UIAxes_2.YLabel.String = '像素数目';
                axis(app.UIAxes_2,'normal')
                axis(app.UIAxes_2,'on')
                app.UIAxes_2.TickDirMode = 'auto'
                app.UIAxes_2.YDir = 'normal'
                app.UIAxes_2.XGrid = 'on'
                app.UIAxes_2.YGrid = 'on'
                plot(app.UIAxes_2,255*x,counts);
            end
        end

        % Selection changed function: ButtonGroup_2_1
        function ButtonGroup_2_1SelectionChanged2(app, event)
                selectedButton = app.ButtonGroup_2_1.SelectedObject;
                %功能选择
                app.DropDown.Visible = 'off'
                app.DropDown_2.Visible = 'off'
                app.DropDown_3.Visible = 'off'
                app.Label_yz.Visible = 'off'
                app.Slider.Visible = 'off'
                app.Label_xt.Visible = 'off'
                colorbar(app.UIAxes2_2,'off')
%                 iptsetpref('ImshowAxesVisible','off')
                axis(app.UIAxes2_2,'off')
%                 axis(app.UIAxes_2,'off')
                hold(app.UIAxes2_2,'off')
                hold(app.UIAxes_2,'off')
                app.UIAxes2_2.Title.String = ' '
                app.UIAxes2_2.XLabel.String = '';
                app.UIAxes2_2.YLabel.String = '';
                app.UIAxes_2.Title.String = ' '
                app.UIAxes_2.XLabel.String = '';
                app.UIAxes_2.YLabel.String = '';
                imshow(app.im,'Parent',app.UIAxes2);
                [counts,x] = imhist(app.im);
                plot(app.UIAxes,x,counts);
                switch selectedButton.Text
                    case '灰度线性变换'
                        app.DropDown.Visible = 'on'
                        im = im2double(app.im);
                        app.DropDown.Value = '增加对比度'
                        %预览灰度直方图显示
                        [counts,x] = imhist(app.im);
                        plot(app.UIAxes,x,counts);
                        %灰度线性变换
                        Fa = 2;Fb = -55;
                        o1 = Fa .*im + Fb/255;
                        [counts,x] = imhist(o1,64);
                        %图像输出
                        imshow(o1,'Parent',app.UIAxes2_2,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = '';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        app.UIAxes_2.Title.String = '输出灰度直方图';
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'
                        plot(app.UIAxes_2,255*x,counts);
                    case '灰度对数变换'
                      
                        im = im2double(app.im);
                        [counts,x] = imhist(app.im);
                        plot(app.UIAxes,x,counts);
                        
                        o1 = fft2(im);
                        o1 = fftshift(o1);
                        o1 = abs(o1);
                        o1 = log(o1+1);
                        [counts,x] = imhist(o1,64);
                        imshow(o1,'Parent',app.UIAxes2_2,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = '';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        plot(app.UIAxes_2,255*x,counts);
                        app.UIAxes_2.Title.String = '输出灰度直方图';
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'

                    case '伽玛变换'
                        app.DropDown_2.Visible = 'on'
                        app.DropDown_2.Value = 'Gamma 0.75'
                        im = im2double(app.im);
                        
                        j = imadjust(im,[],[],0.75);
                        [counts,x] = imhist(j,64);
                        imshow(j,'parent',app.UIAxes2_2,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = '';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        plot(app.UIAxes_2,255*x,counts);
                        app.UIAxes_2.Title.String = '输出灰度直方图';
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'

                    case '灰度阈值变换'
                        app.Slider.Visible = 'on'
                        app.Label_yz.Visible = 'on'
                        im = app.im;
                        
                        [counts,x] = imhist(app.im);
                        plot(app.UIAxes,x,counts);
                        
                        thresh = graythresh(im);
                        app.Slider.Value = 255*thresh;
                        bw1 = im2bw(im,thresh);
                        [counts,x] = imhist(bw1,2);
                        imshow(bw1,'parent',app.UIAxes2_2,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = '';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        scatter(app.UIAxes_2,x*255,counts,'filled');
                        app.UIAxes_2.Title.String = '输出灰度直方图 '
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'
                    case '分段线性变换'
                        try
                            im = app.im;
                            [counts,x] = imhist(app.im);
                            plot(app.UIAxes,x,counts)
                            
                            default={'100;150','50;200'};
                            ans_fx = inputdlg({'要变换的灰度区间（0~255，使用分号；分割）','要变换到的灰度空间（0~255，使用分号；分割）'},'分段取值',1,default);
                            ans = isempty(ans_fx)
                            if ans == 1
                                a1 = 100/255
                                a2 = 150/255
                                b1 = 50/255
                                b2 = 200/255
                            else
                                a = str2num(ans_fx{1})
                                b = str2num(ans_fx{2})
                                a1 = a(1)/255
                                a2 = a(2)/255
                                b1 = b(1)/255
                                b2 = b(2)/255
                            end
                            imx = imadjust(im, [a1;a2], [b1;b2]);
                            [counts,x] = imhist(imx,64);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            imshow(imx,'parent',app.UIAxes2_2,'InitialMagnification','fit');
                            app.UIAxes2_2.Title.String = '';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            plot(app.UIAxes_2,255*x,counts);
                        catch
                            uiwait(msgbox('输入错误 以默认值输出', 'Error','error'));
                            a1 = 100/255;
                            a2 = 150/255;
                            b1 = 50/255;
                            b2 = 200/255;
                            imx = imadjust(im, [a1;a2], [b1;b2]);
                            [counts,x] = imhist(imx,64);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            imshow(imx,'parent',app.UIAxes2_2,'InitialMagnification','fit');
                            plot(app.UIAxes_2,255*x,counts);
                        end
            
                    case '直方图均衡化'
                        im = app.im;
                        [counts,x] = imhist(app.im);
                        
                        plot(app.UIAxes,x,counts);
                        im = im2double(im);
                        i1 = 2*im - 55/255;
                        i1 = histeq(i1);
                        [M,N] = size(i1);
                        [counts,x] = imhist(i1,64);
                        
                        imshow(i1,'Parent',app.UIAxes2_2,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = '';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        plot(app.UIAxes_2,255*x,counts);
                        app.UIAxes_2.Title.String = '输出灰度直方图';
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'
                        
                    case 'Radon变换'

                        im = app.im;
                        BW=im2bw(im);
                        sobelImage=edge(BW,'sobel');
                        theta=0:0.5:179.5;
                        [R,x]=radon(sobelImage,theta);
                        imshow(R,[],'parent',app.UIAxes2_2,'XData',theta,'YData',x,'InitialMagnification','fit'); 
                        app.UIAxes2_2.Title.String = 'Radon transform '
                        app.UIAxes2_2.XLabel.String = '\theta';
                        app.UIAxes2_2.YLabel.String = 'x''';
                        axis(app.UIAxes2_2,'on')
                        axis(app.UIAxes2_2,'normal')
                        colormap(app.UIAxes2_2,"hot");
                        colorbar(app.UIAxes2_2)
                        imshow(sobelImage,'parent',app.UIAxes_2,'InitialMagnification','fit');
                        app.UIAxes_2.Title.String = '边缘检测图 '
                        app.UIAxes_2.XLabel.String = '';
                        app.UIAxes_2.YLabel.String = '';

                    case '傅里叶变换'
                        im = app.im;
                        bw=im2bw(im);
                        F=fft2(bw);
                        spectrum = fftshift(F)
                        F1=log(abs(spectrum)+1);
                        imshow(F1,[],'parent',app.UIAxes2_2)
                        app.UIAxes2_2.Title.String = '经过平移幅度谱';
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        F2=log(abs(F)+1);
                        imshow(F2,[],'parent',app.UIAxes_2);
                        app.UIAxes_2.Title.String = '未经平移幅度谱 '
                        app.UIAxes_2.XLabel.String = '';
                        app.UIAxes_2.YLabel.String = '';
                    case 'Hough变换'
                        im = app.im;
                        im1 = im2bw(im);
                        BW=edge(im1, 'Sobel');
                        %plot(BW)
                        [H,T,R] = hough(BW,'RhoResolution',0.5,'Theta',-90:0.5:89);
                        %[H,T,R] = hough(BW);
                        imshow(imadjust(rescale(H)),'parent',app.UIAxes2_2,'XData',T,'YData',R,'InitialMagnification','fit');
                        app.UIAxes2_2.Title.String = 'Hough transform '
                        app.UIAxes2_2.XLabel.String = '\theta';
                        app.UIAxes2_2.YLabel.String = '\rho';
                        colormap(app.UIAxes2_2,'hot');
                        colorbar(app.UIAxes2_2)
                        axis(app.UIAxes2_2,'on')
                        axis(app.UIAxes2_2,'normal')
                        hold(app.UIAxes2_2,'on')
                        %寻峰值
                        XN=inputdlg('请输入要检测的直线数目：','Hough变换');
                        xn = str2num(XN{1})
                        P  = houghpeaks(H,xn,'threshold',ceil(0.3*max(H(:)))); % 从霍夫变换矩阵H中提取5个极值点
                        x = T(P(:,2)); 
                        y = R(P(:,1));
                        plot(app.UIAxes2_2,x,y,'s','color','blue');%标出极值点
                        %绘制直线
                        lines=houghlines(BW,T,R,P,'FillGap',100,'MinLength',7);%提取线段
                        imshow(im,'parent',app.UIAxes_2,'InitialMagnification','fit');
                        hold(app.UIAxes_2,'on')
                        app.UIAxes_2.Title.String = '直线检测 '
                        app.UIAxes_2.XLabel.String = '';
                        app.UIAxes_2.YLabel.String = '';
                        max_len = 0;
                        for k = 1:length(lines)
                            xy = [lines(k).point1; lines(k).point2];
                            plot(app.UIAxes_2,xy(:,1),xy(:,2),'LineWidth',1,'Color','green');%画出线段

                        end
                    case '图像镜像'
                        im = app.im;
                        [height, width, dim]=size(im);
                        %水平镜像变换
                        tform = maketform('affine',[-1 0 0;0 1 0; width 0 1]);   
                        B=imtransform(im, tform, 'nearest');   %将二维空间变换应用于图像
                        %垂直镜像变换
                        tform2 = maketform('affine', [1 0 0; 0 -1 0; 0 height 1]);
                        C=imtransform(im, tform2, 'nearest');
                        imshow(B,'parent',app.UIAxes2_2);
                        app.UIAxes2_2.Title.String = '水平镜像 '
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        imshow(C,'parent',app.UIAxes_2);
                        app.UIAxes_2.Title.String = '垂直镜像 '
                        app.UIAxes_2.XLabel.String = '';
                        app.UIAxes_2.YLabel.String = '';
                        
                    case '图像转置'
                        im = app.im;
                        tform = maketform('affine',[0 1 0;1 0 0; 0 0 1]);
                        B= imtransform(im,tform,'nearest');
                        imshow(B,'parent',app.UIAxes2_2);
                        app.UIAxes2_2.Title.String = '图像转置 '
                        app.UIAxes2_2.XLabel.String = '';
                        app.UIAxes2_2.YLabel.String = '';
                        [counts,x] = imhist(B);
                        app.UIAxes_2.Title.String = '输出灰度直方图';
                        app.UIAxes_2.XLabel.String = '灰度';
                        app.UIAxes_2.YLabel.String = '像素数目';
                        axis(app.UIAxes_2,'normal')
                        axis(app.UIAxes_2,'on')
                        app.UIAxes_2.TickDirMode = 'auto'
                        app.UIAxes_2.YDir = 'normal'
                        app.UIAxes_2.XGrid = 'on'
                        app.UIAxes_2.YGrid = 'on'
                        plot(app.UIAxes_2,255*x,counts);
                    case '图像缩放'
                         try
                            im = app.im;
                            addition=inputdlg('缩放大小：','图像缩放');
                            addition = cell2mat(addition)
                            addition=str2num(addition);%addition2数值为文字，转化为数值
                            b=addition;%倍数
                            B= imresize(im,b,'nearest');
                            imshow(B,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            [counts,x] = imhist(B);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            plot(app.UIAxes_2,255*x,counts);
                        catch ME
                            uiwait(msgbox('输入错误 默认扩大1.2倍', 'Error','error'));
                            B= imresize(im,1.2,'nearest');
                            imshow(B,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            [counts,x] = imhist(im);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            plot(app.UIAxes_2,255*x,counts);
                         end
                    case '图像旋转'
                        im = app.im;
                        try
                            addition=inputdlg('旋转角度：','旋转');
                            addition2=cell2mat(addition);%addition数值为元胞，转化为文字
                            addition3=str2num(addition2);%addition2数值为文字，转化为数值
                            b=addition3(1,1);%输入值
                            J=imrotate(im,b);
                            imshow(J,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            [counts,x] = imhist(J);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            plot(app.UIAxes_2,255*x,counts);
                        catch
                            uiwait(msgbox('输入错误 默认旋转15°', 'Error','error'));
                            b=15;%输入值
                            J=imrotate(im,b);
                            imshow(J,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            [counts,x] = imhist(J);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                            plot(app.UIAxes_2,255*x,counts);
                        end
                    case '图像插值'
                            im = app.im;
                            c1=imrotate(im,30,'nearest');
                            imshow(c1,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '最近邻插值旋转效果';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            c2=imrotate(im,30,'bilinear');
                            app.UIAxes_2.Title.String = '双线性插值旋转效果';
                            app.UIAxes_2.XLabel.String = '';
                            app.UIAxes_2.YLabel.String = '';
                            imshow(c2,'parent',app.UIAxes_2);   
                    case '灰度腐蚀'
                            im = app.im;
                            app.DropDown_3.Visible = 'on'
%                             app.DropDown_3.value = '3×3正方形'
                            app.Label_xt.Visible = 'on'
                            im1 = im2bw(im);
                            se = strel('square',3)
                            Ib = imerode(im1,se)
                            imshow(Ib,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '腐蚀图';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            [counts,x] = imhist(Ib);
                            app.UIAxes_2.Title.String = '输出灰度直方图';
                            app.UIAxes_2.XLabel.String = '灰度';
                            app.UIAxes_2.YLabel.String = '像素数目';
                            plot(app.UIAxes_2,255*x,counts);
                            axis(app.UIAxes_2,'normal')
                            axis(app.UIAxes_2,'on')
                            app.UIAxes_2.TickDirMode = 'auto'
                            app.UIAxes_2.YDir = 'normal'
                            app.UIAxes_2.XGrid = 'on'
                            app.UIAxes_2.YGrid = 'on'
                    case '灰度膨胀'
                            im = app.im;
                            im1 = im2bw(im);
                            se = strel('square',3);
                            Ib = imdilate(im1,se);
                            imshow(Ib,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '3×3正方形结构元素膨胀';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            se1 = strel([0 1 0;1 1 1;0 1 0]);
                            Ib = imdilate(im1,se1)
                            imshow(Ib,'parent',app.UIAxes_2);
                            app.UIAxes_2.Title.String = '3×3十字结构元素膨胀';
                            app.UIAxes_2.XLabel.String = '';
                            app.UIAxes_2.YLabel.String = '';
                    case '开运算'
                            im = app.im;
                            im1 = im2bw(im);
                            o = strel('square',6);
                            Ib = imopen(im1,o);
                            imshow(Ib,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '6×6正方形结构元素开运算';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            se1 = strel('square',6);
                            Ib = imerode(im1,se1)
                            imshow(Ib,'parent',app.UIAxes_2);
                            app.UIAxes_2.Title.String = '6×6正方形结构元素腐蚀';
                            app.UIAxes_2.XLabel.String = '';
                            app.UIAxes_2.YLabel.String = '';
                    case '闭运算'
                            im = app.im;
                            im1 = im2bw(im);
                            o = strel('square',6);
                            Ib = imclose(im1,o);
                            imshow(Ib,'parent',app.UIAxes2_2);
                            app.UIAxes2_2.Title.String = '6×6正方形结构元素闭运算';
                            app.UIAxes2_2.XLabel.String = '';
                            app.UIAxes2_2.YLabel.String = '';
                            se1 = strel('square',6);
                            Ib = imdilate(im1,se1)
                            imshow(Ib,'parent',app.UIAxes_2);
                            app.UIAxes_2.Title.String = '6×6正方形结构元素膨胀';
                            app.UIAxes_2.XLabel.String = '';
                            app.UIAxes_2.YLabel.String = '';
                end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case '3×3正方形'
                    im = app.im;
                    app.DropDown_3.Visible = 'on'
                    app.Label_xt.Visible = 'on'
                    im1 = im2bw(im);
                    se = strel('square',3)
                    Ib = imerode(im1,se)
                    imshow(Ib,'parent',app.UIAxes2_2);
                    app.UIAxes2_2.Title.String = '腐蚀图';
                    app.UIAxes2_2.XLabel.String = '';
                    app.UIAxes2_2.YLabel.String = '';
                    [counts,x] = imhist(Ib);
                    app.UIAxes_2.Title.String = '输出灰度直方图';
                    app.UIAxes_2.XLabel.String = '灰度';
                    app.UIAxes_2.YLabel.String = '像素数目';
                    plot(app.UIAxes_2,255*x,counts);
                case '3×3十字'
                    im = app.im;
                    app.DropDown_3.Visible = 'on'
                    app.Label_xt.Visible = 'on'
                    im1 = im2bw(im);
                    se = strel([0 1 0;1 1 1;0 1 0])
                    Ib = imerode(im1,se)
                    imshow(Ib,'parent',app.UIAxes2_2);
                    app.UIAxes2_2.Title.String = '腐蚀图';
                    app.UIAxes2_2.XLabel.String = '';
                    app.UIAxes2_2.YLabel.String = '';
                    [counts,x] = imhist(Ib);
                    app.UIAxes_2.Title.String = '输出灰度直方图';
                    app.UIAxes_2.XLabel.String = '灰度';
                    app.UIAxes_2.YLabel.String = '像素数目';
                    plot(app.UIAxes_2,255*x,counts);
                case '5×5正方形'
                    im = app.im;
                    app.DropDown_3.Visible = 'on'
                    app.Label_xt.Visible = 'on'
                    im1 = im2bw(im);
                    se = strel('square',5)
                    Ib = imerode(im1,se)
                    imshow(Ib,'parent',app.UIAxes2_2);
                    app.UIAxes2_2.Title.String = '腐蚀图';
                    app.UIAxes2_2.XLabel.String = '';
                    app.UIAxes2_2.YLabel.String = '';
                    [counts,x] = imhist(Ib);
                    app.UIAxes_2.Title.String = '输出灰度直方图';
                    app.UIAxes_2.XLabel.String = '灰度';
                    app.UIAxes_2.YLabel.String = '像素数目';
                    plot(app.UIAxes_2,255*x,counts);
                case '6×6正方形'
                    im = app.im;
                    app.DropDown_3.Visible = 'on'
                    app.Label_xt.Visible = 'on'
                    im1 = im2bw(im);
                    se = strel('square',6)
                    Ib = imerode(im1,se)
                    imshow(Ib,'parent',app.UIAxes2_2);
                    app.UIAxes2_2.Title.String = '腐蚀图';
                    app.UIAxes2_2.XLabel.String = '';
                    app.UIAxes2_2.YLabel.String = '';
                    [counts,x] = imhist(Ib);
                    app.UIAxes_2.Title.String = '输出灰度直方图';
                    app.UIAxes_2.XLabel.String = '灰度';
                    app.UIAxes_2.YLabel.String = '像素数目';
                    plot(app.UIAxes_2,255*x,counts);
                case '7×7正方形'
                    im = app.im;
                    app.DropDown_3.Visible = 'on'
                    app.Label_xt.Visible = 'on'
                    im1 = im2bw(im);
                    se = strel('square',7)
                    Ib = imerode(im1,se)
                    imshow(Ib,'parent',app.UIAxes2_2);
                    app.UIAxes2_2.Title.String = '腐蚀图';
                    app.UIAxes2_2.XLabel.String = '';
                    app.UIAxes2_2.YLabel.String = '';
                    [counts,x] = imhist(Ib);
                    app.UIAxes_2.Title.String = '输出灰度直方图';
                    app.UIAxes_2.XLabel.String = '灰度';
                    app.UIAxes_2.YLabel.String = '像素数目';
                    plot(app.UIAxes_2,255*x,counts);
            end
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)

            delete(app.UIFigure)
            run main.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '基础图像处理';
            app.Fun_Panel = uipanel(app.UIFigure);
            app.ButtonGroup_2_1 = uibuttongroup(app.Fun_Panel);
            app.ButtonGroup_2_1.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2_1SelectionChanged2, true);
            app.Button_2_1_1 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_1.Text = '灰度线性变换';
            app.Button_2_1_1.Value = true;
            app.Button_2_1_2 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_2.Text = '灰度对数变换';
            app.Button_2_1_3 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_3.Text = '伽玛变换';
            app.Button_2_1_4 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_4.Text = '灰度阈值变换';
            app.Button_2_1_5 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_5.Text = '分段线性变换';
            app.Button_2_1_6 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_2_1_6.Text = '直方图均衡化';
            app.RadonButton = uiradiobutton(app.ButtonGroup_2_1);
            app.RadonButton.Text = 'Radon变换';
            app.Button_13 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_13.Text = '傅里叶变换';
            app.HoughButton = uiradiobutton(app.ButtonGroup_2_1);
            app.HoughButton.Text = 'Hough变换';
            app.Button_15 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_15.Text = '图像镜像';
            app.Button_16 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_16.Text = '图像转置';
            app.Button_17 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_17.Text = '图像缩放';
            app.Button_18 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_18.Text = '图像旋转';
            app.Button_19 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_19.Text = '图像插值';
            app.Button_20 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_20.Text = '灰度膨胀';
            app.Button_21 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_21.Text = '灰度腐蚀';
            app.Button_22 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_22.Text = '开运算';
            app.Button_23 = uiradiobutton(app.ButtonGroup_2_1);
            app.Button_23.Text = '闭运算';
            app.Label_5 = uilabel(app.ButtonGroup_2_1);
            app.Label_5.Text = '图像变换';
            app.Label_6 = uilabel(app.ButtonGroup_2_1);
            app.Label_6.Text = '图像几何变换';
            app.Label_7 = uilabel(app.ButtonGroup_2_1);
            app.Label_7.Text = '形态学图像处理';
            app.Label_4 = uilabel(app.ButtonGroup_2_1);
            app.Label_4.Text = '图像点运算';
            app.Panel_6 = uipanel(app.Fun_Panel);
            app.Button = uibutton(app.Panel_6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '浏览...';
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = 'folder.png';
            app.Image3 = uiimage(app.Panel_6);
            app.Image3.ImageSource = 'samplepicture4.png';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, '输入灰度直方图')
            xlabel(app.UIAxes, '灰度')
            ylabel(app.UIAxes, '像素数目')
            app.Panel_4 = uipanel(app.Panel_2);
            app.UIAxes2 = uiaxes(app.Panel_4);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '图像预览';
            app.Panel_3 = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel_3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像处理结果';
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, '输出灰度直方图')
            xlabel(app.UIAxes_2, '灰度')
            ylabel(app.UIAxes_2, '像素数目')
            app.Panel_7 = uipanel(app.Tab);
            app.UIAxes2_2 = uiaxes(app.Panel_7);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.DropDown = uidropdown(app.Panel_7);
            app.DropDown.Items = {'增加对比度', '减小对比度', '线性增加亮度', '反相显示'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = '增加对比度';
            app.DropDown_2 = uidropdown(app.Panel_7);
            app.DropDown_2.Items = {'Gamma 0.75', 'Gamma 1', 'Gamma 1.5'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'Gamma 0.75';
            app.Slider = uislider(app.Panel_7);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.Label_yz = uilabel(app.Panel_7);
            app.Label_yz.Visible = 'off';
            app.Label_yz.Text = '灰度阈值';
            app.DropDown_3 = uidropdown(app.Panel_7);
            app.DropDown_3.Items = {'3×3正方形', '3×3十字', '5×5正方形', '6×6正方形', '7×7正方形'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = '3×3正方形';
            app.Label_xt = uilabel(app.Panel_7);
            app.Label_xt.Visible = 'off';
            app.Label_xt.Text = '结构元素';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '图像输出';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '返回';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Basic_Image_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end