classdef Image_enhancement_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel13              matlab.ui.container.Panel
        Brightness             matlab.ui.control.Slider
        uipanel12              matlab.ui.container.Panel
        CropOkay               matlab.ui.control.Button
        Y2                     matlab.ui.control.EditField
        X2                     matlab.ui.control.EditField
        Y1                     matlab.ui.control.EditField
        X1                     matlab.ui.control.EditField
        Save                   matlab.ui.control.Button
        uipanel22              matlab.ui.container.Panel
        Watershed              matlab.ui.control.Button
        uipanel24              matlab.ui.container.Panel
        Homomorphic            matlab.ui.control.Button
        uipanel20              matlab.ui.container.Panel
        convexhull             matlab.ui.control.Button
        uipanel21              matlab.ui.container.Panel
        prune                  matlab.ui.control.Button
        fillholes              matlab.ui.control.Button
        Thickening             matlab.ui.control.Button
        Thinning               matlab.ui.control.Button
        Skeleton               matlab.ui.control.Button
        uipanel19              matlab.ui.container.Panel
        fourierangle           matlab.ui.control.Button
        Fouriermag             matlab.ui.control.Button
        uipanel18              matlab.ui.container.Panel
        LabelImage             matlab.ui.control.Button
        uipanel17              matlab.ui.container.Panel
        Addnoiseweiner         matlab.ui.control.Button
        WeinerFilter           matlab.ui.control.Button
        uipanel16              matlab.ui.container.Panel
        GaussianNoise          matlab.ui.control.Button
        saltandpepper          matlab.ui.control.Button
        uipanel15              matlab.ui.container.Panel
        hitmiss                matlab.ui.control.Button
        morphogradient         matlab.ui.control.Button
        ImageClosing           matlab.ui.control.Button
        ImageOpening           matlab.ui.control.Button
        Erosion                matlab.ui.control.Button
        Dilation               matlab.ui.control.Button
        uipanel14              matlab.ui.container.Panel
        resize                 matlab.ui.control.Button
        uipanel11              matlab.ui.container.Panel
        Histogramequalization  matlab.ui.control.Button
        uipanel10              matlab.ui.container.Panel
        Unsharpmask            matlab.ui.control.Button
        laplacian              matlab.ui.control.Button
        uipanel9               matlab.ui.container.Panel
        gaussianfilter         matlab.ui.control.Button
        averagefilter          matlab.ui.control.Button
        uipanel8               matlab.ui.container.Panel
        zerocross              matlab.ui.control.Button
        prewitt                matlab.ui.control.Button
        Sobel                  matlab.ui.control.Button
        robert                 matlab.ui.control.Button
        laplacianofgaussian    matlab.ui.control.Button
        canny                  matlab.ui.control.Button
        uipanel7               matlab.ui.container.Panel
        Arithmeticmean         matlab.ui.control.Button
        contraharmonicmean     matlab.ui.control.Button
        geometricmean          matlab.ui.control.Button
        uipanel6               matlab.ui.container.Panel
        adaptivemeanfilter     matlab.ui.control.Button
        adaptivemedianfilter   matlab.ui.control.Button
        uipanel5               matlab.ui.container.Panel
        Alphatrimfilter        matlab.ui.control.Button
        Midpointfilter         matlab.ui.control.Button
        Minimumfilter          matlab.ui.control.Button
        maximumfilter          matlab.ui.control.Button
        medianfilter           matlab.ui.control.Button
        uipanel4               matlab.ui.container.Panel
        Close                  matlab.ui.control.Button
        Im2Binary              matlab.ui.control.Button
        Load                   matlab.ui.control.Button
        rgbtogray              matlab.ui.control.Button
        Reset                  matlab.ui.control.Button
        SaveAs                 matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        uipanel1               matlab.ui.container.Panel
        axes1                  matlab.ui.control.UIAxes
        axes2                  matlab.ui.control.UIAxes
        axes3                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Image_enhancement_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            axes(handles.axes1);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes2);
            set(gca,'xtick',[]);
            set(gca,'ytick',[]);
            set(gca,'color','none');
            
            axes(handles.axes3)    %在坐标轴7下画图
            ii = imread('background.png');
            set(gca,'color','none');
            imshow(ii)
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Addnoiseweiner
        function Addnoiseweiner_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=addnoiseweiner(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Alphatrimfilter
        function Alphatrimfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=alpha_trim(im2);
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: Arithmeticmean
        function Arithmeticmean_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=average_filter(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Value changed function: Brightness
        function Brightness_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            val=.5*get(hObject,'Value');
            im2=im2+val;
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Close
        function Close_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            close
        end

        % Button pushed function: CropOkay
        function CropOkay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global X1 Y1 X2 Y2 im im2
            im2=imcrop(im2,[X1 Y1 X2 Y2]);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Dilation
        function Dilation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=image_dilate(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Erosion
        function Erosion_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=image_erode(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Fouriermag
        function Fouriermag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=fftshift(log(abs(fft2(im2))));
            im2=im2double(mat2gray(im2));
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: GaussianNoise
        function GaussianNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=imnoise(im2,'gaussian');
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Histogramequalization
        function Histogramequalization_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=Histogram_Equalize(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Homomorphic
        function Homomorphic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=homomorphic(im2);
            axes(handles.axes2)
            imshow(im2,[])
        end

        % Button pushed function: Im2Binary
        function Im2Binary_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=im2double(im2);
             level=graythresh(im2);
            % im2=im2bw(im2,level);
            
             im2=my_im2bw(im2,level);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: ImageClosing
        function ImageClosing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=image_closing(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: ImageOpening
        function ImageOpening_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=image_opening(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: LabelImage
        function LabelImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwlabel(im2,4);
            im2=label2rgb(im2);%,'jet','w','shuffle');
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Load
        function Load_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            [path,user_cance]=imgetfile();
            if user_cance
                msgbox(sprintf('Error'),'Error','Error');
                return
            end
            im=imread(path);
            axes(handles.axes1);
            imshow(im)
            im2=im;
        end

        % Button pushed function: Midpointfilter
        function Midpointfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=midpoint(im2);
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: Minimumfilter
        function Minimumfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
             im2=minimum_filter(im2);
             axes(handles.axes2)
             imshow(im2)
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            axes(handles.axes2);
            imshow(im)
            im2=im;
        end

        % Button pushed function: SaveAs
        function SaveAs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % global im im2
            % [filename, pathname]=uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';'*.*','All Files' },'Save Image','C:\Users\Ahsan\Documents\MATLAB\Project\newfile.jpg');
            % imwrite(im2,filename);
            % im=im2;
            % axes(handles.axes1)
            % imshow(im2)
            imsave(handles.axes2)
        end

        % Button pushed function: Save
        function Save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            
            im=im2;
            axes(handles.axes1)
            imshow(im2)
        end

        % Button pushed function: Skeleton
        function Skeleton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwmorph(im2,'skel');
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: Sobel
        function Sobel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=sobel_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Thickening
        function Thickening_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwmorph(im2,'thicken');
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: Thinning
        function Thinning_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwmorph(im2,'thin');
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: Unsharpmask
        function Unsharpmask_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=unsharp_mask(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: Watershed
        function Watershed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=watershedseg(im2);
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: WeinerFilter
        function WeinerFilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=WienerFilter(im2,im);
            axes(handles.axes2);
            imshow(im2);
        end

        % Value changed function: X1
        function X1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global X1
            X1=str2num(get(hObject,'String'));
        end

        % Value changed function: X2
        function X2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global X2
            X2=str2num(get(hObject,'String'));
        end

        % Value changed function: Y1
        function Y1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Y1
            Y1=str2num(get(hObject,'String'));
        end

        % Value changed function: Y2
        function Y2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global Y2
            Y2=str2num(get(hObject,'String'));
        end

        % Button pushed function: adaptivemeanfilter
        function adaptivemeanfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=adaptive_mean(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: adaptivemedianfilter
        function adaptivemedianfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=adaptive_median(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: averagefilter
        function averagefilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=average_filter(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: canny
        function canny_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=canny_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: contraharmonicmean
        function contraharmonicmean_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=contra_mean(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: convexhull
        function convexhull_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=convexhull(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: fillholes
        function fillholes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwmorph(im2,'fill');
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: fourierangle
        function fourierangle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=im2double(im2);
            im2=fftshift(angle(fft2(im2)));
            im2=im2double(mat2gray(im2));
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: gaussianfilter
        function gaussianfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=gauss_filter(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: geometricmean
        function geometricmean_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=geo_mean(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: hitmiss
        function hitmiss_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=hitmiss(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: laplacian
        function laplacian_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=sharpen(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: laplacianofgaussian
        function laplacianofgaussian_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=LOG_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: maximumfilter
        function maximumfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=maximum_filter(im2);
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: medianfilter
        function medianfilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=median_filter(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: morphogradient
        function morphogradient_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=morphological_gradient(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: prewitt
        function prewitt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=prewitt_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: prune
        function prune_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2
            im2=bwmorph(im2,'spur');
            axes(handles.axes2)
            imshow(im2)
        end

        % Button pushed function: resize
        function resize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=resizeimage(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: rgbtogray
        function rgbtogray_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=rgb2gray(im2);
            axes(handles.axes2);
            imshow(im2)
        end

        % Button pushed function: robert
        function robert_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=robert_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: saltandpepper
        function saltandpepper_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im2 im
            im2=imnoise(im2,'salt & pepper');
            axes(handles.axes2);
            imshow(im2);
        end

        % Button pushed function: zerocross
        function zerocross_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global im im2
            im2=zerocross_edge(im2);
            axes(handles.axes2);
            imshow(im2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Image_enhancement';
            app.figure1.Tag = 'figure1';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '原始图像';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = '处理后图像';
            app.uipanel3.Tag = 'uipanel3';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = '功能';
            app.uipanel4.Tag = 'uipanel4';
            app.SaveAs = uibutton(app.uipanel4, 'push');
            app.SaveAs.ButtonPushedFcn = createCallbackFcn(app, @SaveAs_Callback, true);
            app.SaveAs.Tag = 'SaveAs';
            app.SaveAs.Text = '另存为';
            app.Reset = uibutton(app.uipanel4, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Tag = 'Reset';
            app.Reset.Text = '重置';
            app.rgbtogray = uibutton(app.uipanel4, 'push');
            app.rgbtogray.ButtonPushedFcn = createCallbackFcn(app, @rgbtogray_Callback, true);
            app.rgbtogray.Tag = 'rgbtogray';
            app.rgbtogray.Text = 'RGB转GrayScale';
            app.Load = uibutton(app.uipanel4, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @Load_Callback, true);
            app.Load.Tag = 'Load';
            app.Load.Tooltip = '加载图像';
            app.Load.Text = '加载图像';
            app.Im2Binary = uibutton(app.uipanel4, 'push');
            app.Im2Binary.ButtonPushedFcn = createCallbackFcn(app, @Im2Binary_Callback, true);
            app.Im2Binary.Tag = 'Im2Binary';
            app.Im2Binary.Text = 'Im2Binary';
            app.Close = uibutton(app.uipanel4, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @Close_Callback, true);
            app.Close.Tag = 'Close';
            app.Close.Text = '关闭';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = '常用阶滤波';
            app.uipanel5.Tag = 'uipanel5';
            app.medianfilter = uibutton(app.uipanel5, 'push');
            app.medianfilter.ButtonPushedFcn = createCallbackFcn(app, @medianfilter_Callback, true);
            app.medianfilter.Tag = 'medianfilter';
            app.medianfilter.Text = 'Median Filter';
            app.maximumfilter = uibutton(app.uipanel5, 'push');
            app.maximumfilter.ButtonPushedFcn = createCallbackFcn(app, @maximumfilter_Callback, true);
            app.maximumfilter.Tag = 'maximumfilter';
            app.maximumfilter.Text = 'Maximum Filter';
            app.Minimumfilter = uibutton(app.uipanel5, 'push');
            app.Minimumfilter.ButtonPushedFcn = createCallbackFcn(app, @Minimumfilter_Callback, true);
            app.Minimumfilter.Tag = 'Minimumfilter';
            app.Minimumfilter.Text = 'Minimum filter';
            app.Midpointfilter = uibutton(app.uipanel5, 'push');
            app.Midpointfilter.ButtonPushedFcn = createCallbackFcn(app, @Midpointfilter_Callback, true);
            app.Midpointfilter.Tag = 'Midpointfilter';
            app.Midpointfilter.Text = 'Mid-Point Filter';
            app.Alphatrimfilter = uibutton(app.uipanel5, 'push');
            app.Alphatrimfilter.ButtonPushedFcn = createCallbackFcn(app, @Alphatrimfilter_Callback, true);
            app.Alphatrimfilter.Tag = 'Alphatrimfilter';
            app.Alphatrimfilter.Text = 'Alpha Trim Filter';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = '自适应滤波';
            app.uipanel6.Tag = 'uipanel6';
            app.adaptivemedianfilter = uibutton(app.uipanel6, 'push');
            app.adaptivemedianfilter.ButtonPushedFcn = createCallbackFcn(app, @adaptivemedianfilter_Callback, true);
            app.adaptivemedianfilter.Tag = 'adaptivemedianfilter';
            app.adaptivemedianfilter.Text = 'Adaptive Median Filter';
            app.adaptivemeanfilter = uibutton(app.uipanel6, 'push');
            app.adaptivemeanfilter.ButtonPushedFcn = createCallbackFcn(app, @adaptivemeanfilter_Callback, true);
            app.adaptivemeanfilter.Tag = 'adaptivemeanfilter';
            app.adaptivemeanfilter.Text = 'Adaptive Mean Filter';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = '均值滤波';
            app.uipanel7.Tag = 'uipanel7';
            app.geometricmean = uibutton(app.uipanel7, 'push');
            app.geometricmean.ButtonPushedFcn = createCallbackFcn(app, @geometricmean_Callback, true);
            app.geometricmean.Tag = 'geometricmean';
            app.geometricmean.Text = 'Geometric Mean';
            app.contraharmonicmean = uibutton(app.uipanel7, 'push');
            app.contraharmonicmean.ButtonPushedFcn = createCallbackFcn(app, @contraharmonicmean_Callback, true);
            app.contraharmonicmean.Tag = 'contraharmonicmean';
            app.contraharmonicmean.Text = 'Contraharmonic Mean';
            app.Arithmeticmean = uibutton(app.uipanel7, 'push');
            app.Arithmeticmean.ButtonPushedFcn = createCallbackFcn(app, @Arithmeticmean_Callback, true);
            app.Arithmeticmean.Tag = 'Arithmeticmean';
            app.Arithmeticmean.Text = 'Arithmetic Mean';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = '边缘检测';
            app.uipanel8.Tag = 'uipanel8';
            app.canny = uibutton(app.uipanel8, 'push');
            app.canny.ButtonPushedFcn = createCallbackFcn(app, @canny_Callback, true);
            app.canny.Tag = 'canny';
            app.canny.Text = 'Canny';
            app.laplacianofgaussian = uibutton(app.uipanel8, 'push');
            app.laplacianofgaussian.ButtonPushedFcn = createCallbackFcn(app, @laplacianofgaussian_Callback, true);
            app.laplacianofgaussian.Tag = 'laplacianofgaussian';
            app.laplacianofgaussian.Text = 'Laplacian of Gaussian';
            app.robert = uibutton(app.uipanel8, 'push');
            app.robert.ButtonPushedFcn = createCallbackFcn(app, @robert_Callback, true);
            app.robert.Tag = 'robert';
            app.robert.Text = 'Robert';
            app.Sobel = uibutton(app.uipanel8, 'push');
            app.Sobel.ButtonPushedFcn = createCallbackFcn(app, @Sobel_Callback, true);
            app.Sobel.Tag = 'Sobel';
            app.Sobel.Text = 'Sobel';
            app.prewitt = uibutton(app.uipanel8, 'push');
            app.prewitt.ButtonPushedFcn = createCallbackFcn(app, @prewitt_Callback, true);
            app.prewitt.Tag = 'prewitt';
            app.prewitt.Text = 'Prewitt';
            app.zerocross = uibutton(app.uipanel8, 'push');
            app.zerocross.ButtonPushedFcn = createCallbackFcn(app, @zerocross_Callback, true);
            app.zerocross.Tag = 'zerocross';
            app.zerocross.Text = 'Zero Cross';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = '去噪';
            app.uipanel9.Tag = 'uipanel9';
            app.averagefilter = uibutton(app.uipanel9, 'push');
            app.averagefilter.ButtonPushedFcn = createCallbackFcn(app, @averagefilter_Callback, true);
            app.averagefilter.Tag = 'averagefilter';
            app.averagefilter.Text = 'Average Filter';
            app.gaussianfilter = uibutton(app.uipanel9, 'push');
            app.gaussianfilter.ButtonPushedFcn = createCallbackFcn(app, @gaussianfilter_Callback, true);
            app.gaussianfilter.Tag = 'gaussianfilter';
            app.gaussianfilter.Text = 'Gaussian Filter';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = '锐化';
            app.uipanel10.Tag = 'uipanel10';
            app.laplacian = uibutton(app.uipanel10, 'push');
            app.laplacian.ButtonPushedFcn = createCallbackFcn(app, @laplacian_Callback, true);
            app.laplacian.Tag = 'laplacian';
            app.laplacian.Text = 'Laplacian';
            app.Unsharpmask = uibutton(app.uipanel10, 'push');
            app.Unsharpmask.ButtonPushedFcn = createCallbackFcn(app, @Unsharpmask_Callback, true);
            app.Unsharpmask.Tag = 'Unsharpmask';
            app.Unsharpmask.Text = 'Unsharp Masking';
            app.uipanel11 = uipanel(app.figure1);
            app.uipanel11.Title = '均衡化';
            app.uipanel11.Tag = 'uipanel11';
            app.Histogramequalization = uibutton(app.uipanel11, 'push');
            app.Histogramequalization.ButtonPushedFcn = createCallbackFcn(app, @Histogramequalization_Callback, true);
            app.Histogramequalization.Tag = 'Histogramequalization';
            app.Histogramequalization.Text = 'Histogram Equalization';
            app.uipanel14 = uipanel(app.figure1);
            app.uipanel14.Title = '缩放';
            app.uipanel14.Tag = 'uipanel14';
            app.resize = uibutton(app.uipanel14, 'push');
            app.resize.ButtonPushedFcn = createCallbackFcn(app, @resize_Callback, true);
            app.resize.Tag = 'resize';
            app.resize.Text = 'Resize';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = '形态学操作';
            app.uipanel15.Tag = 'uipanel15';
            app.Dilation = uibutton(app.uipanel15, 'push');
            app.Dilation.ButtonPushedFcn = createCallbackFcn(app, @Dilation_Callback, true);
            app.Dilation.Tag = 'Dilation';
            app.Dilation.Text = 'Dilation';
            app.Erosion = uibutton(app.uipanel15, 'push');
            app.Erosion.ButtonPushedFcn = createCallbackFcn(app, @Erosion_Callback, true);
            app.Erosion.Tag = 'Erosion';
            app.Erosion.Text = 'Erosion';
            app.ImageOpening = uibutton(app.uipanel15, 'push');
            app.ImageOpening.ButtonPushedFcn = createCallbackFcn(app, @ImageOpening_Callback, true);
            app.ImageOpening.Tag = 'ImageOpening';
            app.ImageOpening.Text = 'Opening';
            app.ImageClosing = uibutton(app.uipanel15, 'push');
            app.ImageClosing.ButtonPushedFcn = createCallbackFcn(app, @ImageClosing_Callback, true);
            app.ImageClosing.Tag = 'ImageClosing';
            app.ImageClosing.Text = 'Closing';
            app.morphogradient = uibutton(app.uipanel15, 'push');
            app.morphogradient.ButtonPushedFcn = createCallbackFcn(app, @morphogradient_Callback, true);
            app.morphogradient.Tag = 'morphogradient';
            app.morphogradient.Text = 'Morphological Gradient';
            app.hitmiss = uibutton(app.uipanel15, 'push');
            app.hitmiss.ButtonPushedFcn = createCallbackFcn(app, @hitmiss_Callback, true);
            app.hitmiss.Tag = 'hitmiss';
            app.hitmiss.Text = 'Hit and Miss Transform';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Title = '添加噪声';
            app.uipanel16.Tag = 'uipanel16';
            app.saltandpepper = uibutton(app.uipanel16, 'push');
            app.saltandpepper.ButtonPushedFcn = createCallbackFcn(app, @saltandpepper_Callback, true);
            app.saltandpepper.Tag = 'saltandpepper';
            app.saltandpepper.Text = 'Salt And Pepper';
            app.GaussianNoise = uibutton(app.uipanel16, 'push');
            app.GaussianNoise.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoise_Callback, true);
            app.GaussianNoise.Tag = 'GaussianNoise';
            app.GaussianNoise.Text = 'Gaussian Noise';
            app.uipanel17 = uipanel(app.figure1);
            app.uipanel17.Title = '维纳滤波';
            app.uipanel17.Tag = 'uipanel17';
            app.WeinerFilter = uibutton(app.uipanel17, 'push');
            app.WeinerFilter.ButtonPushedFcn = createCallbackFcn(app, @WeinerFilter_Callback, true);
            app.WeinerFilter.Tag = 'WeinerFilter';
            app.WeinerFilter.Text = 'Weiner Filter';
            app.Addnoiseweiner = uibutton(app.uipanel17, 'push');
            app.Addnoiseweiner.ButtonPushedFcn = createCallbackFcn(app, @Addnoiseweiner_Callback, true);
            app.Addnoiseweiner.Tag = 'Addnoiseweiner';
            app.Addnoiseweiner.Text = 'Add Noise';
            app.uipanel18 = uipanel(app.figure1);
            app.uipanel18.Title = '标记';
            app.uipanel18.Tag = 'uipanel18';
            app.LabelImage = uibutton(app.uipanel18, 'push');
            app.LabelImage.ButtonPushedFcn = createCallbackFcn(app, @LabelImage_Callback, true);
            app.LabelImage.Tag = 'LabelImage';
            app.LabelImage.Text = 'Label';
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = '傅里叶变换';
            app.uipanel19.Tag = 'uipanel19';
            app.Fouriermag = uibutton(app.uipanel19, 'push');
            app.Fouriermag.ButtonPushedFcn = createCallbackFcn(app, @Fouriermag_Callback, true);
            app.Fouriermag.Tag = 'Fouriermag';
            app.Fouriermag.Text = 'Fourier Magnitude';
            app.fourierangle = uibutton(app.uipanel19, 'push');
            app.fourierangle.ButtonPushedFcn = createCallbackFcn(app, @fourierangle_Callback, true);
            app.fourierangle.Tag = 'fourierangle';
            app.fourierangle.Text = 'Fourier Angle';
            app.uipanel21 = uipanel(app.figure1);
            app.uipanel21.Title = '高级形态学操作';
            app.uipanel21.Tag = 'uipanel21';
            app.Skeleton = uibutton(app.uipanel21, 'push');
            app.Skeleton.ButtonPushedFcn = createCallbackFcn(app, @Skeleton_Callback, true);
            app.Skeleton.Tag = 'Skeleton';
            app.Skeleton.Text = 'Skeleton';
            app.Thinning = uibutton(app.uipanel21, 'push');
            app.Thinning.ButtonPushedFcn = createCallbackFcn(app, @Thinning_Callback, true);
            app.Thinning.Tag = 'Thinning';
            app.Thinning.Text = 'Thinning';
            app.Thickening = uibutton(app.uipanel21, 'push');
            app.Thickening.ButtonPushedFcn = createCallbackFcn(app, @Thickening_Callback, true);
            app.Thickening.Tag = 'Thickening';
            app.Thickening.Text = 'Thickening';
            app.fillholes = uibutton(app.uipanel21, 'push');
            app.fillholes.ButtonPushedFcn = createCallbackFcn(app, @fillholes_Callback, true);
            app.fillholes.Tag = 'fillholes';
            app.fillholes.Text = 'Fill Holes';
            app.prune = uibutton(app.uipanel21, 'push');
            app.prune.ButtonPushedFcn = createCallbackFcn(app, @prune_Callback, true);
            app.prune.Tag = 'prune';
            app.prune.Text = 'Pruning';
            app.uipanel20 = uipanel(app.figure1);
            app.uipanel20.Title = 'Convex Hull（凸包）';
            app.uipanel20.Tag = 'uipanel20';
            app.convexhull = uibutton(app.uipanel20, 'push');
            app.convexhull.ButtonPushedFcn = createCallbackFcn(app, @convexhull_Callback, true);
            app.convexhull.Tag = 'convexhull';
            app.convexhull.Text = 'Conver Hull';
            app.uipanel24 = uipanel(app.figure1);
            app.uipanel24.Title = '同态滤波';
            app.uipanel24.Tag = 'uipanel24';
            app.Homomorphic = uibutton(app.uipanel24, 'push');
            app.Homomorphic.ButtonPushedFcn = createCallbackFcn(app, @Homomorphic_Callback, true);
            app.Homomorphic.Tag = 'Homomorphic';
            app.Homomorphic.Text = 'Homomorphic';
            app.uipanel22 = uipanel(app.figure1);
            app.uipanel22.Title = '分割';
            app.uipanel22.Tag = 'uipanel22';
            app.Watershed = uibutton(app.uipanel22, 'push');
            app.Watershed.ButtonPushedFcn = createCallbackFcn(app, @Watershed_Callback, true);
            app.Watershed.Tag = 'Watershed';
            app.Watershed.Text = 'Watershed';
            app.Save = uibutton(app.figure1, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Callback, true);
            app.Save.Tag = 'Save';
            app.Save.Text = '保存';
            app.uipanel12 = uipanel(app.figure1);
            app.uipanel12.Title = '裁剪';
            app.uipanel12.Tag = 'uipanel12';
            app.X1 = uieditfield(app.uipanel12, 'text');
            app.X1.ValueChangedFcn = createCallbackFcn(app, @X1_Callback, true);
            app.X1.Tag = 'X1';
            app.X1.Value = 'X1';
            app.Y1 = uieditfield(app.uipanel12, 'text');
            app.Y1.ValueChangedFcn = createCallbackFcn(app, @Y1_Callback, true);
            app.Y1.Tag = 'Y1';
            app.Y1.Value = 'Y1';
            app.X2 = uieditfield(app.uipanel12, 'text');
            app.X2.ValueChangedFcn = createCallbackFcn(app, @X2_Callback, true);
            app.X2.Tag = 'X2';
            app.X2.Value = 'X2';
            app.Y2 = uieditfield(app.uipanel12, 'text');
            app.Y2.ValueChangedFcn = createCallbackFcn(app, @Y2_Callback, true);
            app.Y2.Tag = 'Y2';
            app.Y2.Value = 'Y2';
            app.CropOkay = uibutton(app.uipanel12, 'push');
            app.CropOkay.ButtonPushedFcn = createCallbackFcn(app, @CropOkay_Callback, true);
            app.CropOkay.Tag = 'CropOkay';
            app.CropOkay.Text = '确定';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Title = '亮度';
            app.uipanel13.Tag = 'uipanel13';
            app.Brightness = uislider(app.uipanel13);
            app.Brightness.Limits = [-100 100];
            app.Brightness.MajorTicks = [];
            app.Brightness.ValueChangedFcn = createCallbackFcn(app, @Brightness_Callback, true);
            app.Brightness.MinorTicks = [];
            app.Brightness.Tag = 'Brightness';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_enhancement_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Image_enhancement_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end