classdef trepang < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_2     matlab.ui.control.Button
        Jieguotishi  matlab.ui.control.Label
        jieguo       matlab.ui.control.Label
        Image        matlab.ui.control.Image
        Lamp         matlab.ui.control.Lamp
        LampLabel    matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        Tupianlan    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        II % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color=[1, 0, 0];
            II=imread("logo.jpg");
            app.Tupianlan.Selected=1;
            imshow(II,"Parent",app.Tupianlan,'InitialMagnification','fit')
%             layers=lenet5TLfun();
%             net=assembleNetwork(layers);
%             setappdata(app.UIFigure,'net',net)
%             app.Lamp.Color=[1, 0, 0];
            app.jieguo.Visible = 'off'
            app.Jieguotishi.Visible = 'off'
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
            [file,path] = uigetfile({'*.png;*.jpg;*.bmp;*.tif','Supported images';...
                 '*.png','Portable Network Graphics (*.png)';...
                 '*.jpg','J-PEG (*.jpg)';...
                 '*.bmp','Bitmap (*.bmp)';...
                 '*.tif','Tagged Image File (*.tif,)';...
                 '*.*','All files (*.*)'},'Select an image');
            if (file~=0)
                app.Lamp.Color=[0, 1, 0];
                app.II = imread([path file]);
                imshow(app.II,'Parent',app.Tupianlan)   
            end
            catch ME
                msgbox(ME.message,"Error");
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            I=app.II;
            app.jieguo.Visible = 'on'
            app.Jieguotishi.Visible = 'on'
                % imtool(I)
                [a,b,c]=size(I);   
                for i=1:a
                    for j=1:b
                        R=I(i,j,1);
                        G=I(i,j,2);
                        B=I(i,j,3);
                       if R==255
                           I(i,j)=I(i,j);
                       else
                           I(i,j,1)=0;
                           I(i,j,2)=0;
                           I(i,j,3)=0;
                       end
                    end
                end
%                  figure,imshow(I);
                bw1=im2bw(I);
%                 figure,imshow(bw1);
                [L,num] = bwlabel(bw1);
                for i=1:num
                   if numel(find(L==i)) < 1000
                       bw1(L==i) =0;
                   end
                end 
%                  figure,imshow(bw1);
                bw2=~bw1
                w=imresize(bw2,0.5); 
               % imtool(w)
                % [L1 num1]=bwlabel(w,8);%标记连通区域
                 A=regionprops(w,'Area','Perimeter','BoundingBox');
                 A1=A(1).Area;
                 numberD = length(find(w>0.5));
                 if numberD > 125000
                     app.jieguo.Text = "大海参,"+"像素面积为："+numberD
                 else
                     app.jieguo.Text = "小海参,"+"像素面积为："+numberD
                 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tupianlan = uiaxes(app.UIFigure);
            app.Tupianlan.XTick = [];
            app.Tupianlan.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'Select your image here'};
            app.Button.Text = '选择图片';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '海参分级系统';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '状态';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'Red: Processing'; 'Green: Ready'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'samplepicture1.png';
            app.jieguo = uilabel(app.UIFigure);
            app.jieguo.Text = '';
            app.Jieguotishi = uilabel(app.UIFigure);
            app.Jieguotishi.Text = '识别结果：';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tooltip = {'Select your image here'};
            app.Button_2.Text = '开始识别';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trepang

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end